% description: Copyright 2019-2021 Luca Della Santina
classdef TrachomaFinder < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        tblNeuralNet               matlab.ui.control.Table
        tblTraining                matlab.ui.control.Table
        TabGroup                   matlab.ui.container.TabGroup
        AnnotatePhotosTab          matlab.ui.container.Tab
        btnDiagnoseTI              matlab.ui.control.Button
        btnDiagnoseTF              matlab.ui.control.Button
        btnAnnotateAuto            matlab.ui.control.Button
        btnDuplicateTraining       matlab.ui.control.Button
        btnImportTraining          matlab.ui.control.Button
        btnExportTraining          matlab.ui.control.Button
        btnDeleteRow               matlab.ui.control.Button
        btnAnnotateManual          matlab.ui.control.Button
        btnPhotosFolder            matlab.ui.control.Button
        btnChooseUser              matlab.ui.control.Button
        NeuralNetworkTab           matlab.ui.container.Tab
        cmbNetTarget               matlab.ui.control.DropDown
        TargetLabel                matlab.ui.control.Label
        btnNetExport               matlab.ui.control.Button
        btnNetImport               matlab.ui.control.Button
        btnNetInfo                 matlab.ui.control.Button
        btnNetRename               matlab.ui.control.Button
        btnNetClone                matlab.ui.control.Button
        btnNetDelete               matlab.ui.control.Button
        btnNetCreate               matlab.ui.control.Button
        cmbNetModel                matlab.ui.control.DropDown
        NetModelDropDownLabel      matlab.ui.control.Label
        cmbNetType                 matlab.ui.control.DropDown
        NetTypeDropDownLabel       matlab.ui.control.Label
        btnNetTest                 matlab.ui.control.Button
        btnNetTrain                matlab.ui.control.Button
        btnNetGTruth               matlab.ui.control.Button
        CompareTab                 matlab.ui.container.Tab
        btnGradCam                 matlab.ui.control.Button
        btnROC                     matlab.ui.control.Button
        btnCompareNetworks         matlab.ui.control.Button
        btnCompareGraders          matlab.ui.control.Button
        ToolsTab                   matlab.ui.container.Tab
        btnClearFiles              matlab.ui.control.Button
        btnBatchColoredFollicles   matlab.ui.control.Button
        btnResetGPU                matlab.ui.control.Button
        btnChangeTrainingType      matlab.ui.control.Button
        btnClearFollicles          matlab.ui.control.Button
        btnClearEyelid             matlab.ui.control.Button
        btnMergeRecords            matlab.ui.control.Button
        btnBatchSaveBboxes         matlab.ui.control.Button
        btnRelinkImages            matlab.ui.control.Button
        btnBatchMaskFollicles      matlab.ui.control.Button
        btnBatchSaveEachFollicle   matlab.ui.control.Button
        btnBatchMaskEyelid         matlab.ui.control.Button
        btnBatchResize             matlab.ui.control.Button
        SettingsTab                matlab.ui.container.Tab
        btnBackupAnnotations       matlab.ui.control.Button
        btnRefreshTable            matlab.ui.control.Button
        NeuralNetConfidencePanel   matlab.ui.container.Panel
        cmbNetConfidenceEyelid     matlab.ui.control.Spinner
        EyelidLabel_2              matlab.ui.control.Label
        cmbNetConfidenceFollicles  matlab.ui.control.Spinner
        FolliclesLabel_2           matlab.ui.control.Label
        btnResetDefaults           matlab.ui.control.Button
        ObjectsColorPanel          matlab.ui.container.Panel
        ColorMaybe                 matlab.ui.control.Lamp
        ColorTrue                  matlab.ui.control.Lamp
        ColorSelected              matlab.ui.control.Lamp
        ColorFalse                 matlab.ui.control.Lamp
        btnColorMaybe              matlab.ui.control.Button
        btnColorTrue               matlab.ui.control.Button
        btnColorFalse              matlab.ui.control.Button
        btnColorSelected           matlab.ui.control.Button
        ZoomlevelPanel             matlab.ui.container.Panel
        cmbZoomEyelid              matlab.ui.control.Spinner
        EyelidLabel                matlab.ui.control.Label
        cmbZoomFollicles           matlab.ui.control.Spinner
        FolliclesLabel             matlab.ui.control.Label
        AboutTab                   matlab.ui.container.Tab
        TextArea                   matlab.ui.control.TextArea
        lblCopyright               matlab.ui.control.Label
        lblVersion                 matlab.ui.control.Label
        lblAppName                 matlab.ui.control.Label
        Image                      matlab.ui.control.Image
    end

    
    properties (Access = private)
        Version         = '2.11' ; % App version
        UserName        = '';
        ImagesFolder    = '';
        RowSelected     = []; % Row selected in Training UITable
        NetSelected     = []; % Row selected in NeuralNet UITable
        FollicleTypes   = containers.Map({'False','Maybe','True'},[-1,0,1]);
        DiagnosisTypes  = containers.Map({'TNormal','TF','TI', 'TF+TI', 'TS','TT','CO'}, [1,2,3,4,5,6,7]);
    end
    
    methods (Access = private)
        
        function RefreshAppTitle(app)
            app.lblVersion.Text = ['Version ' app.Version];
            if ~isempty(app.UserName)
                app.UIFigure.Name = ['Trachoma Finder ' app.Version ' - User: ' app.UserName];
            else
                app.UIFigure.Name = ['Trachoma Finder ' app.Version];
            end
        end
        
        function RefreshTrainingTable(app)
            % Refreshes the list of available traning session
            app.tblTraining.Data = listTraining;
            
            if isempty(app.RowSelected)
                app.btnDeleteRow.Enable             = 'off';
                app.btnExportTraining.Enable        = 'off';
                app.btnBatchMaskEyelid.Enable       = 'off';
                app.btnBatchMaskFollicles.Enable    = 'off';
                app.btnBatchSaveEachFollicle.Enable = 'off';
                app.btnBatchSaveBboxes.Enable       = 'off';
                app.btnRelinkImages.Enable          = 'off';
                app.btnClearEyelid.Enable           = 'off';
                app.btnClearFollicles.Enable        = 'off';
                app.btnMergeRecords.Enable          = 'off';
                app.btnDuplicateTraining.Enable     = 'off';
                app.btnNetGTruth.Enable             = 'off';
                app.btnChangeTrainingType.Enable    = 'off';
            end
            
            if isempty(app.ImagesFolder)
                app.btnAnnotateManual.Enable  = 'off';
            else
                app.btnAnnotateManual.Enable  = 'on';
            end
        end
        
        function RefreshNeuralNetTable(app)
            %Refreshes the list of available traning session
            app.tblNeuralNet.Data = listNeuralNet;
            
            if isempty(app.NetSelected)
                app.btnNetDelete.Enable     = 'off';
                app.btnNetClone.Enable      = 'off';
                app.btnNetRename.Enable     = 'off';
                app.btnNetExport.Enable     = 'off';
                app.btnNetInfo.Enable       = 'off';
                app.btnNetTrain.Enable      = 'off';
                app.btnNetTest.Enable       = 'off';
                app.btnAnnotateAuto.Enable    = 'off';
            end
            
            app.cmbNetTypeValueChanged;
        end
        
        function Prefs = getPrefsFollicles(app)
            Prefs                   = struct;
            Prefs.Type              = 'Follicles';
            Prefs.Zoom              = app.cmbZoomFollicles.Value;
            Prefs.actionType        = 'Add';
            Prefs.ColorMaybe        = ceil(app.ColorMaybe.Color.*255/2); % Pixel beloning to an unselected maybe dot
            Prefs.ColorFalse        = ceil(app.ColorFalse.Color.*255/2); % Pixel beloning to an unselected false dot
            Prefs.ColorValid        = ceil(app.ColorTrue.Color.*255/2); % Pixel beloning to an unselected valid dot
            Prefs.ColorSelected     = ceil(app.ColorSelected.Color.*255/2); % Pixel belonging to a selected dot
        end
        function Prefs = getPrefsEyelid(app)
            Prefs                   = struct;
            Prefs.Type              = 'Eyelid';
            Prefs.Zoom              = app.cmbZoomEyelid.Value;
            Prefs.actionType        = 'Enclose';
            Prefs.ColorMaybe        = ceil(app.ColorMaybe.Color.*255/2); % Pixel beloning to an unselected maybe dot
            Prefs.ColorFalse        = ceil(app.ColorFalse.Color.*255/2); % Pixel beloning to an unselected false dot
            Prefs.ColorValid        = ceil(app.ColorTrue.Color.*255/2); % Pixel beloning to an unselected valid dot
            Prefs.ColorSelected     = ceil(app.ColorSelected.Color.*255/2); % Pixel belonging to a selected dot
        end
        
        function Prefs = getPrefsAutoFollicles(app)
            Prefs = struct;
            Prefs.Type              = 'Follicles';
            Prefs.Zoom              = app.cmbZoomFollicles.Value;
            Prefs.actionType        = 'Select';
            Prefs.ColorMaybe        = ceil(app.ColorMaybe.Color.*255/2); % Pixel beloning to an unselected maybe dot
            Prefs.ColorFalse        = ceil(app.ColorFalse.Color.*255/2); % Pixel beloning to an unselected false dot
            Prefs.ColorValid        = ceil(app.ColorTrue.Color.*255/2); % Pixel beloning to an unselected valid dot
            Prefs.ColorSelected     = ceil(app.ColorSelected.Color.*255/2); % Pixel belonging to a selected dot
            Prefs.objfinder.maxDotSize   = inf;   % max dot size exclusion criteria.
            Prefs.objfinder.minDotSize   = 70;    % min dot size exclusion criteria.
            Prefs.objfinder.watershed    = true;  % False if you want to skip watershed segmentation during object search
            Prefs.objfinder.sphericity   = false; % True if you want to calculare sphericity
            Prefs.objfinder.minIntensity = 2;     % Minumum allowed voxel intensity (# times the local noise of the block)
            Prefs.objfinder.noiseEstimator = 'std';
        end
        
        function Prefs = getPrefsAutoFolliclesNN(app)
            Prefs = struct;
            Prefs.Type              = 'Follicles';
            Prefs.Zoom              = app.cmbZoomFollicles.Value;
            Prefs.actionType        = 'Select';
            Prefs.ColorMaybe        = ceil(app.ColorMaybe.Color.*255/2); % Pixel beloning to an unselected maybe dot
            Prefs.ColorFalse        = ceil(app.ColorFalse.Color.*255/2); % Pixel beloning to an unselected false dot
            Prefs.ColorValid        = ceil(app.ColorTrue.Color.*255/2); % Pixel beloning to an unselected valid dot
            Prefs.ColorSelected     = ceil(app.ColorSelected.Color.*255/2); % Pixel belonging to a selected dot

            Prefs.objfinder.maxDotSize   = inf;   % max dot size exclusion criteria.
            Prefs.objfinder.minDotSize   = 300;   % min dot size exclusion criteria.
            Prefs.objfinder.watershed    = true;  % False if you want to skip watershed segmentation during object search
            Prefs.objfinder.sphericity   = false; % True if you want to calculare sphericity
            Prefs.objfinder.minIntensity = 2;     % Minumum allowed voxel intensity (# times the local noise of the block)
            Prefs.objfinder.noiseEstimator = 'std';
        end
        
        
        function savePreferences(app)
            Follicles = app.getPrefsFollicles;
            Eyelid    = app.getPrefsEyelid;
            Net.FolliclesP = app.cmbNetConfidenceFollicles.Value;
            Net.EyelidP    = app.cmbNetConfidenceEyelid.Value;
            
            save([userpath filesep 'FollicleFinder' filesep 'Settings.mat'], 'Follicles', 'Eyelid', 'Net');
        end
        
        function loadPreferences(app)
            if ~exist([userpath filesep 'FollicleFinder'], 'dir')
                mkdir([userpath filesep 'FollicleFinder']);
            end
            if ~exist([userpath filesep 'FollicleFinder' filesep 'Settings.mat'], 'file')
                return
            end
            
            Prefs = load([userpath filesep 'FollicleFinder' filesep 'Settings.mat']);
            app.cmbZoomFollicles.Value  = Prefs.Follicles.Zoom;
            app.cmbZoomEyelid.Value     = Prefs.Eyelid.Zoom;
            app.ColorMaybe.Color        = Prefs.Follicles.ColorMaybe*2/256;
            app.ColorFalse.Color        = Prefs.Follicles.ColorFalse*2/256;
            app.ColorTrue.Color         = Prefs.Follicles.ColorValid*2/256;
            app.ColorSelected.Color     = Prefs.Follicles.ColorSelected*2/256;
            if isfield(Prefs, 'Net')
                app.cmbNetConfidenceEyelid.Value = Prefs.Net.EyelidP;
                app.cmbNetConfidenceFollicles.Value = Prefs.Net.FolliclesP;                
            end
            
        end
        
        
        function Obj = generateImgObject(~, ImgFileName, Img)
            % Generates an empty structure in which the properties of
            % objects found in image Img can be stored and a diagnosis can
            % be assigned to the image as well as scoring for trachomatous
            % features (Follicles, Scaring, Papillae, Corneal opacity)
            
            Obj.ImgFile      = ImgFileName;
            Obj.ImgSize      = [size(Img, 1), size(Img, 2)]; % Image size (pixels)
            Obj.ImgRes       = [1 ,1]; % Resolution (mm/pixels)
            Obj.Pos          = [];     % Position of identified objects
            Obj.Vox          = [];     % Pixels belonging to identified objects
            Obj.Filter       = [];     % 1 = valid, -1=false 0 = maybe
            Obj.Shape.Perim  = 0; % Shape properties (perimeter, length, roundness)
            Obj.Shape.LenMaj = 0; % Length of the major axis in pixels
            Obj.Shape.Round  = 0; % Roundness of the object (0=line, 1=circle)
            Obj.Shape.Orient = 0; % Orientation angle (0 = horizontal, +-90 = vertical)
            Obj.Scores.F     = 0; % Follicles presence for TF (F0-3)
            Obj.Scores.P     = 0; % Papillary inflammation, sign of TI (P0-3)
            Obj.Scores.C     = 0; % Conjunctival scaring, sign of TS (C0-3)
            Obj.Scores.TE    = 0; % Trichiasis/Entropion, sign of TT (T0-3)
            Obj.Scores.CC    = 0; % Corneal scarring opacity for CC (CC0-3)
            Obj.Diagnosis   = 'TNormal';
        end
        
        function NN = generateNeuralNet(~, NetName, NetTarget, NetType, ModelName, Net, layers)
            % Generates an empty neural network structure
            NN.UID       = generateUID;
            NN.Name      = NetName;
            NN.Date      = datetime;   % Current date
            NN.Target    = NetTarget;  % Net Targer: Eyelid / Follicles
            NN.Type      = NetType;    % Pixel classifier / Object detector / Object classifier
            NN.Model     = ModelName;  % Name of the network model (alexnet, googlenet, ...)
            NN.Net       = Net;        % Network model
            NN.Layers    = layers;
            NN.Trained   = ~isempty(Net);
        end
        
        
        function newPath = validatePath(~, fPath)
            % Adapt a filesystem path wrote by a different platform into current
            newPath = fPath;
            switch filesep
                case '/' % Unix
                    if contains(fPath, '\')
                        newPath = strrep(fPath, '\', filesep);
                    end
                case '\' % Windows
                    if contains(fPath, '/')
                        newPath = strrep(fPath, '/', filesep);
                    end
            end
        end
                                        
        function fixFocus(app)
            % Fixes an MATLAB glitch for which when a modal winow is
            % invoked, the main app loses its focus
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';                        
        end
        
        function I = loadImage(~, ImgFileName)
            % Loads an image from disk ensuring output is RGB
            I = imread(ImgFileName);
            switch size(I, 3)
                case 1
                    I = cat(3, I, I, I); % Convert B/W image to RGB
                case 4
                    I = I(:,:,1:3); % Ensure image only has RGB and not RGBA
            end           
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Center the dialog on main window
            posParent = get(0,'ScreenSize');
            pos = app.UIFigure.Position;
            pos(1) = ceil(posParent(1)+posParent(3)/2-pos(3)/2);
            pos(2) = ceil(posParent(2)+posParent(4)/2-pos(4)/2);
            app.UIFigure.Position = pos;             

            dlg = uiprogressdlg(app.UIFigure,'Title','Trachoma Finder','Indeterminate', 'on');
            app.RefreshAppTitle;
            dlg.Message = 'Loading annotations table...';
            pause(0.2);
            app.RefreshTrainingTable;
            pause(0.2);
            dlg.Message = 'Loading neural networks table...';
            app.RefreshNeuralNetTable;
            pause(0.2);
            dlg.Message = 'Loading user settings...';
            app.loadPreferences;
            close(dlg);
        end

        % Button pushed function: btnChooseUser
        function btnChooseUserButtonPushed(app, event)
            answer = inputdlg('User name:', 'Select user');
            
            if ~isempty(answer)
                app.UserName = answer{:};
                app.RefreshAppTitle;
                app.btnPhotosFolder.Enable = 'on';
            else
                app.btnPhotosFolder.Enable = 'off';
                app.btnAnnotateManual.Enable  = 'off';
            end
            
        end

        % Button pushed function: btnPhotosFolder
        function btnPhotosFolderButtonPushed(app, event)
            selpath = uigetdir(pwd, 'Select folder with photos to score');
                       
            if selpath == 0
                    return
            elseif exist(selpath,"dir")
                app.ImagesFolder = selpath;
                app.btnAnnotateManual.Enable = 'on';
            else
                app.ImagesFolder = '';
                app.btnAnnotateManual.Enable = 'off';
            end
            
            app.fixFocus;
        end

        % Button pushed function: btnAnnotateManual
        function btnAnnotateManualButtonPushed(app, event)
            Target = uiconfirm(app.UIFigure,'What are you annotating in these photos?','Detection method','Options',{'Eyelid','Follicles'},'DefaultOption', 1,'CancelOption',1);

            dlg = uiprogressdlg(app.UIFigure,'Title','Loading data...','Indeterminate', 'on');
            
            % Identify image files to score and store path to them in a list
            files = dir(app.ImagesFolder);  % List the content of ImageFolder
            files = files(~[files.isdir]);  % Keep only files (ignore subdirs)
            
            lstImages = {};
            for i = 1:numel(files)
                currFile = [files(i).folder filesep files(i).name];
                if isempty(lstImages)
                    lstImages = {currFile};
                else
                    lstImages{end+1} = currFile; %#ok
                end
            end
            
            % Create a new manual training session
            Training                = struct;
            Training.UID            = generateUID;
            Training.Type           = 'Manual';
            Training.Date           = datetime;
            Training.User           = app.UserName;
            Training.ImagesFolder   = app.ImagesFolder;
            Training.ImagesList     = lstImages;
            Training.ImagesTODOFollicles = lstImages(randperm(numel(lstImages))); % randomize order of images
            Training.ImagesTODOEyelid    = lstImages(randperm(numel(lstImages))); % randomize order of images
            Training.Follicles      = [];
            Training.Eyelid         = [];
            
            % If user selected a training, ask if to edit or start anew
            TODOmode = 'New';
            if ~isempty(app.RowSelected)
                switch uiconfirm(app.UIFigure,'Existing training selected: Complete unfinished, review analysed images or start anew?','Grading type','Options',{'Complete', 'Review', 'New'},'DefaultOption',1)
                    case 'Complete'
                        % Load existing training session
                        UID = app.tblTraining.Data{app.RowSelected(:,1),end}; % Retrieve UID
                        Training = loadTraining(UID{:});
                        TODOmode = 'Complete';
                    case 'Review'
                        % Load existing training session
                        UID = app.tblTraining.Data{app.RowSelected(:,1),end}; % Retrieve UID
                        Training = loadTraining(UID{:});
                        TODOmode = 'Review';
                end
            end
            
            switch Target
                case 'Eyelid'
                    Prefs = app.getPrefsEyelid;
                    TODOlist = Training.ImagesTODOEyelid;                    
                case 'Follicles'
                    Prefs = app.getPrefsFollicles;
                    TODOlist = Training.ImagesTODOFollicles;
            end
            
            switch TODOmode
                case {'New', 'Complete'}
                    switch Target
                        case 'Eyelid', TODOlist = Training.ImagesTODOEyelid;                    
                        case 'Follicles', TODOlist = Training.ImagesTODOFollicles;
                    end
                case 'Review'
                    switch Target
                        case 'Eyelid', TODOlist = setdiff(Training.ImagesList, Training.ImagesTODOEyelid);
                        case 'Follicles', TODOlist = setdiff(Training.ImagesList, Training.ImagesTODOFollicles);
                    end
                    [~,TODOfNames,~] = cellfun(@fileparts,TODOlist,'UniformOutput',false);
                    idx = listdlg('PromptString','Select one or more photos to review:','ListString',TODOfNames, 'InitialValue', 1:numel(TODOfNames), 'ListSize', [250 300], 'Name', 'Review selection');
                    if ~isempty(idx)
                        TODOlist = TODOlist(idx);
                    else
                        return
                    end
                    Prefs.Zoom = 1;
                    Prefs.actionType = 'Select';
            end
            
            % Propose one by one each image to the user to score
            dlg.Title = 'Manually grading photos...';
            for i = 1:numel(TODOlist)
                ImgFileName = TODOlist{i};
                [~,fName,fExt] = fileparts(ImgFileName);
                Prefs.Title = ['Image ' num2str(i) '/' num2str(numel(TODOlist)) ': ' fName fExt];
                
                try
                    Img = app.loadImage(ImgFileName);
                catch
                    disp(['Cannot read this image file: ' ImgFileName]);
                    continue;
                end
                
                switch TODOmode
                    case {'New', 'Complete'}
                        % Display custom UI to segment objects of interest
                        Obj = app.generateImgObject(ImgFileName, Img);
                        Annotated = inspectPhoto(Img, Obj, Prefs);                        
                        if isempty(Annotated)
                            break % User cancelled manual traning session
                        end
                        
                        % Append new annotated image and save progress
                        if isempty(Training.(Target))
                            Training.(Target) = Annotated;                            
                        else
                            Training.(Target)(end+1) = Annotated;
                        end
                        switch Target
                            case 'Eyelid', Training.ImagesTODOEyelid = setdiff(Training.ImagesTODOEyelid, ImgFileName);
                            case 'Follicles', Training.ImagesTODOFollicles = setdiff(Training.ImagesTODOFollicles, ImgFileName);
                        end                        
                        saveTraining(Training);
                        
                    case {'Review'}
                        % Find objects belonging to current image
                        Found = 0;
                        for j = 1:numel(Training.(Target))
                            if strcmp(Training.(Target)(j).ImgFile, ImgFileName)
                                Obj = Training.(Target)(j);
                                Found = j;
                                break
                            end
                        end
                        if Found == 0
                            disp(['Cannot find the' Target 'detected for ' ImgFileName]);
                            break
                        end
                        
                        % Display custom UI to segment objects of interest
                        Annotated = inspectPhoto(Img, Obj, Prefs);
                        if isempty(Annotated)
                            break % User cancelled manual traning session
                        end
                        
                        % Save the new manual training to disk
                        Training.(Target)(Found) = Annotated;
                        saveTraining(Training);
                end
            end
            close(dlg);
            app.RefreshTrainingTable;
        end

        % Cell selection callback: tblTraining
        function tblTrainingCellSelection(app, event)
            % Store the row selected by user
            app.RowSelected = event.Indices;
            if isempty(app.RowSelected)
                return
            end
            
            app.btnDeleteRow.Enable             = 'on';
            app.btnAnnotateManual.Enable        = 'on';
            app.btnDiagnoseTI.Enable            = 'on';
            app.btnExportTraining.Enable        = 'on';
            app.btnBatchMaskEyelid.Enable       = 'on';
            app.btnBatchMaskFollicles.Enable    = 'on';
            app.btnBatchColoredFollicles.Enable = 'on';
            app.btnBatchSaveEachFollicle.Enable = 'on';
            app.btnBatchSaveBboxes.Enable       = 'on';
            app.btnRelinkImages.Enable          = 'on';
            app.btnClearEyelid.Enable           = 'on';
            app.btnClearFollicles.Enable        = 'on';
            app.btnDuplicateTraining.Enable     = 'on';
            app.btnNetGTruth.Enable             = 'on';
            app.btnChangeTrainingType.Enable    = 'on';
            
            if size(app.RowSelected,1) > 1
                app.btnMergeRecords.Enable       = 'on';
                app.btnCompareGraders.Enable      = 'on';
            else
                app.btnMergeRecords.Enable      = 'off';
                app.btnCompareGraders.Enable     = 'off';
            end
        end

        % Button pushed function: btnDeleteRow
        function btnDeleteRowButtonPushed(app, event)
            if ~isempty(app.RowSelected)
                switch uiconfirm(app.UIFigure,'Do you really want to permanently remove selected training session?','Delete record','Options',{'No','Yes'},'DefaultOption',1,'CancelOption',1)
                    case 'Yes'
                        dlg = uiprogressdlg(app.UIFigure,'Title','Deleting records...','Indeterminate', 'on');
                        
                        for i = 1:size(app.RowSelected,1)
                            UID = app.tblTraining.Data{app.RowSelected(i,1),end}; % Retrieve UID
                            deleteTraining(UID{:}); % Delete saved training file matching UID
                        end
                        app.RefreshTrainingTable;
                        close(dlg);
                end
            end
        end

        % Button pushed function: btnRefreshTable
        function btnRefreshTableButtonPushed(app, event)
            dlg = uiprogressdlg(app.UIFigure,'Title','Refreshing tables','Indeterminate', 'on');
            dlg.Message = 'Loading annotations table...';
            app.RefreshTrainingTable;
            dlg.Message = 'Loading neural networks table...';
            app.RefreshNeuralNetTable;
            close(dlg);
        end

        % Button pushed function: btnColorSelected
        function btnColorSelectedButtonPushed(app, event)
            s = settings;
            oldcolorpicker = 'matlab.ui.internal.dialog.ColorChooser';
            % newcolorpicker = 'matlab.ui.internal.dialog.WebColorChooser';
            s.matlab.ui.dialog.uisetcolor.ControllerName.TemporaryValue = oldcolorpicker;
            Color = uisetcolor(app.ColorSelected.Color, 'Select color');
            if numel(Color)==3
                app.ColorSelected.Color = Color;
                app.savePreferences;
            end
            app.fixFocus;
        end

        % Button pushed function: btnColorTrue
        function btnColorTrueButtonPushed(app, event)
            s = settings;
            oldcolorpicker = 'matlab.ui.internal.dialog.ColorChooser';
            % newcolorpicker = 'matlab.ui.internal.dialog.WebColorChooser';
            s.matlab.ui.dialog.uisetcolor.ControllerName.TemporaryValue = oldcolorpicker;
            Color = uisetcolor(app.ColorTrue.Color, 'Select color');
            if numel(Color)==3
                app.ColorTrue.Color = Color;
                app.savePreferences;
            end
            app.fixFocus;
        end

        % Button pushed function: btnColorFalse
        function btnColorFalseButtonPushed(app, event)
            s = settings;
            oldcolorpicker = 'matlab.ui.internal.dialog.ColorChooser';
            % newcolorpicker = 'matlab.ui.internal.dialog.WebColorChooser';
            s.matlab.ui.dialog.uisetcolor.ControllerName.TemporaryValue = oldcolorpicker;
            Color = uisetcolor(app.ColorFalse.Color, 'Select color');
            if numel(Color)==3
                app.ColorFalse.Color = Color;
                app.savePreferences;
            end
            app.fixFocus;
        end

        % Button pushed function: btnColorMaybe
        function btnColorMaybeButtonPushed(app, event)
            s = settings;
            oldcolorpicker = 'matlab.ui.internal.dialog.ColorChooser';
            % newcolorpicker = 'matlab.ui.internal.dialog.WebColorChooser';
            s.matlab.ui.dialog.uisetcolor.ControllerName.TemporaryValue = oldcolorpicker;
            Color = uisetcolor(app.ColorMaybe.Color, 'Select color');
            if numel(Color)==3
                app.ColorMaybe.Color = Color;
                app.savePreferences;
            end
            app.fixFocus;
        end

        % Button pushed function: btnResetDefaults
        function btnResetDefaultsButtonPushed(app, event)
            % Reset to default settings values
            app.cmbZoomFollicles.Value  = 2;
            app.cmbZoomEyelid.Value     = 1;
            app.ColorSelected.Color     = [1 0 1];
            app.ColorTrue.Color         = [0 1 0];
            app.ColorFalse.Color        = [1 0 0];
            app.ColorMaybe.Color        = [1 1 0];
            app.savePreferences;
        end

        % Value changed function: cmbZoomFollicles
        function cmbZoomFolliclesValueChanged(app, event)
            app.savePreferences;
        end

        % Value changed function: cmbZoomEyelid
        function cmbZoomEyelidValueChanged(app, event)
            app.savePreferences;
        end

        % Button pushed function: btnNetGTruth
        function btnNetGTruthButtonPushed(app, event)
            % Choose a folder in which to write all binary masks
            if isempty(app.RowSelected)
                return
            end
            
            dstFolder = uigetdir([],'Select Destination folder');
            app.fixFocus;            
            if dstFolder == 0
                return
            elseif ~exist(dstFolder, 'dir')
                mkdir(dstFolder);
            end
            
            % Create different kinds of training data depending on NetType
            switch app.cmbNetType.Value
                case 'Pixel classifier'
                    if isfolder([dstFolder filesep 'GroundTruth'])
                        switch uiconfirm(app.UIFigure,'Data already present in folder, want to delete it?','Delete images','Options',{'No','Yes'},'DefaultOption',1,'CancelOption',1)
                            case 'Yes'
                                rmdir([dstFolder filesep 'GroundTruth'], 's');
                                rmdir([dstFolder filesep 'TrainingImages'], 's');
                        end
                    end
                    mkdir([dstFolder filesep 'GroundTruth']);
                    mkdir([dstFolder filesep 'TrainingImages']);
                    
                    S.Xresolution = 1024;
                    S.Yresolution = 768;
                    S = editStruct(S, 'Target image size (px)');
                    
                    dlg = uiprogressdlg(app.UIFigure,'Title','Generating training data...','Value',0, 'Cancelable', 'on');
                    dlg.Message = 'Creating ground truth image masks';
                    
                    switch app.cmbNetTarget.Value
                        case {'Eyelid'}
                            % Generate masks of the photos in which eyelid regions are white against black elsewhere
                            for t = 1:size(app.RowSelected,1)
                                UID = app.tblTraining.Data{app.RowSelected(t,1),end};
                                T   = loadTraining(UID{:}, {'User', 'Eyelid'});
                                
                                for i = 1:numel(T.Eyelid)
                                    [~, fName, fExt] = fileparts(T.Eyelid(i).ImgFile);
                                    dlg.Title = ['Processing photo ' num2str(i) '/' num2str(numel(T.Eyelid)) ' : ' fName fExt];
                                    dlg.Value = i/numel(T.Eyelid);
                                    
                                    % Generate and save eyelid mask images.
                                    M = zeros(T.Eyelid(i).ImgSize);
                                    M(T.Eyelid(i).Vox.Ind) = 255;
                                    M = imresize(M, [S.Yresolution S.Xresolution]);
                                    imwrite(uint8(M), fullfile([dstFolder filesep 'GroundTruth'], [fName fExt]));
                                    
                                    % Resize save original image files
                                    I = app.loadImage(T.Eyelid(i).ImgFile);
                                    I = imresize(I, [S.Yresolution S.Xresolution]);
                                    imwrite(uint8(I), fullfile([dstFolder filesep 'TrainingImages'], [fName fExt]));
                                end
                            end
                            close(dlg);
                            
                        case {'Follicles'}
                            % Generates masks of the photos in which follicle pixels are white against black elsewhere
                            for t = 1:size(app.RowSelected,1)
                                UID = app.tblTraining.Data{app.RowSelected(t,1),end};
                                T   = loadTraining(UID{:}, {'User', 'Follicles'});
                                
                                for i = 1:numel(T.Follicles)
                                    [~, fName, fExt] = fileparts(T.Follicles(i).ImgFile);
                                    dlg.Title = ['Processing photo ' num2str(i) '/' num2str(numel(T.Follicles)) ' : ' fName fExt];
                                    dlg.Value = i/numel(T.Follicles);
                                    
                                    % Generate and save follicles mask images.
                                    M = zeros(T.Follicles(i).ImgSize);
                                    for f = 1:numel(T.Follicles(i).Vox)
                                        M(T.Follicles(i).Vox(f).Ind) = 255;
                                    end
                                    M = imresize(M, [S.Yresolution S.Xresolution]);
                                    imwrite(uint8(M), fullfile([dstFolder filesep 'GroundTruth'], [fName fExt]));
                                    
                                    % Resize save original image files
                                    I = app.loadImage(T.Follicles(i).ImgFile);
                                    I = imresize(I, [S.Yresolution S.Xresolution]);
                                    imwrite(uint8(I), fullfile([dstFolder filesep 'TrainingImages'], [fName fExt]));
                                end
                            end
                            close(dlg);
                    end
                
                case 'Image classifier'
                    switch app.cmbNetTarget.Value
                        case 'TF'
                                if isfolder([dstFolder filesep 'TF'])
                                    switch uiconfirm(app.UIFigure,'Data already present in folder, want to delete it?','Delete images','Options',{'No','Yes'},'DefaultOption',1,'CancelOption',1)
                                        case 'Yes'
                                            rmdir([dstFolder filesep 'TF'], 's');
                                            rmdir([dstFolder filesep 'TN'], 's');
                                    end
                                end
                                mkdir([dstFolder filesep 'TF']);
                                mkdir([dstFolder filesep 'TN']);                                                                 
                    end
                    
                    S.Width  = 224;
                    S.Height = 224;
                    S.MaskEyelid = 0;
                    S.BlurNotEyelid = 0;
                    S = editStruct(S, 'Images size (px)');
                    
                    dlg = uiprogressdlg(app.UIFigure,'Title','Generating training data...','Value',0, 'Cancelable', 'on');
                    for t = 1:size(app.RowSelected,1)
                        UID = app.tblTraining.Data{app.RowSelected(t,1),end};
                        T   = loadTraining(UID{:}, {'User', 'Eyelid'});

                        for i = 1:numel(T.Eyelid)
                            [~, fName, fExt] = fileparts(T.Eyelid(i).ImgFile);
                            dlg.Title = ['Processing photo ' num2str(i) '/' num2str(numel(T.Eyelid)) ' : ' fName fExt];
                            dlg.Value = i/numel(T.Eyelid);

                            % Resize save original image files
                            I = app.loadImage(T.Eyelid(i).ImgFile);
                            I = imresize(I, [S.Height S.Width]);

                            if S.MaskEyelid
                                % Generate and save eyelid mask images.
                                M = zeros(T.Eyelid(i).ImgSize, 'uint8');
                                M(T.Eyelid(i).Vox.Ind) = 1;
                                M = imresize(M, [S.Height S.Width]);
                                I = I.*M;
                            end
                            
                            if S.BlurNotEyelid
                                % Generate a mask for outside the eyelid
                                Meyelid = zeros(T.Eyelid(i).ImgSize, 'uint8');
                                Meyelid(T.Eyelid(i).Vox.Ind) = 1;
                                Meyelid = imresize(Meyelid, [S.Height S.Width]);
                                Mnoteyelid = ones(T.Eyelid(i).ImgSize, 'uint8');
                                Mnoteyelid(T.Eyelid(i).Vox.Ind) = 0;
                                Mnoteyelid = imresize(Mnoteyelid, [S.Height S.Width]);
                                
                                % Generate a blurred version of photo
                                Iblur = app.loadImage(T.Eyelid(i).ImgFile);
                                Iblur = imgaussfilt(Iblur,40);
                                Iblur = imresize(Iblur, [S.Height S.Width]);
                                
                                % Merge in-focus eyelid with blurred outside eyelid
                                Iblur = Iblur.*Mnoteyelid;
                                I = I.*Meyelid;
                                I = I+Iblur;
                            end
                            
                            switch T.Eyelid(i).Diagnosis
                                case 'TNormal'
                                    imwrite(uint8(I), fullfile([dstFolder filesep 'TN'], [fName fExt]));
                                case 'TF'
                                    imwrite(uint8(I), fullfile([dstFolder filesep 'TF'], [fName fExt]));
                            end
                            
                            if dlg.CancelRequested
                                break % User wants to interrupt computation
                            end                            
                        end
                    end
                    close(dlg);
                
                case 'Object classifier'
                    % Create image cutouts for each true follicle & generate an equal amount of false follicles
                    switch app.cmbNetTarget.Value
                        case 'Eyelid'
                            if isfolder([dstFolder filesep 'Eyelid'])
                                switch uiconfirm(app.UIFigure,'Data already present in folder, want to delete it?','Delete images','Options',{'No','Yes'},'DefaultOption',1,'CancelOption',1)
                                    case 'Yes'
                                        rmdir([dstFolder filesep 'Eyelid'], 's');
                                        rmdir([dstFolder filesep 'Background'], 's');
                                end
                            end
                            mkdir([dstFolder filesep 'Eyelid']);
                            mkdir([dstFolder filesep 'Background']);
                        case 'Follicles'
                            if isfolder([dstFolder filesep 'Follicles'])
                                switch uiconfirm(app.UIFigure,'Data already present in folder, want to delete it?','Delete images','Options',{'No','Yes'},'DefaultOption',1,'CancelOption',1)
                                    case 'Yes'
                                        rmdir([dstFolder filesep 'Follicles'], 's');
                                        rmdir([dstFolder filesep 'Background'], 's');
                                end
                            end
                            mkdir([dstFolder filesep 'Follicles']);
                            mkdir([dstFolder filesep 'Background']);
                    end
                    
                    S.Width  = 224;
                    S.Height = 224;
                    S = editStruct(S, 'Images size (px)');
                    
                    dlg = uiprogressdlg(app.UIFigure,'Title','Generating training data...','Value',0, 'Cancelable', 'on');
                    
                    % If user selected 1 auto and 1 manual training, generate false follicles depending on whether the
                    % auto candidates overlap with one of the manually defined follicles
                    if size(app.RowSelected,1) == 2
                        tType1 = app.tblTraining.Data{app.RowSelected(1,1),3};
                        tType2 = app.tblTraining.Data{app.RowSelected(2,1),3};
                        
                        if (strcmp(tType1,'Manual') && strcmp(tType2,'Auto')) || (strcmp(tType2,'Manual') && strcmp(tType1,'Auto'))
                            gtSaveFalseAutoFollicles = true;
                            if strcmp(tType1,'Manual')
                                tManualIdx  = 1;
                                tAutoIdx    = 2;
                            else
                                tManualIdx  = 2;
                                tAutoIdx    = 1;
                            end
                        else
                            gtSaveFalseAutoFollicles = false;
                        end
                    else
                        gtSaveFalseAutoFollicles = false;
                    end
                    
                    if gtSaveFalseAutoFollicles
                        disp('Processing Manual+Auto follicle');
                        % Store each follice as a separate image in the proper training subfolder
                        UID = app.tblTraining.Data{app.RowSelected(tManualIdx,1),end};
                        TManual   = loadTraining(UID{:}, {'User', 'Follicles'});
                        UID = app.tblTraining.Data{app.RowSelected(tAutoIdx,1),end};
                        TAuto   = loadTraining(UID{:}, {'User', 'Follicles'});
                        dlg.Message = 'Extracting follicle identities from automatic grading based on manual groud truth';
                        
                        for i = 1:numel(TAuto.Follicles)
                            [~,fName,fExt] = fileparts(TAuto.Follicles(i).ImgFile);
                            dlg.Title = ['Processing photo ' num2str(i) '/' num2str(numel(TAuto.Follicles)) ' : ' fName fExt];
                            dlg.Value = i/numel(TAuto.Follicles);
                            
                            for j = 1:numel(TManual.Follicles)
                                if strcmp(TManual.Follicles(j).ImgFile, TAuto.Follicles(i).ImgFile)
                                    % Generate a mask of pixels already used to generate false follicles
                                    Imask = zeros(TManual.Follicles(j).ImgSize, "logical");
                                    for f = 1:numel(TManual.Follicles(j).Vox)
                                        Imask(TManual.Follicles(j).Vox(f).Ind) = true; % label all voxels belonging to voxels
                                    end
                                end
                            end
                            
                            for f = 1:numel(TAuto.Follicles(i).Vox)
                                % Retrieve current Follicle's pixel data
                                F = TAuto.Follicles(i).Vox(f);
                                
                                % Retrieve the intensity values of current follicle
                                minPt = min(F.Pos(:,1:2));
                                maxPt = max(F.Pos(:,1:2));
                                I = zeros([maxPt-minPt+1 3], 'uint8');
                                for k = 1:size(F.Pos, 1)
                                    pt = F.Pos(k,:) - minPt + 1;
                                    I(pt(1), pt(2),:) = F.RawBright(k,:);
                                end
                                I = imresize(I, [S.Height S.Width]);
                                
                                % The candidate follicle is valid if the centroid position falls within a manually validated follicle
                                TrueFollicle = Imask(TAuto.Follicles(i).Pos(f,1), TAuto.Follicles(i).Pos(f,2));
                                
                                if TrueFollicle
                                    imwrite(I, [dstFolder filesep 'Follicles' filesep TAuto.User '_img' fName '_follicle' num2str(f) fExt]);
                                else
                                    imwrite(I, [dstFolder filesep 'Background' filesep TAuto.User '_img' fName '_follicle' num2str(f) fExt]);
                                end
                            end
                            
                            if dlg.CancelRequested
                                break % User wants to interrupt computation
                            end
                        end
                    else
                        switch app.cmbNetTarget.Value
                            case {'Eyelid'}
                                % Ask user how many more random objects (1 = same as true objects, 2= twice as many)
                                R.RandomMultiplier = 1;
                                R = editStruct(R, 'Settings', {'Random over true ratio'});
                                if isempty(R)
                                    return
                                end
                                dlg.Message = ['Extracting eyelid cutouts and generating ' num2str(R.RandomMultiplier) 'X random cutouts'];
                                
                                % Store each follice as a separate image in the proper training subfolder
                                for t = 1:size(app.RowSelected,1)
                                    UID = app.tblTraining.Data{app.RowSelected(t,1),end};
                                    T   = loadTraining(UID{:}, {'User', 'Eyelid'});
                                    
                                    for i = 1:numel(T.Eyelid)
                                        [~,fName,fExt] = fileparts(T.Eyelid(i).ImgFile);
                                        dlg.Title = ['Processing photo ' num2str(i) '/' num2str(numel(T.Eyelid)) ' : ' fName fExt];
                                        dlg.Value = i/numel(T.Eyelid);
                                        
                                        % Generate a mask of pixels already used to generate false follicles
                                        Iphoto = app.loadImage(T.Eyelid(i).ImgFile);
                                        Imask = zeros(T.Eyelid(i).ImgSize, "logical");
                                        for f = 1:numel(T.Eyelid(i).Vox)
                                            Imask(T.Eyelid(i).Vox(f).Ind) = true; % label all voxels belonging to voxels
                                        end
                                        
                                        for f = 1:numel(T.Eyelid(i).Vox)
                                            if T.Eyelid(i).Filter(f) ~= 1
                                                continue % Only process valid Eyelid
                                            end
                                            
                                            % Retrieve current Eyelid's pixel data
                                            F = T.Eyelid(i).Vox(f);
                                            
                                            % Retrieve the intensity values of current Eyelid
                                            minPt = min(F.Pos(:,1:2));
                                            maxPt = max(F.Pos(:,1:2));
                                            I = zeros([maxPt-minPt+1 3], 'uint8');
                                            for k = 1:size(F.Pos, 1)
                                                pt = F.Pos(k,:) - minPt + 1;
                                                I(pt(1), pt(2),:) = Iphoto(F.Pos(k,1), F.Pos(k,2),:);
                                            end
                                            I = imresize(I, [S.Height S.Width]);
                                            imwrite(I, [dstFolder filesep 'Eyelid' filesep T.User '_img' fName '_eyelid' num2str(f) fExt]);
                                            
                                            % Generate 'r' false Eyelid with same shape of the true one but different texture
                                            for r = 1:R.RandomMultiplier
                                                
                                                % Find a random position in the image where no true follicles is present
                                                PosRndFound = false;
                                                %rng(0,'twister'); % initialize random number generator
                                                while ~PosRndFound
                                                    PosRndX = randi([1 size(Imask, 2)]);
                                                    PosRndY = randi([1 size(Imask, 1)]);
                                                    PosRndFound = ~Imask(PosRndY, PosRndX);
                                                    % Ensure we have enough pixels to take a fake follicle snippet
                                                    if PosRndX > minPt(2) || PosRndY > minPt(1)
                                                        PosRndFound = false;
                                                    end
                                                end
                                                
                                                % Create a false Eyelid (same shape and size but at random position)
                                                Ifalse = zeros([maxPt-minPt+1 3], 'uint8');
                                                for k = 1:size(F.Pos, 1)
                                                    pt = F.Pos(k,:) - minPt + 1;
                                                    Ifalse(pt(1), pt(2),:) = Iphoto(pt(1)+PosRndY, pt(2)+PosRndX, :);
                                                    Imask(pt(1), pt(2)) = true; % label voxels are used by this false Eyelid so next cannot be in this position
                                                end
                                                Ifalse = imresize(Ifalse, [S.Height S.Width]);
                                                imwrite(Ifalse, [dstFolder filesep 'Background' filesep T.User '_img' fName '_eyelid' num2str(f) '_'  num2str(r) '.jpg']);
                                            end
                                        end
                                        
                                        if dlg.CancelRequested
                                            break % User wants to interrupt computation
                                        end
                                        
                                    end
                                    
                                end
                                
                            case {'Follicles'}
                                % Ask user how many more random objects (1 = same as true objects, 2= twice as many)
                                R.FollicleShape = 1;
                                R.RandomMultiplier = 1;
                                R = editStruct(R, 'Settings');
                                if isempty(R)
                                    return
                                end
                                dlg.Message = ['Extracting follicle cutouts and generating ' num2str(R.RandomMultiplier) 'X random cutouts'];
                                
                                % Store each follice as a separate image in the proper training subfolder
                                for t = 1:size(app.RowSelected,1)
                                    UID = app.tblTraining.Data{app.RowSelected(t,1),end};
                                    T   = loadTraining(UID{:}, {'User', 'Follicles'});
                                    
                                    for i = 1:numel(T.Follicles)
                                        [~,fName,fExt] = fileparts(T.Follicles(i).ImgFile);
                                        dlg.Title = ['Processing photo ' num2str(i) '/' num2str(numel(T.Follicles)) ' : ' fName fExt];
                                        dlg.Value = i/numel(T.Follicles);
                                        
                                        % Generate a mask of pixels already used to generate false follicles
                                        Iphoto = app.loadImage(T.Follicles(i).ImgFile);
                                        Imask = zeros(T.Follicles(i).ImgSize, "logical");
                                        for f = 1:numel(T.Follicles(i).Vox)
                                            Imask(T.Follicles(i).Vox(f).Ind) = true; % label all voxels belonging to voxels
                                        end
                                        
                                        for f = 1:numel(T.Follicles(i).Vox)
                                            if T.Follicles(i).Filter(f) ~= 1
                                                continue % Only process valid follicles
                                            end
                                            
                                            % Retrieve current Follicle's pixel data
                                            F = T.Follicles(i).Vox(f);
                                            
                                            % Retrieve the intensity values of current follicle
                                            minPt = min(F.Pos(:,1:2));
                                            maxPt = max(F.Pos(:,1:2));
                                            if R.FollicleShape
                                                I = zeros([maxPt-minPt+1 3], 'uint8');
                                                for k = 1:size(F.Pos, 1)
                                                    pt = F.Pos(k,:) - minPt + 1;
                                                    I(pt(1), pt(2),:) = F.RawBright(k,:);
                                                end
                                            else
                                                I = Iphoto(minPt(1):maxPt(1), minPt(2):maxPt(2), :);
                                            end
                                            I = imresize(I, [S.Height S.Width]);                                            
                                            imwrite(I, [dstFolder filesep 'Follicles' filesep T.User '_img' fName '_follicle' num2str(f) fExt]);
                                            
                                            % Generate 'r' false follicles with same shape of the true one but different texture
                                            for r = 1:R.RandomMultiplier
                                                
                                                % Find a random position in the image where no true follicles is present
                                                PosRndFound = false;
                                                %rng(0,'twister'); % initialize random number generator
                                                while ~PosRndFound
                                                    PosRndX = randi([1 size(Imask, 2)]);
                                                    PosRndY = randi([1 size(Imask, 1)]);
                                                    PosRndFound = ~Imask(PosRndY, PosRndX);
                                                    % Ensure we have enough pixels to take a fake follicle snippet
                                                    if PosRndX > minPt(2) || PosRndY > minPt(1)
                                                        PosRndFound = false;
                                                    end
                                                end
                                                
                                                % Create a false follicle (same shape and size but at random position)
                                                if R.FollicleShape
                                                Ifalse = zeros([maxPt-minPt+1 3], 'uint8');
                                                for k = 1:size(F.Pos, 1)
                                                    pt = F.Pos(k,:) - minPt + 1;
                                                    Ifalse(pt(1), pt(2),:) = Iphoto(pt(1)+PosRndY, pt(2)+PosRndX, :);
                                                    Imask(pt(1), pt(2)) = true; % label voxels are used by this false folllicle so next cannot be in this position
                                                end
                                                else
                                                    Ifalse = Iphoto(PosRndY:maxPt(1)-minPt(1)+1+PosRndY, PosRndX:maxPt(2)-minPt(2)+1+PosRndX, :);
                                                end                                                
                                                Ifalse = imresize(Ifalse, [S.Height S.Width]);
                                                imwrite(Ifalse, [dstFolder filesep 'Background' filesep T.User '_img' fName '_follicle' num2str(f) '_'  num2str(r) '.jpg']);
                                            end
                                        end
                                        
                                        if dlg.CancelRequested
                                            break % User wants to interrupt computation
                                        end
                                        
                                    end
                                    
                                end
                        end
                        
                    end
                    close(dlg);
                    
                case 'Object detector'
                    % Create individual image cutouts and boundingboxes for each true follicle & generate an equal amount of false follicles
                    if isfolder([dstFolder filesep 'TrainingImages'])
                        rmdir([dstFolder filesep 'TrainingImages'], 's');
                    end
                    mkdir([dstFolder filesep 'TrainingImages']);
                    
                    % Ask user for preferences
                    S.Width     = 1024;
                    S.Height    = 768;
                    S           = editStruct(S, 'Target images size (px)');
                    if isempty(S)
                        return
                    end                    
                    
                    % Store each follice bounding box coordinates in a table and resize images
                    tblF = []; % Table storing coordinates of bounding boxes for false follicles
                    dlg  = uiprogressdlg(app.UIFigure,'Title','Generating training data...','Value',0, 'Cancelable', 'on');
                    dlg.Message = 'Generating table of bounding boxes';
                    
                    for t = 1:size(app.RowSelected,1)
                        UID = app.tblTraining.Data{app.RowSelected(t,1),end};
                        T   = loadTraining(UID{:}, {'User', 'Follicles'});
                        
                        for i = 1:numel(T.Follicles)
                            [~,fName,fExt] = fileparts(T.Follicles(i).ImgFile);
                            dlg.Title = ['Processing photo ' num2str(i) '/' num2str(numel(T.Follicles)) ' : ' fName fExt];
                            dlg.Value = i/numel(T.Follicles);
                            
                            % Generate a mask of pixels already used to generate false follicles
                            
                            srcImg = app.loadImage(T.Follicles(i).ImgFile);
                            scalingX = S.Width / size(srcImg,2);
                            scalingY = S.Height / size(srcImg,1);
                            dstImg = imresize(srcImg, [S.Height S.Width]);
                            imwrite(dstImg, [dstFolder filesep 'TrainingImages' filesep fName fExt]);
                            
                            bBoxes = [];
                            for f = 1:numel(T.Follicles(i).Vox)
                                if T.Follicles(i).Filter(f) ~= 1
                                    continue % Only process valid follicles
                                end
                                
                                % Calculate bounding box from pixel data
                                F = T.Follicles(i).Vox(f);

                                xMin    = min(F.Pos(:,2));
                                xMax    = max(F.Pos(:,2));
                                width   = xMax - xMin;

                                yMin    = min(F.Pos(:,1));
                                yMax    = max(F.Pos(:,1));
                                height  = yMax - yMin;
                                                                
                                xMinScaled   = ceil(xMin*scalingX);
                                yMinScaled   = ceil(yMin*scalingY);
                                widthScaled  = ceil(width*scalingX);
                                heightScaled = ceil(height*scalingY);
                                
                                if f==1
                                    bBoxes = [xMinScaled, yMinScaled, widthScaled, heightScaled];
                                else
                                    bBoxes = [bBoxes;[xMinScaled, yMinScaled, widthScaled, heightScaled]]; %#ok
                                end
                            end
                            
                            if ~isempty(bBoxes)
                                % Populate Bounding boxes table
                                dstFileName = [dstFolder filesep 'TrainingImages' filesep fName fExt];
                                if isempty(tblF)
                                    tblF = table({dstFileName}, {bBoxes},...
                                        'VariableNames', {'Filename', 'Follicles'});
                                else
                                    tblF = [tblF; table({dstFileName}, {bBoxes},...
                                        'VariableNames', {'Filename', 'Follicles'})]; %#ok
                                end
                                
                                % Write tables to disk
                                if ~isempty(tblF)
                                    save([dstFolder filesep 'BoundingBoxes.mat'],"tblF");
                                end
                            end
                            
                            if dlg.CancelRequested
                                break % User wants to interrupt computation
                            end
                        end
                    end
                    
                    close(dlg);
            end
        end

        % Button pushed function: btnNetTrain
        function btnNetTrainButtonPushed(app, event)
            if isempty(app.NetSelected)
                return
            end
            
            % Ask user to specify common training options
            S.InitialLearnRate = 1e-2;
            S.Momentum = 0.9;
            S.MaxEpochs = 10;
            S.MiniBatchSize = 8;
            S.ValidationFrequency = 200;
            S.LearnRateSchedule = 'none';
            S.ExecutionEnvironment = 'gpu';
            S.Shuffle = 'every-epoch';
            S = editStruct(S, 'Settings');
            if isempty(S)
                return
            end
            
            % Choose a folder in which to write all binary masks
            gtFolder = uigetdir([],'Select Training folder');
            app.fixFocus;
            if gtFolder == 0
                return
            elseif ~isfolder(gtFolder)
                return
            end
            
            % Load network from disk and ensure layers are defined
            % TODO: check if this way to extract layers keeps also weights from prev trainings
            dlg = uiprogressdlg(app.UIFigure,'Title','Loading data...','Indeterminate', 'on');
            UID          = app.tblNeuralNet.Data{app.NetSelected(:,1),end}; % Retrieve UID
            NN           = loadNeuralNet(UID{:});
            if ~isfield(NN, 'Layers')
                if isa(NN.Net,'SeriesNetwork')
                    NN.Layers = layerGraph(NN.Net.Layers);
                else
                    NN.Layers = layerGraph(NN.Net);
                end
            end
            
            switch app.cmbNetType.Value
                case 'Pixel classifier'
                    % Create the training image datastore
                    classNames      = ["background", app.cmbNetTarget.Value];
                    labelIDs        = [0 255];
                    imageAugmenter  = imageDataAugmenter('RandRotation',[-20,20], 'RandXReflection',1);
                    imds            = imageDatastore([gtFolder filesep 'TrainingImages']);
                    pxdsTruth       = pixelLabelDatastore([gtFolder filesep 'GroundTruth'], classNames, labelIDs);
                    trainData       = pixelLabelImageDatastore(imds, pxdsTruth, 'DataAugmentation', imageAugmenter);
                    
                    % Create the validation image datastore
                    valFolder       = uigetdir([],'Select Validation folder');
                    app.fixFocus;                 
                    valImDS         = imageDatastore([valFolder filesep 'TrainingImages']);
                    valpxdsTruth    = pixelLabelDatastore([valFolder filesep 'GroundTruth'], classNames, labelIDs);
                    valDS           = pixelLabelImageDatastore(valImDS, valpxdsTruth);
                    
                    % Define training options according to user choice
                    NN.trainOpts = trainingOptions(...
                        'sgdm', ...
                        'LearnRateSchedule', S.LearnRateSchedule,...
                        'InitialLearnRate', S.InitialLearnRate, ...
                        'MaxEpochs', S.MaxEpochs, ...
                        'Shuffle', S.Shuffle,...
                        'Plots','training-progress',...
                        'ExecutionEnvironment','multi-gpu',...
                        'MiniBatchSize',S.MiniBatchSize,...
                        'ValidationData', valDS,...
                        'ValidationFrequency', S.ValidationFrequency);
                    
                    % Replace the final layer
                    switch app.cmbNetModel.Value
                        case 'FCN Eyelid'
                            tbl             = countEachLabel(trainData);
                            totalNumPixels  = sum(tbl.PixelCount);
                            frequency       = tbl.PixelCount / totalNumPixels;
                            classWeights    = 1./frequency;
                            NN.Layers(end)  = pixelClassificationLayer('Classes',tbl.Name,'ClassWeights',classWeights);
                            
                        case 'U-Net'
                            tbl             = countEachLabel(trainData);
                            totalNumPixels  = sum(tbl.PixelCount);
                            frequency       = tbl.PixelCount / totalNumPixels;
                            classWeights    = 1./frequency;
                            pxLayer         = pixelClassificationLayer('Name', 'Segmentation-Layer', 'Classes',tbl.Name,'ClassWeights',classWeights);
                            NN.Layers       = removeLayers(NN.Layers, 'Segmentation-Layer');
                            NN.Layers       = addLayers(NN.Layers, pxLayer);
                            NN.Layers       = connectLayers(NN.Layers, 'Softmax-Layer' ,'Segmentation-Layer');
                            
                        case 'SegNet'
                            tbl             = countEachLabel(trainData);
                            totalNumPixels  = sum(tbl.PixelCount);
                            frequency       = tbl.PixelCount / totalNumPixels;
                            classWeights    = 1./frequency;
                            pxLayer         = pixelClassificationLayer('Name', 'pixelLabels', 'Classes',tbl.Name,'ClassWeights',classWeights);
                            NN.Layers       = removeLayers(NN.Layers, 'pixelLabels');
                            NN.Layers       = addLayers(NN.Layers, pxLayer);
                            NN.Layers       = connectLayers(NN.Layers, 'softmax' ,'pixelLabels');
                            
                        case 'DeepLab v3+'
                            tbl             = countEachLabel(trainData);
                            totalNumPixels  = sum(tbl.PixelCount);
                            frequency       = tbl.PixelCount / totalNumPixels;
                            classWeights    = 1./frequency;
                            pxLayer         = pixelClassificationLayer('Name', 'classification', 'Classes',tbl.Name,'ClassWeights',classWeights);
                            NN.Layers       = removeLayers(NN.Layers, 'classification');
                            NN.Layers       = addLayers(NN.Layers, pxLayer);
                            NN.Layers       = connectLayers(NN.Layers, 'softmax-out' ,'classification');
                    end
                    
                    % Train the network
                    dlg.Title = 'Training network...';
                    NN.Net = trainNetwork(trainData, NN.Layers, NN.trainOpts);
                    
                case 'Object classifier'
                    % Load the training set and split images in a randomized way according to user defined split ratio
                    imds = imageDatastore(gtFolder,'IncludeSubfolders',true,'LabelSource','foldernames');
                    [trainingImages, validationImages] = splitEachLabel(imds, 0.9,'randomized');
                    
                    % Augment training dataset if chosen by user, otherwise just conform to size required by net
                    % trainingImages   = augmentedImageDatastore(inputSize(1:2),trainingImages); % No image augmentation
                    inputSize = NN.Layers.Layers(1).InputSize;
                    
                    pixelRange       = [-30 30];
                    scaleRange       = [0.9 1.1];
                    imageAugmenter   = imageDataAugmenter('RandXReflection',true, 'RandXTranslation',pixelRange, 'RandYTranslation',pixelRange, 'RandXScale',scaleRange, 'RandYScale',scaleRange);
                    trainingImages   = augmentedImageDatastore(inputSize(1:2),trainingImages, 'DataAugmentation',imageAugmenter);
                    
                    % Define training options
                    numIterationsPerEpoch   = floor(trainingImages.NumObservations/S.MiniBatchSize);
                    NN.trainOpts = trainingOptions(...
                        'sgdm',...
                        'MiniBatchSize',S.MiniBatchSize,...
                        'MaxEpochs',S.MaxEpochs,...
                        'Shuffle', S.Shuffle,...
                        'InitialLearnRate',S.InitialLearnRate,...
                        'LearnRateSchedule', S.LearnRateSchedule,...
                        'Verbose',false,...
                        'Plots','training-progress',...
                        'ExecutionEnvironment', S.ExecutionEnvironment,...
                        'ValidationData',validationImages,...
                        'ValidationFrequency',numIterationsPerEpoch);
                    
                    % Train the network using training dataet
                    NN.Net      = trainNetwork(trainingImages, NN.Layers, NN.trainOpts);
                    predicted   = classify(NN.Net, augmentedImageDatastore(inputSize(1:2), validationImages));
                    expected    = validationImages.Labels;
                    accuracy    = sum(predicted == expected) / numel(expected);
                    disp(['NeuralNet accuracy = ' num2str(accuracy)]);

                case 'Image classifier'
                    % Load the training set and split images in a randomized way according to user defined split ratio
                    imds = imageDatastore(gtFolder,'IncludeSubfolders',true,'LabelSource','foldernames');
                    [trainingImages, validationImages] = splitEachLabel(imds, 0.8,'randomized');
                    
                    % Augment training dataset if chosen by user, otherwise just conform to size required by net
                    % trainingImages   = augmentedImageDatastore(inputSize(1:2),trainingImages); % No image augmentation
                    inputSize = NN.Layers.Layers(1).InputSize;
                    
%                     pixelRange       = [-30 30];
%                     scaleRange       = [0.9 1.1];
%                     imageAugmenter   = imageDataAugmenter('RandXReflection',true, 'RandXTranslation',pixelRange, 'RandYTranslation',pixelRange, 'RandXScale',scaleRange, 'RandYScale',scaleRange);
%                     trainingImages   = augmentedImageDatastore(inputSize(1:2),trainingImages, 'DataAugmentation',imageAugmenter);
                    
                    % Define training options
                    numIterationsPerEpoch   = floor(numel(trainingImages.Files)/S.MiniBatchSize);
                    NN.trainOpts = trainingOptions(...
                        'sgdm',...
                        'MiniBatchSize',S.MiniBatchSize,...
                        'MaxEpochs',S.MaxEpochs,...
                        'Shuffle', S.Shuffle,...
                        'InitialLearnRate',S.InitialLearnRate,...
                        'LearnRateSchedule', S.LearnRateSchedule,...
                        'Verbose',false,...
                        'Plots','training-progress',...
                        'ExecutionEnvironment', S.ExecutionEnvironment,...
                        'ValidationData',validationImages,...
                        'ValidationFrequency',numIterationsPerEpoch);
                    
                    % Train the network using training dataet
                    NN.Net      = trainNetwork(trainingImages, NN.Layers, NN.trainOpts);
                    predicted   = classify(NN.Net, augmentedImageDatastore(inputSize(1:2), validationImages));
                    expected    = validationImages.Labels;
                    accuracy    = sum(predicted == expected) / numel(expected);
                    disp(['NeuralNet accuracy = ' num2str(accuracy)]);
                    
                case 'Object detector'
                    switch app.cmbNetModel.Value
                        case 'YOLOv2'
                            % Load the training set and split images in a randomized way according to user defined split ratio
                            trainingData = load([gtFolder filesep 'BoundingBoxes.mat']);
                            trainingData = trainingData.tblF;
                            
                            % Randomly shuffle training data
                            rng(0);
                            shuffledIdx  = randperm(height(trainingData));
                            trainingData = trainingData(shuffledIdx,:);
                            
                            % Create the training image datastore
                            imds        = imageDatastore(trainingData.Filename);
                            blds        = boxLabelDatastore(trainingData(:,2));
                            ds          = combine(imds, blds);
                            
                            NN.trainOpts = trainingOptions('sgdm', ...
                                'MiniBatchSize', S.MiniBatchSize, ....
                                'LearnRateSchedule', S.LearnRateSchedule,...
                                'InitialLearnRate', S.InitialLearnRate, ...
                                'MaxEpochs', S.MaxEpochs,...
                                'Verbose', true,...
                                'VerboseFrequency', S.ValidationFrequency,...
                                'ExecutionEnvironment', S.ExecutionEnvironment,...
                                'Shuffle', S.Shuffle);
                            
                            % Train YOLO v2 detector.
                            dlg.Title = 'Training network...';                            
                            NN.Net = trainYOLOv2ObjectDetector(ds, NN.Layers, NN.trainOpts);
                            
                        case 'SSD'
                            % Load the training set and split images in a randomized way according to user defined split ratio
                            trainingData = load([gtFolder filesep 'BoundingBoxes.mat']);
                            trainingData = trainingData.tblF;
                            
                            % Randomly shuffle training data
                            rng(0);
                            shuffledIdx  = randperm(height(trainingData));
                            trainingData = trainingData(shuffledIdx,:);
                            
                            % Create the training image datastore
                            imds        = imageDatastore(trainingData.Filename);
                            blds        = boxLabelDatastore(trainingData(:,2));
                            ds          = combine(imds, blds);
                            
                            NN.trainOpts = trainingOptions('sgdm', ...
                                'MiniBatchSize', S.MiniBatchSize, ....
                                'LearnRateSchedule', S.LearnRateSchedule,...
                                'InitialLearnRate', S.InitialLearnRate, ...
                                'MaxEpochs', S.MaxEpochs,...
                                'Verbose', true,...
                                'VerboseFrequency', S.ValidationFrequency,...
                                'ExecutionEnvironment', S.ExecutionEnvironment,...
                                'Shuffle', S.Shuffle);
                            
                            % Train YOLO v2 detector.
                            dlg.Title = 'Training network...';                            
                            NN.Net = trainSSDObjectDetector(ds, NN.Layers, NN.trainOpts);

                        case 'Faster R-CNN'
                            % Load the training set and split images in a randomized way according to user defined split ratio
                            trainingData = load([gtFolder filesep 'BoundingBoxes.mat']);
                            trainingData = trainingData.tblF;
                            
                            % Randomly shuffle training data
                            rng(0);
                            shuffledIdx  = randperm(height(trainingData));
                            trainingData = trainingData(shuffledIdx,:);
                            
                            % Create the training image datastore
                            imds        = imageDatastore(trainingData.Filename);
                            blds        = boxLabelDatastore(trainingData(:,2));
                            ds          = combine(imds, blds);
                            
                            NN.trainOpts = trainingOptions('sgdm', ...
                                'MiniBatchSize', S.MiniBatchSize, ....
                                'InitialLearnRate', S.InitialLearnRate, ...
                                'LearnRateSchedule', S.LearnRateSchedule,...
                                'MaxEpochs', S.MaxEpochs,...
                                'verbose', true,...
                                'Shuffle', 'never');
                            
                            
                            % Train Faster R-CNN detector
                            NN.Net = trainFasterRCNNObjectDetector(ds, NN.Layers, NN.trainOpts);
                    end
            end
            
            NN.Trained      = true;
            NN.Date         = datetime;
            saveNeuralNet(NN);
            
            dlg.Title = 'Cleaning Video Card(s) memory...';
            % clear GPU memory
            for i=1:gpuDeviceCount
                gpuDevice(i);
                reset(gpuDevice(i));
                wait(gpuDevice(i));
            end
            
            dlg.Title = 'Refreshing networks table...';
            app.RefreshNeuralNetTable;
            close(dlg);
        end

        % Button pushed function: btnNetTest
        function btnNetTestButtonPushed(app, event)
            if isempty(app.NetSelected) || isempty(app.RowSelected)
                return
            end
            
            switch app.cmbNetType.Value
                case 'Pixel classifier'
                    dstFolder = uigetdir(pwd,"Select test results destination folder");
                    app.fixFocus;
                    if dstFolder == 0
                        return
                    elseif ~isfolder(dstFolder)
                        return
                    end
                    
                    dlg = uiprogressdlg(app.UIFigure,'Title','Test Network','Indeterminate', 'on');
                    dlg.Message = 'Loading data...';
                    UID = app.tblNeuralNet.Data{app.NetSelected(:,1),end}; % Retrieve UID
                    NN = loadNeuralNet(UID);
                    try
                        inputSize = NN.Layers.Layers(1).InputSize;
                    catch
                        inputSize = NN.Layers(1).InputSize;
                    end
                    
                    UID = app.tblTraining.Data{app.RowSelected(:,1),end}; % Retrieve UID
                    Training = loadTraining(UID);
                    if isempty(Training)
                        return
                    end
                    
                    dlg.Message = 'Predicting pixel identities...';
                    dlg.Indeterminate = 'off';
                    dlg.Cancelable = 'on';
                    dlg.Value = 0;
                    
                    Target = app.cmbNetTarget.Value; % Eyelid / Follicles
                    ImagesNum   = numel(Training.(NN.Target));
                    Accuracy    = zeros(ImagesNum, 1);
                    Precision   = zeros(ImagesNum, 1);
                    Sensitivity = zeros(ImagesNum, 1);
                    Specificity = zeros(ImagesNum, 1);
                    F1score     = zeros(ImagesNum, 1);
                    DICEcoef    = zeros(ImagesNum, 1);
                    IoUcoef     = zeros(ImagesNum, 1);                    
                    FileNames   = {Training.(Target).ImgFile}';
                    
                    for i = 1: ImagesNum
                        [~, fName, fExt] = fileparts(Training.(Target)(i).ImgFile);
                        dlg.Message = ['Processing photo ' num2str(i) '/' num2str(ImagesNum) ' : ' fName fExt];
                        dlg.Value = i/ImagesNum;
                        
                        % Load original image and predict pixel identity
                        srcImage = app.loadImage(Training.(Target)(i).ImgFile);
                        
                        % Predict categories and convert into a binary mask
                        [Prediction, ~, AllScores] = semanticseg(imresize(srcImage, inputSize(1:2)), NN.Net);
                        
                        % Calculate a heatmap of segmentation scores
                        ScoresRF = AllScores(:,:,2)-AllScores(:,:,1);
                        ScoresRF(ScoresRF < 0) = 0;
                        
                        
                        switch app.cmbNetTarget.Value
                            case 'Eyelid'
                                MP = zeros(size(Prediction));
                                %MP(Prediction == NN.Target) = 1;
                                MP(ScoresRF > app.cmbNetConfidenceEyelid.Value) = 1;
                                
                                % Post-process result to close gaps
                                MP = medfilt2(MP);
                                MP = imclose(MP, strel('disk',10));
                                MP = imfill(MP);
                                MP = imresize(MP, [size(srcImage, 1) size(srcImage, 2)]);
                                
                                % Keep only largest connected object in mask (eyelid)
                                Labels = bwlabel(MP,8);
                                LargestLabel = mode(Labels(Labels ~= mode(Labels, 'all')), 'all');
                                MP(Labels ~= LargestLabel) = 0;
                                
                                % Create ground truth eyelid mask
                                M = zeros(Training.Eyelid(i).ImgSize);
                                M(Training.Eyelid(i).Vox.Ind) = 255;
                                
                            case 'Follicles'
                                MP = zeros(size(Prediction));
                                %MP(Prediction == NN.Target) = 1;
                                MP(ScoresRF > app.cmbNetConfidenceFollicles.Value) = 1;
                                MP = imresize(MP, [size(srcImage, 1) size(srcImage, 2)]);
                                
                                % Create ground truth follicles mask
                                M = zeros(Training.Follicles(i).ImgSize);
                                for f = 1:numel(Training.Follicles(i).Vox)
                                    M(Training.Follicles(i).Vox(f).Ind) = 255;
                                end
                        end
                        
                        % Calculate prediction accuracy, precision, sensitivity, specificity, F1 score
                        CP = find(M);  % Condition Positives = Real positive values from ground truth
                        CN = find(~M); % Condition Negatives = Real negative values from ground truth
                        PP = find(MP); % Predicted Positive
                        PN = find(~MP); % Predicted Negative
                        
                        P = numel(CP); % Real Positive values
                        N = numel(CN); % Real Negative values
                        TP = numel(intersect(PP,CP)); % True Positive predictions
                        TN = numel(intersect(PN,CN)); % True Negative predictions
                        FP = numel(intersect(PP,CN)); % False Positive predictions
                        FN = numel(intersect(PN,CP)); %#ok False Negative predictions
                        
                        if P > 0
                            Sensitivity(i)= TP / P;
                        end
                        if (P+N) > 0
                            Accuracy(i)   = (TP+TN) / (P+N);
                        end
                        if (TP+FP) > 0
                            Precision(i)  = TP / (TP+FP);
                        end
                        if N > 0
                            Specificity(i)= TN / N;
                        end
                        if (Precision(i) + Sensitivity(i)) > 0
                            F1score(i) = 2*Precision(i)*Sensitivity(i) / (Precision(i) + Sensitivity(i));
                        end
                        
                        % Jaccart coefficient (IoU) = (a intesect b)/(a union b)
                        % Sorensen-Dice coefficient = 2*(a intesect b)/(|a| + |b|)
                        Mbool = logical(M);
                        MPbool = logical(MP);
                        MPandM = MPbool & Mbool;
                        MPorM = MPbool | Mbool;
                        IoUcoef(i) = sum(MPandM(:)) / sum(MPorM(:)); 
                        DICEcoef(i) = 2 * sum(MPandM(:)) / ( sum(MPbool(:)) + sum(Mbool(:)) );                         
                        
                        % Save predicted and ground truth eyelid masks as images in dstFolder
                        imwrite(imresize(srcImage, inputSize(1:2)), fullfile(dstFolder, [fName '_RawImage'   fExt]));
                        imwrite(imresize(MP, inputSize(1:2)), fullfile(dstFolder, [fName '_Predicted'   fExt]));
                        imwrite(imresize(M, inputSize(1:2)), fullfile(dstFolder, [fName '_GroundTruth' fExt]));
                        imwrite(ScoresRF, fullfile(dstFolder, [fName '_Scores'      fExt]));
                        disp(['Filename: ' fName ', Precision:' num2str(Precision(i)) ', Recall(Sensitivity):' num2str(Sensitivity(i)) ', F1 score:' num2str(F1score(i)) ', IoU:' num2str(IoUcoef(i)) ', DICE:' num2str(DICEcoef(i))]);
                        drawnow;
                        
                        if dlg.CancelRequested
                            close(dlg);
                            return % User wants to interrupt computation
                        end
                    end
                    
                    close(dlg);
                    T = table(FileNames, Precision, Sensitivity, IoUcoef, DICEcoef);
                    writetable(T,[dstFolder filesep 'validationData.xls']);
                    
                case 'Object detector'
                    S.ResizeImages = 1;
                    S.Threshold = 0.5;
                    S.SelectStrongest = 1;
                    S = editStruct(S);
                    
                    dlg = uiprogressdlg(app.UIFigure,'Title','Loading data...','Indeterminate', 'on');
                    UID = app.tblNeuralNet.Data{app.NetSelected(:,1),end}; % Retrieve UID
                    NN = loadNeuralNet(UID);
                    try
                        inputSize = NN.Layers.Layers(1).InputSize;
                    catch
                        inputSize = NN.Layers(1).InputSize;
                    end
                    
                    UID = app.tblTraining.Data{app.RowSelected(:,1),end}; % Retrieve UID
                    Training = loadTraining(UID);
                    if isempty(Training)
                        return
                    end
                    close(dlg);
                    dlg = uiprogressdlg(app.UIFigure,'Title','Detecting objects...','Value',0, 'Cancelable', 'on');
                    
                    ImagesNum = numel(Training.(NN.Target));
                    
                    for i = 1: ImagesNum
                        [~, fName, fExt] = fileparts(Training.(NN.Target)(i).ImgFile);
                        dlg.Title = ['Processing photo ' num2str(i) '/' num2str(ImagesNum) ' : ' fName fExt];
                        dlg.Value = i/ImagesNum;
                        
                        I = app.loadImage(Training.(NN.Target)(i).ImgFile);                        
                        if S.ResizeImages
                            I = imresize(I,inputSize(1:2));
                        end
                        
                        [bboxes, scores, labels] = detect(NN.Net, I, 'Threshold', S.Threshold, 'SelectStrongest',S.SelectStrongest); %#ok
                        
                        if ~isempty(bboxes)
                            I = insertObjectAnnotation(I,'Rectangle',bboxes,cellstr(labels));
                            %figure;
                            imshow(I);
                        else
                            disp([fName fExt ': No objects found']);
                        end
                    end
                    
                    close(dlg);
                    
                case {'Image classifier', 'Object classifier'}
                    gtFolder = uigetdir(pwd,"Select test images folder");
                    app.fixFocus;
                    if gtFolder == 0
                        return
                    elseif ~isfolder(gtFolder)
                        return
                    end
                    
                    dlg = uiprogressdlg(app.UIFigure,'Title','Testing network','Indeterminate', 'on');
                    dlg.Message = 'Loading images...';                   
                    imds = imageDatastore(gtFolder,'IncludeSubfolders',true,'LabelSource','foldernames');
                    
                    dlg.Message = 'Loading neural net...';
                    UID = app.tblNeuralNet.Data{app.NetSelected(:,1),end}; % Retrieve UID
                    NN = loadNeuralNet(UID);

                    dlg.Message = 'Classifying...';
                    predicted = classify(NN.Net, imds);
                    expected = imds.Labels;
                    accuracy = sum(predicted == expected) / numel(expected);
                    disp(NN.Name);                    
                    disp(['Network accuracy' num2str(accuracy)]);

                    plotconfusion(expected, predicted);
                    title([NN.Name ' (accuracy: ' num2str(accuracy, '%.2f') ')']);

                    cats = num2str(countcats(expected));
                    cats = strjoin(cellstr(cats), ' /');
                    xlabel(['Expected: ' cats]);

                    cats = num2str(countcats(predicted));
                    cats = strjoin(cellstr(cats), ' /');
                    ylabel(['Predicted: ' cats]);
                    close(dlg);
                    
            end
        end

        % Button pushed function: btnNetCreate
        function btnNetCreateButtonPushed(app, event)
            % Ask user what size images will be used to train
            S.Name = 'New NeuralNet';
            S.Xsize = 1024;
            S.Ysize = 768;
            switch app.cmbNetModel.Value
                case 'FCN Eyelid'
                    S.FilterSize = 3;
                    S.NumFilters = 48;
                case 'U-Net'
                    S.EncoderDepth = 4;
                    S.NumFirstEncoderFilters = 32;
                    S.FilterSize = 3;
                case 'SegNet'
                    S.EncoderDepth = 8;
                    S.NumConvolutionLayers = 2;
                    S.NumOutputChannels = 64;
                    S.FilterSize = 15;
                case 'DeepLab v3+'
                    S.Network = 'resnet50';
                    S.DownsamplingSize = 8;
                case {'ResNet-50', 'ResNet-101'}
                    S.Xsize = 224;
                    S.Ysize = 224;
                case {'Inception-ResNet-v2', 'Inception-v3'}
                    S.Xsize = 299;
                    S.Ysize = 299;
                case 'YOLOv2'
                    S.NumAnchorBoxes = 3;
                    S.FilterSize = 3;
                case 'Faster R-CNN'
                    S.NumAnchorBoxes = 3;
                case 'SSD'
                    S.Model = 'resnet50';
            end
            S = editStruct(S, 'Settings');
            if isempty(S)
                return
            end
            
            dlg = uiprogressdlg(app.UIFigure,'Title','Creating neural network...','Indeterminate', 'on');
            
            % Create either an empty network or a pretrained network for transfer learning
            try
                switch app.cmbNetModel.Value
                    case 'AlexNet',             net = eval('alexnet');
                    case 'VGG-16',              net = eval('vgg16');
                    case 'VGG-19',              net = eval('vgg19');
                    case 'SqueezeNet',          net = eval('squeezenet');
                    case 'GoogleNet',           net = eval('googlenet');
                    case 'Inception-v3',        net = eval('inceptionv3');
                    case 'ResNet-50',           net = eval('resnet50');
                    case 'ResNet-101',          net = eval('resnet101');
                    case 'Inception-ResNet-v2', net = eval('inceptionresnetv2');
                    otherwise,                  net = [];
                end
            catch
                msgbox([app.cmbNetModel.Value ' not available, please install it using the AddOn explorer'], 'NeuralNet Error', 'error');
                return
            end
            
            % Create or adjust the net layers
            numClasses = 2;
            inputSize  = [S.Ysize S.Xsize 3];
            
            switch app.cmbNetModel.Value
                case 'FCN Eyelid'
                    layers = [
                        imageInputLayer(inputSize)
                        convolution2dLayer(S.FilterSize, S.NumFilters,'Padding',1)
                        reluLayer()
                        maxPooling2dLayer(2,'Stride',2)
                        convolution2dLayer(S.FilterSize, S.NumFilters,'Padding',1)
                        reluLayer()
                        transposedConv2dLayer(4, S.NumFilters,'Stride',2,'Cropping',1);
                        convolution2dLayer(1,numClasses);
                        softmaxLayer()
                        pixelClassificationLayer()
                        ];
                case 'SegNet'
                    % Create SetNet with 2 classes
                    layers = segnetLayers(inputSize, numClasses, S.EncoderDepth, 'FilterSize', [S.FilterSize S.FilterSize]);
                case 'DeepLab v3+'
                    % Create DeepLav v3+ with 2 classes
                    layers = deeplabv3plusLayers(inputSize,numClasses,S.Network, 'DownsamplingFactor', S.DownsamplingSize);
                case 'U-Net'
                    %layers = app.createUNet([S.Ysize S.Xsize 3], 2);
                    layers = unetLayers(inputSize, numClasses, 'EncoderDepth', S.EncoderDepth, 'NumFirstEncoderFilters', S.NumFirstEncoderFilters, 'FilterSize', S.FilterSize);
                case 'YOLOv2'
                    % Load training data (bounding boxes stored as table)
                    gtFolder = uigetdir([],'Select Training folder');
                    app.fixFocus;
                    if gtFolder == 0
                        return
                    end
                    
                    trainingData = load([gtFolder filesep 'BoundingBoxes.mat']);
                    trainingData = trainingData.tblF;
                    
                    % Estimate anchor boxes size from the training data
                    blds = boxLabelDatastore(trainingData(:,2));
                    Anchors = estimateAnchorBoxes(blds, S.NumAnchorBoxes);
                    
                    % Create YOLOv2 network
                    inputLayer = imageInputLayer(inputSize,'Name','input','Normalization','none');
                    filterSize = [S.FilterSize S.FilterSize];
                    middleLayers = [
                        convolution2dLayer(filterSize, 16, 'Padding', 1,'Name','conv_1',...
                        'WeightsInitializer','narrow-normal')
                        batchNormalizationLayer('Name','BN1')
                        reluLayer('Name','relu_1')
                        maxPooling2dLayer(2, 'Stride',2,'Name','maxpool1')
                        convolution2dLayer(filterSize, 32, 'Padding', 1,'Name', 'conv_2',...
                        'WeightsInitializer','narrow-normal')
                        batchNormalizationLayer('Name','BN2')
                        reluLayer('Name','relu_2')
                        maxPooling2dLayer(2, 'Stride',2,'Name','maxpool2')
                        convolution2dLayer(filterSize, 64, 'Padding', 1,'Name','conv_3',...
                        'WeightsInitializer','narrow-normal')
                        batchNormalizationLayer('Name','BN3')
                        reluLayer('Name','relu_3')
                        maxPooling2dLayer(2, 'Stride',2,'Name','maxpool3')
                        convolution2dLayer(filterSize, 128, 'Padding', 1,'Name','conv_4',...
                        'WeightsInitializer','narrow-normal')
                        batchNormalizationLayer('Name','BN4')
                        reluLayer('Name','relu_4')
                        ]; 
                    lgraph = layerGraph([inputLayer; middleLayers]);                    
                    numClasses = size(trainingData,2)-1;
                    layers = yolov2Layers(inputSize, numClasses, Anchors, lgraph, 'relu_4');
                    
                case 'SSD'
                    % Load training data (bounding boxes stored as table)
                    gtFolder = uigetdir([],'Select Training folder');
                    app.fixFocus;                    
                    if gtFolder == 0
                        return
                    end
                    
                    trainingData    = load([gtFolder filesep 'BoundingBoxes.mat']);
                    trainingData    = trainingData.tblF;
                    
                    % Generate a SSD network layers
                    numClasses = size(trainingData,2)-1;
                    layers = ssdLayers(inputSize, numClasses, 'resnet50');
                    
                case 'Faster R-CNN'
                    % Load training data (bounding boxes stored as table)
                    gtFolder = uigetdir([],'Select Training folder');
                    app.fixFocus;
                    if gtFolder == 0
                        return
                    end
                    
                    trainingData    = load([gtFolder filesep 'BoundingBoxes.mat']);
                    trainingData    = trainingData.tblF;
                    
                    % Estimate anchor boxes size from the training data
                    blds            = boxLabelDatastore(trainingData(:,2));
                    anchorBoxes     = estimateAnchorBoxes(blds, S.NumAnchorBoxes);
                    
                    % Create Faster R-CNN with 1 class, for images of 1024x768 input size
                    numClasses      = 1; % TODO: Check if perhaps needs 2 = background + follicles
                    UID             = app.tblNeuralNet.Data{app.NetSelected(:,1),end}; % Retrieve UID
                    baseNet         = loadNeuralNet(UID);   % Load the feature extraction network (ResNet-50)
                    baseLayer       = 'activation_40_relu'; % Specify the feature extraction layer (specific to ResNet-50)
                    layers          = fasterRCNNLayers(inputSize, numClasses, anchorBoxes, baseNet.Net, baseLayer);
                otherwise
                    % Retrieve net layers graph
                    if isa(net,'SeriesNetwork')
                        layers = layerGraph(net.Layers);
                    else
                        layers = layerGraph(net);
                    end
                    
                    % Check whether last learnable layer is fully connected or convolutional
                    learnableLayer = layers.Layers(end-2);
                    if isa(learnableLayer,'nnet.cnn.layer.FullyConnectedLayer')
                        newLearnableLayer = fullyConnectedLayer(numClasses, 'Name','FF_fc', 'WeightLearnRateFactor',20, 'BiasLearnRateFactor',20);
                    elseif isa(learnableLayer,'nnet.cnn.layer.Convolution2DLayer')
                        newLearnableLayer = convolution2dLayer(1,numClasses, 'Name','FF_conv', 'WeightLearnRateFactor',20, 'BiasLearnRateFactor',20);
                    end
                    
                    % Replace and connect last 3 layers with a new clasification layer based on numClasses
                    layers = removeLayers(layers, {layers.Layers(end-2:end).Name});
                    layers = addLayers(layers, [newLearnableLayer, softmaxLayer('Name', 'FF_softmax'), classificationLayer('Name','FF_output')]);
                    layers = connectLayers(layers, layers.Layers(end-3).Name, layers.Layers(end-2).Name);
            end
            
            NN = app.generateNeuralNet(S.Name, app.cmbNetTarget.Value, app.cmbNetType.Value, app.cmbNetModel.Value, net, layers);
            dlg.Title = 'Saving network to disk...';
            saveNeuralNet(NN);
            dlg.Title = 'Refreshing networks table...';
            app.RefreshNeuralNetTable;
            close(dlg);
        end

        % Cell selection callback: tblNeuralNet
        function tblNeuralNetCellSelection(app, event)
            % Store the row selected by user
            app.NetSelected = event.Indices;
            if isempty(app.NetSelected)
                return
            end

            if app.tblNeuralNet.Data{app.NetSelected(:,1),6}
                NetTrained = 'on';
            else
                NetTrained = 'off';
            end
            
            app.btnNetDelete.Enable     = 'on';
            app.btnNetClone.Enable      = 'on';
            app.btnNetRename.Enable     = 'on';
            app.btnNetImport.Enable     = 'on';
            app.btnNetExport.Enable     = 'on';
            app.btnNetInfo.Enable       = 'on';
            app.btnNetTrain.Enable      = 'on';
            app.btnNetTest.Enable       = NetTrained;
            app.cmbNetTarget.Value      = app.tblNeuralNet.Data{app.NetSelected(end,1),2};
            app.cmbNetType.Value        = app.tblNeuralNet.Data{app.NetSelected(end,1),3};
            NetModel = app.tblNeuralNet.Data{app.NetSelected(:,1),4};
            NetModel = NetModel{:};
            app.cmbNetTypeValueChanged;
            if find(strcmp(app.cmbNetModel.Items, NetModel))
                app.cmbNetModel.Value   = NetModel;
            else
                %app.cmbNetModel.Value   = app.cmbNetModel.Items{1};
            end
            
            switch app.cmbNetTarget.Value
                case 'Eyelid'
                    app.btnAnnotateAuto.Enable = 'on';
                    app.btnDiagnoseTF.Enable = 'off';
                case 'Follicles'
                    app.btnAnnotateAuto.Enable = 'off';
                    switch app.cmbNetType.Value
                        case 'Pixel classifier'
                            app.btnDiagnoseTF.Enable = 'on';
                            app.btnDiagnoseTI.Enable = 'on';
                            app.btnAnnotateAuto.Enable = 'on';
                        case 'Object classifier'
                            app.btnDiagnoseTF.Enable = 'on';
                            app.btnDiagnoseTI.Enable = 'on';
                            app.btnAnnotateAuto.Enable = 'off';
                        case 'Object detector'
                            app.btnDiagnoseTF.Enable = 'on';
                            app.btnDiagnoseTI.Enable = 'on';
                            app.btnAnnotateAuto.Enable = 'on';
                    end
            end

        end

        % Button pushed function: btnNetDelete
        function btnNetDeleteButtonPushed(app, event)
            if ~isempty(app.NetSelected)
                switch uiconfirm(app.UIFigure,'Do you really want to permanently remove selected NeuralNet?','Delete NeuralNet','Options',{'No','Yes'},'DefaultOption',1,'CancelOption',1)
                    case 'Yes'
                        dlg = uiprogressdlg(app.UIFigure,'Title','Deleting network from disk...','Indeterminate', 'on');
                        NetUIDs  = app.tblNeuralNet.Data{app.NetSelected(:,1),end};
                        for i = 1:numel(NetUIDs)
                            deleteNeuralNet(NetUIDs{i}); % Delete saved training file matching UID
                        end
                        app.NetSelected = [];
                        dlg.Title = 'Refreshing networks table...';
                        app.RefreshNeuralNetTable;
                        close(dlg);
                end
            end
        end

        % Button pushed function: btnNetClone
        function btnNetCloneButtonPushed(app, event)
            if ~isempty(app.NetSelected)
                UID = app.tblNeuralNet.Data{app.NetSelected(:,1),end}; % Retrieve UID
                
                dlg = uiprogressdlg(app.UIFigure,'Title','Cloning neural network...','Indeterminate', 'on');
                NN = loadNeuralNet(UID);
                NN.UID = generateUID;
                NN.Name = [NN.Name ' (copy)'];
                saveNeuralNet(NN);
                app.NetSelected = [];
                
                dlg.Title = 'Refreshing networks table...';
                app.RefreshNeuralNetTable;
                close(dlg);
            end
        end

        % Button pushed function: btnNetInfo
        function btnNetInfoButtonPushed(app, event)
            if ~isempty(app.NetSelected)
                dlg = uiprogressdlg(app.UIFigure,'Title','Inspecting neural network...','Indeterminate', 'on');
                
                UID = app.tblNeuralNet.Data{app.NetSelected(:,1),end}; % Retrieve UID
                NN = loadNeuralNet(UID);
                try
                    if NN.Trained
                        NN.trainOpts
                    end
                    analyzeNetwork(NN.Layers);
                catch
                    close(dlg);
                    uiconfirm(app.UIFigure,'Error retrieving NeuralNet properties','Error','Options',{'Ok'})
                end
                close(dlg);
            end
        end

        % Button pushed function: btnNetRename
        function btnNetRenameButtonPushed(app, event)
            if isempty(app.NetSelected)
                return
            end
            
            dlg = uiprogressdlg(app.UIFigure,'Title','Renaming network...','Indeterminate', 'on');
            
            % Load selected NeuralNet
            UID = app.tblNeuralNet.Data{app.NetSelected(:,1),end}; % Retrieve UID
            NN = loadNeuralNet(UID, {'UID', 'Name'});
            
            % Ask user what size images will be used to train
            S.Name = NN.Name;
            S = editStruct(S, 'Change name');
            if isempty(S)
                return
            end
            NN.Name = S.Name;
            saveNeuralNet(NN, 'Name');
            
            dlg.Title = 'Refreshing networks table...';
            app.RefreshNeuralNetTable;
            
            close(dlg);
        end

        % Button pushed function: btnNetImport
        function btnNetImportButtonPushed(app, event)
            % Choose a neural network file to import
            [srcFile, srcPath] = uigetfile('*.mat', 'Select file to import');
            app.fixFocus;
            if srcFile == 0
                return
            end
            
            % Copy the selected file into local Training repository
            dlg = uiprogressdlg(app.UIFigure,'Title','Importing network from disk...','Indeterminate', 'on');
            dstPath = [userpath filesep 'FollicleFinder' filesep 'NeuralNet'];
            if ~isfolder(dstPath)
                mkdir(dstPath);
            end
            copyfile(fullfile(srcPath, srcFile), fullfile(dstPath, srcFile));
            app.RefreshNeuralNetTable;
            close(dlg);
        end

        % Button pushed function: btnBatchResize
        function btnBatchResizeButtonPushed(app, event)
            % Identify image files to score and store path to them in a list
            srcFolder = uigetdir([], 'Source folder');
            files = dir(srcFolder);  % List the content of ImageFolder
            files = files(~[files.isdir]);  % Keep only files (ignore subdirs)
            
            dstFolder = uigetdir([], 'Destination folder');
            if ~exist(dstFolder, 'dir')
                mkdir(dstFolder);
            end
            
            S.Xresolution = 1024;
            S.Yresolution = 768;
            S = editStruct(S, 'Target size (px)');
            app.fixFocus;
            lstImages = {};
            for i = 1:numel(files)
                currFile = [files(i).folder filesep files(i).name];
                if isempty(lstImages)
                    lstImages = {currFile};
                else
                    lstImages{end+1} = currFile; %#ok
                end
            end
            
            dlg = uiprogressdlg(app.UIFigure,'Title','Resizing photos...','Value',0, 'Cancelable', 'on');
            for i = 1:numel(lstImages)
                [~, fName, fExt] = fileparts(lstImages{i});
                dlg.Title = ['Processing photo ' num2str(i) '/' num2str(numel(lstImages)) ' : ' fName];
                dlg.Value = i/numel(lstImages);
                
                try
                    srcImg = app.loadImage(lstImages{i});
                    dstImg = imresize(srcImg, [S.Yresolution S.Xresolution], 'nearest');
                    imwrite(dstImg, fullfile(dstFolder, [fName fExt]));
                catch
                    disp(['Error processing ' fName fExt]);
                end
                
                if dlg.CancelRequested
                    break % User wants to interrupt computation
                end
            end
            close(dlg);
        end

        % Button pushed function: btnBatchMaskEyelid
        function btnBatchMaskEyelidButtonPushed(app, event)
            % Load selected training session
            if ~isempty(app.RowSelected)
                UID = app.tblTraining.Data{app.RowSelected(:,1),end}; % Retrieve UID
                Training = loadTraining(UID{:});
            end
            
            % Choose a folder in which to write all binary masks
            dstFolder = uigetdir([],'Select Destination folder');
            app.fixFocus;            
            if dstFolder == 0
                return
            elseif ~exist(dstFolder, 'dir')
                mkdir(dstFolder);
            end
            
            switch uiconfirm(app.UIFigure,'Create masks for eyelids or follicles?','Export masks','Options',{'Eyelid','Follicles'},'DefaultOption',1,'CancelOption',1)
                case 'Eyelid'
                    % Extract eyelid region as a binary image TIF
                    dlg = uiprogressdlg(app.UIFigure,'Title','Extracting eyelids...','Value',0, 'Cancelable', 'on');
                    for i = 1:numel(Training.Eyelid)
                        [~, fName, ~] = fileparts(Training.Eyelid(i).ImgFile);
                        dlg.Title = ['Processing photo ' num2str(i) '/' num2str(numel(Training.Eyelid)) ' : ' fName];
                        dlg.Value = i/numel(Training.Eyelid);

                        I = zeros(Training.Eyelid(i).ImgSize);
                        I(Training.Eyelid(i).Vox.Ind) = 255;
                        imwrite(I, fullfile(dstFolder, [fName '.JPG']));

                        if dlg.CancelRequested
                            break % User wants to interrupt computation
                        end
                    end
                    close(dlg);
                    
                case 'Follicles'
                    % Extract follicles region as a binary image TIF
                    dlg = uiprogressdlg(app.UIFigure,'Title','Extracting follicles...','Value',0, 'Cancelable', 'on');
                    for i = 1:numel(Training.Follicles)
                        [~, fName, ~] = fileparts(Training.Follicles(i).ImgFile);
                        dlg.Title = ['Processing photo ' num2str(i) '/' num2str(numel(Training.Follicles)) ' : ' fName];
                        dlg.Value = i/numel(Training.Follicles);

                        I = zeros(Training.Follicles(i).ImgSize);
                        for f = 1:numel(Training.Follicles(i).Vox)
                            I(Training.Follicles(i).Vox(f).Ind) = 255;
                        end
                        imwrite(I, fullfile(dstFolder, [fName '.tif']));

                        if dlg.CancelRequested
                            break % User wants to interrupt computation
                        end
                    end
                    close(dlg);
            end
        end

        % Button pushed function: btnBatchSaveEachFollicle
        function btnBatchSaveEachFollicleButtonPushed(app, event)
            % Load selected training session
            if ~isempty(app.RowSelected)
                UID = app.tblTraining.Data{app.RowSelected(:,1),end}; % Retrieve UID
                T = loadTraining(UID{:});
            end
            
            % Choose a folder in which to write all binary masks
            dstFolder = uigetdir([],'Select Destination folder');
            app.fixFocus;
            if dstFolder == 0
                return
            end
            if ~exist(dstFolder, 'dir'), mkdir(dstFolder); end
            if ~exist([dstFolder filesep 'Follicles'], 'dir'), mkdir([dstFolder filesep 'Follicles']);end
            if ~exist([dstFolder filesep 'Background'], 'dir'), mkdir([dstFolder filesep 'Background']);end

            % Ask user how to cutout follicles (1 = using follicle shape, 0= using bounding box square)
            R.FollicleShape = 1;
            R = editStruct(R, 'Settings', {'Cutout follicle shape'});
            if isempty(R)
                return
            end
            
            % Extract each eyelid region as a binary image TIF
            dlg = uiprogressdlg(app.UIFigure,'Title','Extracting follicles...','Value',0, 'Cancelable', 'on');
            for i = 1:numel(T.Follicles)
                [~, fName, ~] = fileparts(T.Follicles(i).ImgFile);
                dlg.Title = ['Processing photo ' num2str(i) '/' num2str(numel(T.Follicles)) ' : ' fName];
                dlg.Value = i/numel(T.Follicles);
                Img = app.loadImage(T.Follicles(i).ImgFile);                        
                
                for f = 1:numel(T.Follicles(i).Vox)
                    % Retrieve current Follicle's pixel data
                    F = T.Follicles(i).Vox(f);
                    
                    % Retrieve the intensity values of current follicle
                    minPt = min(F.Pos(:,1:2));
                    maxPt = max(F.Pos(:,1:2));
                    if R.FollicleShape
                        I = zeros([maxPt-minPt+1 3], 'uint8');
                        for k = 1:size(F.Pos, 1)
                            pt = F.Pos(k,:) - minPt + 1;
                            I(pt(1), pt(2),:) = F.RawBright(k,:);
                        end
                    else
                        I = Img(minPt(1):maxPt(1), minPt(2):maxPt(2), :);
                    end
                    % Store the follicles into 'True/False/Maybe' folder based upon their valudation status
                    switch T.Follicles(i).Filter(f)
                        case 1, imwrite(I, [dstFolder filesep 'Follicles' filesep T.User '_img' fName '_follicle' num2str(f) '.jpg']);
                        case -1, imwrite(I, [dstFolder filesep 'Background' filesep T.User '_img' fName '_follicle' num2str(f) '.jpg']);
                    end
                end
                
                if dlg.CancelRequested
                    break % User wants to interrupt computation
                end
            end
            close(dlg);
        end

        % Button pushed function: btnBatchMaskFollicles
        function btnBatchMaskFolliclesButtonPushed(app, event)
            % Load selected training session
            if ~isempty(app.RowSelected)
                UID = app.tblTraining.Data{app.RowSelected(:,1),end}; % Retrieve UID
                T = loadTraining(UID{:});
            end
            
            % Choose a folder in which to write all binary masks
            dstFolder = uigetdir([],'Select Destination folder');
            if dstFolder == 0
                return
            elseif ~exist(dstFolder, 'dir')
                mkdir(dstFolder);
            end     
            
            S.Width  = 224;
            S.Height = 224;
            S.BlurOutsideMask = 0;
            S = editStruct(S, 'Images size (px)');
            app.fixFocus;

            switch uiconfirm(app.UIFigure,'Create masks for eyelids or follicles?','Export masks','Options',{'Eyelid','Follicles'},'DefaultOption',1,'CancelOption',1)
                case 'Eyelid'
                    dlg = uiprogressdlg(app.UIFigure,'Title','Exporting photos','Value',0, 'Cancelable', 'on');
                    for t = 1:size(app.RowSelected,1)

                        for i = 1:numel(T.Eyelid)
                            [~, fName, fExt] = fileparts(T.Eyelid(i).ImgFile);
                            dlg.Title = ['Processing photo ' num2str(i) '/' num2str(numel(T.Eyelid)) ' : ' fName fExt];
                            dlg.Value = i/numel(T.Eyelid);

                            % Resize save original image files
                            I = app.loadImage(T.Eyelid(i).ImgFile);
                            I = imresize(I, [S.Height S.Width]);

                            % Generate and save eyelid mask images.
                            M = zeros(T.Eyelid(i).ImgSize, 'uint8');
                            M(T.Eyelid(i).Vox.Ind) = 1;
                            M = imresize(M, [S.Height S.Width]);
                            I = I.*M;

                            if S.BlurOutsideMask
                                % Generate a mask for outside the eyelid
                                Meyelid = zeros(T.Eyelid(i).ImgSize, 'uint8');
                                Meyelid(T.Eyelid(i).Vox.Ind) = 1;
                                Meyelid = imresize(Meyelid, [S.Height S.Width]);
                                Mnoteyelid = ones(T.Eyelid(i).ImgSize, 'uint8');
                                Mnoteyelid(T.Eyelid(i).Vox.Ind) = 0;
                                Mnoteyelid = imresize(Mnoteyelid, [S.Height S.Width]);

                                % Generate a blurred version of photo
                                Iblur = app.loadImage(T.Eyelid(i).ImgFile);
                                Iblur = imgaussfilt(Iblur,40);
                                Iblur = imresize(Iblur, [S.Height S.Width]);

                                % Merge in-focus eyelid with blurred outside eyelid
                                Iblur = Iblur.*Mnoteyelid;
                                I = I.*Meyelid;
                                I = I+Iblur;
                            end

                            imwrite(uint8(I), fullfile(dstFolder, [fName fExt]));

                            if dlg.CancelRequested
                                break % User wants to interrupt computation
                            end
                        end
                    end
                    close(dlg);

                case 'Follicles'
                    dlg = uiprogressdlg(app.UIFigure,'Title','Exporting photos','Value',0, 'Cancelable', 'on');
                    for t = 1:size(app.RowSelected,1)

                        for i = 1:numel(T.Follicles)
                            [~, fName, fExt] = fileparts(T.Follicles(i).ImgFile);
                            dlg.Title = ['Processing photo ' num2str(i) '/' num2str(numel(T.Follicles)) ' : ' fName fExt];
                            dlg.Value = i/numel(T.Follicles);

                            % Resize save original image files
                            I = app.loadImage(T.Follicles(i).ImgFile);
                            I = imresize(I, [S.Height S.Width]);

                            % Generate and save eyelid mask images.
                            M = zeros(T.Follicles(i).ImgSize, 'uint8');
                            for f = 1:numel(T.Follicles(i).Vox)
                                M(T.Follicles(i).Vox(f).Ind) = 1;
                            end                            
                            M = imresize(M, [S.Height S.Width]);
                            I = I.*M;

                            if S.BlurOutsideMask
                                % Generate a mask for outside the eyelid
                                Mfollicles = zeros(T.Follicles(i).ImgSize, 'uint8');
                                for f = 1:numel(T.Follicles(i).Vox)
                                    Mfollicles(T.Follicles(i).Vox(f).Ind) = 1;
                                end
                                Mfollicles = imresize(Mfollicles, [S.Height S.Width]);

                                Mnotfollicles = ones(T.Follicles(i).ImgSize, 'uint8');
                                for f = 1:numel(T.Follicles(i).Vox)
                                    Mnotfollicles(T.Follicles(i).Vox(f).Ind) = 0;
                                end
                                Mnotfollicles = imresize(Mnotfollicles, [S.Height S.Width]);

                                % Generate a blurred version of photo
                                Iblur = app.loadImage(T.Follicles(i).ImgFile);
                                Iblur = imgaussfilt(Iblur,40);
                                Iblur = imresize(Iblur, [S.Height S.Width]);

                                % Merge in-focus eyelid with blurred outside eyelid
                                Iblur = Iblur.*Mnotfollicles;
                                I = I.*Mfollicles;
                                I = I+Iblur;
                            end

                            imwrite(uint8(I), fullfile(dstFolder, [fName fExt]));

                            if dlg.CancelRequested
                                break % User wants to interrupt computation
                            end
                        end
                    end
                    close(dlg);

            end
        end

        % Button pushed function: btnExportTraining
        function btnExportTrainingButtonPushed(app, event)
            if isempty(app.RowSelected)
                return
            end
            
            % Choose a folder in which to write all binary masks
            dstFolder = uigetdir([],'Select Destination folder');
            app.fixFocus;
            if dstFolder == 0
                return
            elseif ~exist(dstFolder, 'dir')
                mkdir(dstFolder);
            end

            dlg = uiprogressdlg(app.UIFigure,'Title','Exporting records files...','Indeterminate', 'on');
            
            for i = 1:size(app.RowSelected,1)
                UID = app.tblTraining.Data{app.RowSelected(i,1),end}; % Retrieve UID
                Training = loadTraining(UID{:});
                
                FileName = [dstFolder filesep Training.UID '.mat'];
                save(FileName, '-struct', 'Training');
            end            
            close(dlg);
        end

        % Button pushed function: btnRelinkImages
        function btnRelinkImagesButtonPushed(app, event)
            % Ensure a training is selected from the table
            if isempty(app.RowSelected)
                return
            end
            
            % Choose a folder in which to write all binary masks
            dstFolder = uigetdir([],'Select images folder');
            app.fixFocus;
            
            if dstFolder == 0
                return
            elseif ~exist(dstFolder, 'dir')
                return
            end

            dlg = uiprogressdlg(app.UIFigure,'Title','Relinking image files...','Indeterminate', 'on');            
            for i = 1:size(app.RowSelected,1)
                
                UID = app.tblTraining.Data{app.RowSelected(i,1),end}; % Retrieve UID
                Training = loadTraining(UID{:});
                
                % Replace old image folder path with new dstFolder
                Training.ImagesFolder = dstFolder;
                for j = 1:numel(Training.ImagesList)
                    [~, fName, fExt] = fileparts(app.validatePath(Training.ImagesList{j}));
                    Training.ImagesList{j} = fullfile(dstFolder, [fName fExt]);
                end
                for j = 1:numel(Training.ImagesTODOEyelid)
                    [~, fName, fExt] = fileparts(app.validatePath(Training.ImagesTODOEyelid{j}));
                    Training.ImagesTODOEyelid{j} = fullfile(dstFolder, [fName fExt]);
                end
                for j = 1:numel(Training.ImagesTODOFollicles)
                    [~, fName, fExt] = fileparts(app.validatePath(Training.ImagesTODOFollicles{j}));
                    Training.ImagesTODOFollicles{j} = fullfile(dstFolder, [fName fExt]);
                end
                for j = 1:numel(Training.Follicles)
                    [~, fName, fExt] = fileparts(app.validatePath(Training.Follicles(j).ImgFile));
                    Training.Follicles(j).ImgFile = fullfile(dstFolder, [fName fExt]);
                end
                for j = 1:numel(Training.Eyelid)
                    [~, fName, fExt] = fileparts(app.validatePath(Training.Eyelid(j).ImgFile));
                    Training.Eyelid(j).ImgFile = fullfile(dstFolder, [fName fExt]);
                end
                
                saveTraining(Training);
            end
            app.RefreshTrainingTable;
            
            close(dlg);
        end

        % Button pushed function: btnNetExport
        function btnNetExportButtonPushed(app, event)
            if ~isempty(app.NetSelected)
                UID = app.tblNeuralNet.Data{app.NetSelected(:,1),end}; % Retrieve UID
                NN = loadNeuralNet(UID{:});
                
                % Choose a folder in which to write all binary masks
                dstFolder = uigetdir([],'Select Destination folder');
                app.fixFocus;                
                if dstFolder == 0
                    return
                elseif ~exist(dstFolder, 'dir')
                    mkdir(dstFolder);
                end
                
                dlg = uiprogressdlg(app.UIFigure,'Title','Exporting network to disk...','Indeterminate', 'on');
                FileName = [dstFolder filesep NN.UID '.mat'];
                save(FileName, '-struct', 'NN');
                close(dlg);
            end
        end

        % Button pushed function: btnImportTraining
        function btnImportTrainingButtonPushed(app, event)
            % Choose a folder in which to write all binary masks
            [srcFile, srcPath] = uigetfile('*.mat', 'Select file to import');
            app.fixFocus;
            if srcFile == 0
                return
            end
            
            % Copy the selected file into local Training repository
            dlg = uiprogressdlg(app.UIFigure,'Title','Importing record...','Indeterminate', 'on');                        
            dlg.Message = 'Setting up path';
            dstPath = [userpath filesep 'FollicleFinder' filesep 'Training'];
            dlg.Message = 'Copying record into local drive';
            copyfile(fullfile(srcPath, srcFile), fullfile(dstPath, srcFile));
            dlg.Message = 'Refreshing records table';
            app.RefreshTrainingTable;
            close(dlg);
                        
        end

        % Button pushed function: btnBatchSaveBboxes
        function btnBatchSaveBboxesButtonPushed(app, event)
            % Load selected training session
            if ~isempty(app.RowSelected)
                UID = app.tblTraining.Data{app.RowSelected(:,1),end}; % Retrieve UID
                Training = loadTraining(UID{:});
            end
            
            % Choose a folder in which to write all binary masks
            dstFolder = uigetdir([],'Select Destination folder');
            app.fixFocus;
            if dstFolder == 0
                return
            elseif ~exist(dstFolder, 'dir')
                mkdir(dstFolder);
            end
            
            % Extract each eyelid region as a binary image TIF
            dlg = uiprogressdlg(app.UIFigure,'Title','Saving bounding boxes...','Value',0, 'Cancelable', 'on');
            for i = 1:numel(Training.Follicles)
                [~, fName, ~] = fileparts(Training.Follicles(i).ImgFile);
                dlg.Title = ['Processing photo ' num2str(i) '/' num2str(numel(Training.Follicles)) ' : ' fName];
                dlg.Value = i/numel(Training.Follicles);
                
                tblF = [];
                for f = 1:numel(Training.Follicles(i).Vox)
                    % Retrieve current Follicle's pixel data
                    F = Training.Follicles(i).Vox(f);
                    
                    % Retrieve bbox boundaries (box must enclose object, hence it has to be 1 pixel wider in each direction)
                    yMin = max(1, min(F.Pos(:,1))-1);
                    yMax = max(F.Pos(:,1))+1;
                    xMin = max(1, min(F.Pos(:,2))-1);
                    xMax = max(F.Pos(:,2))+1;
                    
                    switch Training.Follicles(i).Filter(f)
                        case 1,  fType = 'True';
                        case -1, fType = 'False';
                        case 0,  fType = 'Maybe';
                    end
                    
                    if isempty(tblF)
                        tblF = table(f, {Training.Follicles(i).ImgFile}, xMin, yMin, xMax, yMax, {fType},...
                            'VariableNames', {'Follicle', 'Filename', 'xMin', 'yMin', 'xMax', 'yMax', 'Type'});
                    else
                        tblF = [tblF; table(f, {Training.Follicles(i).ImgFile}, xMin, yMin, xMax, yMax, {fType},...
                            'VariableNames', {'Follicle', 'Filename', 'xMin', 'yMin', 'xMax', 'yMax', 'Type'})]; %#ok
                    end
                end
                
                % Write data as an Excel table
                if ~isempty(tblF)
                    tblFileName = fullfile(dstFolder, [fName '.xls']);
                    writetable(tblF, tblFileName);
                end
                
                if dlg.CancelRequested
                    break % User wants to interrupt computation
                end
            end
            close(dlg);
        end

        % Button pushed function: btnCompareGraders
        function btnCompareGradersButtonPushed(app, event)
            if isempty(app.RowSelected) || size(app.RowSelected, 1) < 2
                return
            end
            
            % Accumulate training sessions to compare
            dlg = uiprogressdlg(app.UIFigure,'Title','Calculating agreement between graders','Value',0, 'Cancelable', 'on');
            for row = 1:numel(app.RowSelected(:,1))
                Grader = app.tblTraining.Data{app.RowSelected(row,1),1};
                dlg.Message = ['Loading data of grader ' num2str(row) '/' num2str(size(app.RowSelected,1)) ': ' Grader{:}];
                dlg.Value = row / numel(app.RowSelected(:,1));
                UID = app.tblTraining.Data{app.RowSelected(row,1),end}; % Retrieve UID
                T(row) = loadTraining(UID); %#ok
                
                if dlg.CancelRequested
                    close(dlg);
                    return % User wants to interrupt computation
                end
            end
            
            Images          = T(1).ImagesList;
            AreaEyelid      = zeros(numel(T), numel(Images));
            PerimEyelid     = zeros(numel(T), numel(Images));
            AreaFollicles   = zeros(numel(T), numel(Images));
            PerimFollicles  = zeros(numel(T), numel(Images));
            NumFollicles    = zeros(numel(T), numel(Images));
            for i = 1: numel(Images)
                [~, fName, fExt] = fileparts(Images{i});
                dlg.Message = ['Comparing photo ' num2str(i) '/' num2str(numel(Images)) ' : ' fName fExt];
                dlg.Value = i / numel(Images);
                %disp(['Image: ' Images{i}]);
                
                % Find the same image among all the Training (images are stored in shuffled order)
                for t = 1:numel(T)
                    for j = 1:numel(T(t).Eyelid)
                        if strcmp(Images{i}, T(t).Eyelid(j).ImgFile)
                            % Create the ground truth eyelid mask
                            Mask = zeros(T(t).Eyelid(j).ImgSize);
                            Mask(T(t).Eyelid(j).Vox.Ind) = 255;
                            
                            % Measure area and perimeter of all both eyes
                            R = regionprops(logical(Mask), 'Area', 'Perimeter');
                            AreaEyelid(t,i) = sum([R.Area]);
                            PerimEyelid(t,i) = sum([R.Perimeter]);
                        end
                    end
                    
                    for j = 1:numel(T(t).Follicles)
                        if strcmp(Images{i}, T(t).Follicles(j).ImgFile)
                            
                            % Create the ground truth eyelid mask
                            Mask = zeros(T(t).Follicles(j).ImgSize);
                            for r = 1:numel(T(t).Follicles(j).Vox)
                                idxs = T(t).Follicles(j).Vox(r).Ind;
                                Mask(idxs) = 255;
                            end
                            
                            % Measure area and perimeter of all both eyes
                            R = regionprops(logical(Mask), 'Area', 'Perimeter');
                            AreaFollicles(t,i) = sum([R.Area]);
                            PerimFollicles(t,i) = sum([R.Perimeter]);
                            NumFollicles(t,i) = numel(T(t).Follicles(j).Vox);
                        end
                    end
                end
                
                if dlg.CancelRequested
                    close(dlg);
                    break % User wants to interrupt computation
                end
            end
            close(dlg);
            uialert(app.UIFigure,{['Number of graders = ' num2str(size(AreaEyelid, 1))],...
                ['Eyelid area ICC = ' num2str(ICC(2,'k',AreaEyelid),3)], ...
                ['Eyelid perimeter ICC = ' num2str(ICC(2,'k',PerimEyelid),3)],...
                ['Follicles area ICC = ' num2str(ICC(2,'k',AreaFollicles),3)],...
                ['Follicles perimeter ICC = ' num2str(ICC(2,'k',PerimFollicles),3)],...
                ['Follicles number ICC = ' num2str(ICC(2,'k',NumFollicles),3)]},...
                'Intraclass correlation coefficient (ICC)', "Icon", "info", "Modal", true);
        end

        % Button pushed function: btnMergeRecords
        function btnMergeRecordsButtonPushed(app, event)
            if isempty(app.RowSelected) || size(app.RowSelected,1) < 2
                return
            end
            
            dlg = uiprogressdlg(app.UIFigure,'Title','Merging records...','Indeterminate', 'on');
            
            UID       = app.tblTraining.Data{app.RowSelected(1,1),end};
            dstT      = loadTraining(UID);
            dstT.UID  = generateUID;
            dstT.Date = datetime;
            
            for i = 2:size(app.RowSelected, 1)
                UID   = app.tblTraining.Data{app.RowSelected(i,1),end};
                srcT  = loadTraining(UID);
                
                dstT.ImagesList = [dstT.ImagesList, srcT.ImagesList];
                dstT.ImagesTODOFollicles = [dstT.ImagesTODOFollicles, srcT.ImagesTODOFollicles];
                dstT.ImagesTODOEyelid = [dstT.ImagesTODOEyelid, srcT.ImagesTODOEyelid];
                dstT.Follicles  = [dstT.Follicles,  srcT.Follicles];
                dstT.Eyelid     = [dstT.Eyelid,     srcT.Eyelid];
            end
            
            saveTraining(dstT);
            app.RefreshTrainingTable;
            close(dlg);
        end

        % Button pushed function: btnClearFollicles
        function btnClearFolliclesButtonPushed(app, event)
            if isempty(app.RowSelected)
                return
            end
            
            switch uiconfirm(app.UIFigure,'Do you really want to permanently clear follicles data?','Delete data','Options',{'No','Yes'},'DefaultOption',1,'CancelOption',1)
                case 'Yes'
                    dlg = uiprogressdlg(app.UIFigure,'Title','Clearing follicle data...','Indeterminate', 'on');
                    
                    for i = 1:size(app.RowSelected,1)
                        UID = app.tblTraining.Data{app.RowSelected(i,1),end}; % Retrieve UID
                        T = loadTraining(UID{:}, {'UID', 'ImagesList', 'ImagesTODOFollicles', 'Type'});
                        T.Follicles = [];
                        switch T.Type
                            case 'Manual', T.ImagesTODOFollicles = T.ImagesList(randperm(numel(T.ImagesList))); % randomize order of images
                            case 'Auto',   T.ImagesTODOFollicles = T.ImagesList;
                        end
                        saveTraining(T,'Follicles');
                        saveTraining(T,'ImagesTODOFollicles');
                    end
                    app.RefreshTrainingTable;                    
                    close(dlg);
            end
        end

        % Button pushed function: btnClearEyelid
        function btnClearEyelidButtonPushed(app, event)
            if isempty(app.RowSelected)
                return
            end
            
            switch uiconfirm(app.UIFigure,'Do you really want to permanently clear eyelid data?','Delete data','Options',{'No','Yes'},'DefaultOption',1,'CancelOption',1)
                case 'Yes'
                    dlg = uiprogressdlg(app.UIFigure,'Title','Clearing eyelid data...','Indeterminate', 'on');
                    
                    for i = 1:size(app.RowSelected,1)
                        UID = app.tblTraining.Data{app.RowSelected(i,1),end}; % Retrieve UID
                        T = loadTraining(UID{:}, {'UID', 'ImagesList', 'ImagesTODOEyelid', 'Type'});
                        T.Eyelid = [];
                        switch T.Type
                            case 'Manual', T.ImagesTODOEyelid = T.ImagesList(randperm(numel(T.ImagesList))); % randomize order of images
                            case 'Auto',   T.ImagesTODOEyelid = T.ImagesList;
                        end
                        
                        saveTraining(T,'Eyelid');
                        saveTraining(T,'ImagesTODOEyelid');
                    end
                    app.RefreshTrainingTable;                    
                    close(dlg);
            end
        end

        % Value changed function: cmbNetType
        function cmbNetTypeValueChanged(app, event)
            switch app.cmbNetType.Value
                case 'Pixel classifier'
                    app.cmbNetModel.Items = {'FCN Eyelid', 'U-Net', 'SegNet', 'DeepLab v3+'};
                case 'Object classifier'
                    app.cmbNetModel.Items = {'AlexNet', 'ResNet-50', 'ResNet-101', 'Inception-ResNet-v2', 'Inception-v3'};
                case 'Object detector'
                    app.cmbNetModel.Items = {'YOLOv2', 'Faster R-CNN', 'SSD'};
                case 'Image classifier'
                    app.cmbNetModel.Items = {'AlexNet', 'ResNet-50', 'ResNet-101', 'Inception-ResNet-v2', 'Inception-v3'};
            end
        end

        % Button pushed function: btnDuplicateTraining
        function btnDuplicateTrainingButtonPushed(app, event)
            if isempty(app.RowSelected)
                return
            end
            
            % Choose a folder in which to write all binary masks
            dlg = uiprogressdlg(app.UIFigure,'Title','Duplicating record...','Indeterminate', 'on');            
            for i = 1:size(app.RowSelected,1)
                UID = app.tblTraining.Data{app.RowSelected(i,1),end}; % Retrieve UID
                T   = loadTraining(UID{:});
                T.UID = generateUID;
                saveTraining(T);
            end            
            app.RefreshTrainingTable;
            close(dlg);
        end

        % Button pushed function: btnAnnotateAuto
        function btnAnnotateAutoButtonPushed(app, event)
            if isempty(app.NetSelected)
                L = listNeuralNet;
                if isempty(L)
                    uiconfirm(app.UIFigure,'No neural nets installed.','Error','Options',{'Ok'},'DefaultOption',1,'CancelOption',1)
                end
                idx = listdlg('ListString', L{:,1},...
                    'SelectionMode','single',...
                    'ListSize', [500 300],...
                    'Name', 'Select a network');
                if isempty(idx)
                    return
                end
                app.NetSelected = [idx, 1];
            end
            
            dlg = uiprogressdlg(app.UIFigure,'Title','Loading NeuralNet...','Indeterminate', 'on');
            UID = app.tblNeuralNet.Data{app.NetSelected(:,1),end}; % Retrieve UID
            NN = loadNeuralNet(UID);
            assignin('base','NN', NN);
            try
                inputSize = NN.Layers.Layers(1).InputSize;
            catch
                inputSize = NN.Layers(1).InputSize;
            end
            close(dlg);
            
            % Identify image files to score and store path to them in a list
            files = dir(app.ImagesFolder);  % List the content of ImageFolder
            files = files(~[files.isdir]);  % Keep only files (ignore subdirs)
            
            lstImages = {};
            for i = 1:numel(files)
                currFile = [files(i).folder filesep files(i).name];
                if isempty(lstImages)
                    lstImages = {currFile};
                else
                    lstImages{end+1} = currFile; %#ok
                end
            end
            
            % Create a new manual training session
            Training                = struct;
            Training.UID            = generateUID;
            Training.Type           = 'Auto';
            Training.Date           = datetime;
            Training.User           = 'Auto';
            Training.ImagesFolder   = app.ImagesFolder;
            Training.ImagesList     = lstImages;
            Training.ImagesTODOFollicles = lstImages; % do NOT randomize order of images
            Training.ImagesTODOEyelid    = lstImages; % do NOT randomize order of images
            Training.Follicles      = [];
            Training.Eyelid         = [];
            
            % If user selected a training, ask if to edit or start anew
            TODOmode = 'New';
            if ~isempty(app.RowSelected)
                switch uiconfirm(app.UIFigure,'Existing training selected: Complete unfinished, Redo analysed images or start anew?','Grading type','Options',{'Complete', 'Redo', 'New'},'DefaultOption',1)
                    case 'Complete'
                        % Load existing training session
                        UID = app.tblTraining.Data{app.RowSelected(:,1),end}; % Retrieve UID
                        Training = loadTraining(UID{:});
                        TODOmode = 'Complete';
                    case 'Redo'
                        % Load existing training session
                        UID = app.tblTraining.Data{app.RowSelected(:,1),end}; % Retrieve UID
                        Training = loadTraining(UID{:});
                        TODOmode = 'Redo';
                end
            end
            
            switch TODOmode
                case {'New', 'Complete'}
                    switch NN.Target
                        case 'Eyelid', TODOlist = Training.ImagesTODOEyelid;
                        case 'Follicles', TODOlist = Training.ImagesTODOFollicles;
                    end
                case 'Redo'
                   switch NN.Target
                       case 'Eyelid', TODOlist = setdiff(Training.ImagesList, Training.ImagesTODOEyelid);
                       case 'Follicles', TODOlist = setdiff(Training.ImagesList, Training.ImagesTODOFollicles);
                   end
            end
            
            dlg = uiprogressdlg(app.UIFigure,'Title','Prediction pixel identities...','Value',0, 'Cancelable', 'on');
            for i = 1:numel(TODOlist)
                ImgFileName = TODOlist{i};
                [~, fName, fExt] = fileparts(ImgFileName);
                dlg.Title = ['Processing photo ' num2str(i) '/' num2str(numel(TODOlist)) ' : ' fName fExt];
                dlg.Value = i/numel(TODOlist);
                
                % Load original image and predict pixel identity
                try
                    srcImage = app.loadImage(ImgFileName);
                catch
                    disp(['Cannot read this image file: ' ImgFileName]);
                    continue;
                end
                
                switch NN.Target
                    case 'Eyelid'
                        % Predict categories and convert into a binary mask
                        [Prediction, ~, AllScores] = semanticseg(imresize(srcImage, inputSize(1:2)), NN.Net);
                        
                        % Calculate a heatmap of segmentation scores
                        ScoresRF = AllScores(:,:,2)-AllScores(:,:,1);
                        ScoresRF(ScoresRF < 0) = 0;
                        MP = zeros(size(Prediction));
                        %MP(Prediction == NN.Target) = 1;
                        MP(ScoresRF > app.cmbNetConfidenceEyelid.Value) = 1;
                        
                        % Post-process result to close gaps
                        MP = medfilt2(MP);
                        MP = imclose(MP, strel('disk',10));
                        MP = imfill(MP);
                        MP = imresize(MP, [size(srcImage, 1) size(srcImage, 2)]);
                        
                        % Keep only largest connected object in mask (eyelid)
                        Labels = bwlabel(MP,8);
                        LargestLabel = mode(Labels(Labels ~= mode(Labels, 'all')), 'all');
                        MP(Labels ~= LargestLabel) = 0;
                        
%                         % Measure geometrical properties and calibrate image according to eyelid major axis size
%                         % Reference eyelid (ImgSizeX: 1000 px, eyelid length: 617 px = 185 mm; 0.3 mm/px)
%                         Eyelid = app.generateImgObject(ImgFileName, srcImage);
%                         Props  = regionprops(MP, 'MajorAxisLength', 'Perimeter', 'Circularity', 'Orientation');
%                         
%                         % Store eyelid region properties
%                         if ~isempty(Props)
%                             MMperPx = 617 * 0.3 / Props.MajorAxisLength;
%                             Eyelid.Shape.LenMaj = Props.MajorAxisLength;
%                             Eyelid.Shape.Perim  = Props.Perimeter;
%                             Eyelid.Shape.Round  = Props.Circularity;
%                             Eyelid.Shape.Orient = Props.Orientation;
%                         else
%                             MMperPx = 1;
%                         end
%                         Eyelid.ImgRes       = [MMperPx MMperPx];
                        Eyelid = app.generateImgObject(ImgFileName, srcImage);                        
                        Eyelid.ImgFile      = ImgFileName;
                        Eyelid.Vox.Ind      = find(MP);
%                         assignin('base', "srcImage", srcImage);
%                         assignin('base', "Eyelid", Eyelid);
                        [Eyelid.Vox.Pos(:,1), Eyelid.Vox.Pos(:,2)] = ind2sub(size(srcImage), Eyelid.Vox.Ind);
                        Eyelid.Filter       = 1;
                        Eyelid.Pos(1,:)     = [ceil(mean(Eyelid.Vox.Pos(:,1))),ceil(mean(Eyelid.Vox.Pos(:,2)))];
                        
                        if isempty(Training.Eyelid)
                            Training.Eyelid = Eyelid;
                        else
                            Training.Eyelid(end+1) = Eyelid;
                        end
                        Training.ImagesTODOEyelid = setdiff(Training.ImagesTODOEyelid, ImgFileName);
                        %saveTraining(Training);
                        
                    case 'Follicles'
                        % If the eyelid was segmented, mask image to search only within eyelid region
                        FoundEyelid = false;
                        for lid = 1:numel(Training.Eyelid)
                            if strcmp(ImgFileName, Training.Eyelid(lid).ImgFile)
                                disp(['Searching ' fName fExt ' within eyelid region']);
                                EyelidMask = zeros(Training.Eyelid(lid).ImgSize, "uint8");
                                EyelidMask(Training.Eyelid(lid).Vox.Ind) = 1;
                                Img = srcImage .* repmat(EyelidMask,[1,1,3]);
                                % imshow(Img); % Show masked image
                                % drawnow;
                                FoundEyelid = true;
                                break;
                            end
                        end
                        if ~FoundEyelid
                            disp(['Searching ' fName fExt]);
                        end
                        
                        dlg.Message = {'Step 2/3: Predicting pixel identities using NeuralNet'};
                        % Predict categories and convert into a binary mask
                        % AllScores(:,:,1) = Background, AllScores (:,:,2) = Follicles
                        [~, Scores, AllScores] = semanticseg(imresize(Img, inputSize(1:2)), NN.Net);
                        
                        % Improve segmentation contrast by subtracting probability of a given pixel to belong to background
                        ScoresRF = AllScores(:,:,2) - AllScores(:,:,1);
                        
                        % Improve segmentation contrast by removing edge regions and median filter for stray pixels
                        ScoresRF = medfilt2(ScoresRF - imcomplement(Scores));
                        
                        % Improve segmentation accuracy by ignoring everything with less than 25 percent chance of being a follicle
                        %ScoresRF(ScoresRF < 0.5) = 0;
                        ScoresRF(ScoresRF <= app.cmbNetConfidenceEyelid.Value) = 0;
                        
                        % Create an 8-bit image by stretching the score values [0:1] between [0:255]
                        ScoresImg = imresize(uint8(ceil(ScoresRF*255)), [size(Img, 1) size(Img, 2)]);
                        
                        dlg.Message = {'Step 3/3: Detecting individual follicle structures'};
                        F = findObjects2DNN(Img, ScoresImg, app.getPrefsAutoFolliclesNN);
                        
                        if ~isempty(F)
                            % Add missing fields to the results
                            F.ImgFile   = ImgFileName;
                            F.ImgSize   = [size(Img, 1), size(Img, 2)]; % Image size (pixels)
                            F.ImgRes    = [1 ,1]; % Resolution (mm/pixels)
                            F.Filter    = ones(1, numel(F.Vox));
                            F.Shape.Perim  = 0; % Shape properties - perimeter
                            F.Shape.LenMaj = 0; % Shape properties - length of majoraxis
                            F.Shape.Round  = 0; % Shape properties - roundness
                            F.Scores.F  = 0;
                            F.Scores.P  = 0;
                            F.Scores.C  = 0;
                            F.Scores.TE = 0;
                            F.Scores.CC = 0;
                            F.Diagnosis = 'TNormal';
                            
                            % Save the new manual training to disk
                            if isempty(Training.Follicles)
                                Training.Follicles = F;
                            else
                                Training.Follicles(end+1) = F;
                            end
                            Training.ImagesTODOFollicles = setdiff(Training.ImagesTODOFollicles, ImgFileName);
                            %saveTraining(Training);
                            disp(['Found ' num2str(numel(F.Filter)) ' follicles']);
                        else
                            disp(['Error processing ' ImgFileName ', continuing with the next image.']);
                        end
                        
                end
                
                if dlg.CancelRequested
                    break % User wants to interrupt computation
                end
            end
            saveTraining(Training);
            close(dlg);            
            app.RefreshTrainingTable;
        end

        % Button pushed function: btnDiagnoseTF
        function btnDiagnoseTFButtonPushed(app, event)
            if isempty(app.RowSelected) || isempty(app.NetSelected)
                return
            end
            
            % Ask user how to cutout follicles (1 = using follicle shape, 0= using bounding box square)
            R.FollicleShape = 1;
            R.ConfidenceLevel = 0.95;
            R = editStruct(R, 'Settings');
            if isempty(R)
                return
            end
            app.fixFocus;
            
            dlg = uiprogressdlg(app.UIFigure,'Title','Loading NeuralNet...','Indeterminate', 'on');
            UID = app.tblNeuralNet.Data{app.NetSelected(:,1),end}; % Retrieve UID
            NN = loadNeuralNet(UID);
            if ~strcmp(NN.Target, 'Follicles') || ~strcmp(NN.Type, 'Object classifier')
                uiconfirm(app.UIFigure,'Selected NeuralNet cannot validate follicles, please select an object classifier NeuralNet','Wrong NeuralNet','Options',{'OK'}, 'Icon','error');
                return
            end
            try
                inputSize = NN.Layers.Layers(1).InputSize;
            catch
                inputSize = NN.Layers(1).InputSize;
            end
            
            dlg.Title = 'Loading Follicle data...';
            UID = app.tblTraining.Data{app.RowSelected(:,1),end}; % Retrieve UID
            Training = loadTraining(UID);
            close(dlg);
            
            dlg = uiprogressdlg(app.UIFigure,'Title','Validating follicles...','Value',0, 'Cancelable', 'on');
            for i = 1:numel(Training.Follicles)
                
                [~, fName, fExt] = fileparts(Training.Follicles(i).ImgFile);
                dlg.Title = ['Processing photo ' num2str(i) '/' num2str(numel(Training.Follicles)) ' : ' fName fExt];
                dlg.Value = i/numel(Training.Follicles);
                Iphoto = app.loadImage(Training.Follicles(i).ImgFile);
                
                for f = 1:numel(Training.Follicles(i).Vox)
                    dlg.Message = {['Validating candidate follicle ' num2str(f) '/' num2str(numel(Training.Follicles(i).Vox))]};
                    
                    % Retrieve current Follicle's pixel data
                    F = Training.Follicles(i).Vox(f);
                    
                    % Retrieve the intensity values of current follicle
                    minPt = min(F.Pos(:,1:2));
                    maxPt = max(F.Pos(:,1:2));
                    if R.FollicleShape
                        I = zeros([maxPt-minPt+1 3], 'uint8');
                        for k = 1:size(F.Pos, 1)
                            pt = F.Pos(k,:) - minPt + 1;
                            I(pt(1), pt(2),:) = F.RawBright(k,:);
                        end
                    else
                        I = Iphoto(minPt(1):maxPt(1), minPt(2):maxPt(2), :);
                    end
                    I = imresize(I,inputSize(1:2));
                    % Store the follicles into 'True/False/Maybe' folder based upon their valudation status
                    
                    [result, score] = classify(NN.Net,I);
                    switch result
                        case 'Follicles'
                            if score(2) >R.ConfidenceLevel 
                                %disp(['yay score:' num2str(score(2))]);
                                Training.Follicles(i).Filter(f) = 1;
                            else
                                %disp(['boo low score: '  num2str(score(2))]);
                                Training.Follicles(i).Filter(f) = -1;
                            end
                        case 'Background'
                            %disp('boo');
                            Training.Follicles(i).Filter(f) = -1;
                    end
                end

                if sum(Training.Follicles(i).Filter==1)>=5
                    Training.Follicles(i).Diagnosis = 'TF';
                else
                    Training.Follicles(i).Diagnosis = 'TNormal';
                end
                disp([char(fName) char(fExt) ' | Candidates: ' num2str(numel(Training.Follicles(i).Filter)) ' Positives: ' num2str(sum(Training.Follicles(i).Filter==1)) ' Diagnosis: ' Training.Follicles(i).Diagnosis]);

                if dlg.CancelRequested
                    break % User wants to interrupt computation
                end
            end
            saveTraining(Training, 'Follicles');
            close(dlg);
        end

        % Button pushed function: btnChangeTrainingType
        function btnChangeTrainingTypeButtonPushed(app, event)
            if isempty(app.RowSelected)
                return
            end
            
            dlg = uiprogressdlg(app.UIFigure,'Title','Switching follicle training type...','Indeterminate', 'on');
            
            for i = 1:size(app.RowSelected,1)
                UID = app.tblTraining.Data{app.RowSelected(i,1),end}; % Retrieve UID
                T = loadTraining(UID{:}, {'UID', 'Type'});
                T.Follicles = [];
                switch T.Type
                    case 'Manual', T.Type = 'Auto';
                    case 'Auto',   T.Type = 'Manual';
                end
                saveTraining(T,'Type');
                
            end
            app.RefreshTrainingTable;
            close(dlg);
        end

        % Button pushed function: btnCompareNetworks
        function btnCompareNetworksButtonPushed(app, event)
            if isempty(app.NetSelected) || isempty(app.RowSelected) || size(app.NetSelected, 1) < 2
                return
            end
            
            dstFolder = uigetdir(pwd,"Select test results destination folder");
            app.fixFocus;
            if dstFolder == 0
                return
            elseif ~isfolder(dstFolder)
                return
            end
            
            dlg = uiprogressdlg(app.UIFigure,'Title','Loading test data...','Indeterminate', 'on');
            UID = app.tblTraining.Data{app.RowSelected(:,1),end}; % Retrieve UID
            Training = loadTraining(UID);
            if isempty(Training)
                return
            end
            close(dlg);
            
            switch app.cmbNetType.Value
                case 'Pixel classifier'
                    NetNames = app.tblNeuralNet.Data{app.NetSelected(:,1),1};
                    NetUIDs  = app.tblNeuralNet.Data{app.NetSelected(:,1),end};
                    Target = app.cmbNetTarget.Value; % Eyelid / Follicles
                    
                    % Initialize counter matrices
                    ImagesNum   = numel(Training.(Target));
                    Accuracy    = zeros(ImagesNum, numel(NetNames));
                    Precision   = zeros(ImagesNum, numel(NetNames));
                    Sensitivity = zeros(ImagesNum, numel(NetNames));
                    Specificity = zeros(ImagesNum, numel(NetNames));
                    F1score     = zeros(ImagesNum, numel(NetNames));
                    DICEcoef    = zeros(ImagesNum, numel(NetNames));
                    IoUcoef     = zeros(ImagesNum, numel(NetNames));                    
                    %FileNames   = {Training.(Target).ImgFile}';
                    
                    % Test accuracy of each selected network
                    for netNum = 1:numel(NetNames)
                        dlg = uiprogressdlg(app.UIFigure,'Title','Loading network...','Indeterminate', 'on');
                        NN  = loadNeuralNet(NetUIDs{netNum});
                        try
                            inputSize = NN.Layers.Layers(1).InputSize;
                        catch
                            inputSize = NN.Layers(1).InputSize;
                        end                        
                        close(dlg);
                        
                        dlg = uiprogressdlg(app.UIFigure,'Title','Prediction pixel identities...', 'Value', 0, 'Cancelable', 'on');
                        dlg.Message = {['Neural Network ' num2str(netNum) '/' num2str(numel(NetNames)) ': '], NetNames{netNum} };
                        disp(['Neural Network ' num2str(netNum) '/' num2str(numel(NetNames)) ': ' NetNames{netNum}]);
                        
                        for i = 1: ImagesNum
                            [~, fName, fExt] = fileparts(Training.(Target)(i).ImgFile);
                            dlg.Title = ['Processing photo ' num2str(i) '/' num2str(ImagesNum) ' : ' fName fExt];
                            dlg.Value = i/ImagesNum;
                            
                            % Load original image and predict pixel identity
                            srcImage = app.loadImage(Training.(Target)(i).ImgFile);
                            
                            % Predict categories and convert into a binary mask
                            [Prediction, ~, AllScores] = semanticseg(imresize(srcImage, inputSize(1:2)), NN.Net);
                            
                            % Calculate a heatmap of segmentation scores
                            ScoresRF = AllScores(:,:,2)-AllScores(:,:,1);
                            ScoresRF(ScoresRF < 0) = 0;

                            
                            switch Target
                                case 'Eyelid'
                                    MP = zeros(size(Prediction));
                                    %MP(Prediction == NN.Target) = 1;
                                    MP(ScoresRF > app.cmbNetConfidenceEyelid.Value) = 1;
                                    
                                    MP = medfilt2(MP);
                                    %MP = imclose(MP, strel('disk',10));
                                    MP = imfill(MP);
                                    MP = imresize(MP, [size(srcImage, 1) size(srcImage, 2)]);
                                    
                                    % Keep only largest connected object in mask (eyelid)
                                    Labels = bwlabel(MP,8);
                                    LargestLabel = mode(Labels(Labels ~= mode(Labels, 'all')), 'all');
                                    MP(Labels ~= LargestLabel) = 0;
                                    
                                    % Create ground truth eyelid mask
                                    M = zeros(Training.(Target)(i).ImgSize);
                                    M(Training.(Target)(i).Vox.Ind) = 255;
                                    
                                case 'Follicles'
                                    MP = zeros(size(Prediction));
                                    %MP(Prediction == NN.Target) = 1;
                                    MP(ScoresRF > app.cmbNetConfidenceFollicles.Value) = 1;
                                    
                                    MP = imresize(MP, [size(srcImage, 1) size(srcImage, 2)]);
                                    
                                    % Create ground truth follicles mask
                                    M = zeros(Training.(Target)(i).ImgSize);
                                    for f = 1:numel(Training.(Target)(i).Vox)
                                        M(Training.(Target)(i).Vox(f).Ind) = 255;
                                    end
                            end                            
                            
                            % Calculate prediction accuracy, precision, sensitivity, specificity, F1 score
                            CP = find(M);  % Condition Positives = Real positive values from ground truth
                            CN = find(~M); % Condition Negatives = Real negative values from ground truth                            
                            PP = find(MP); % Predicted Positive
                            PN = find(~MP); % Predicted Negative
                            
                            P = numel(CP); % Real Positive values
                            N = numel(CN); % Real Negative values
                            TP = numel(intersect(PP,CP)); % True Positive predictions
                            TN = numel(intersect(PN,CN)); % True Negative predictions
                            FP = numel(intersect(PP,CN)); % False Positive predictions
                            FN = numel(intersect(PN,CP)); %#ok False Negative predictions
                            
                            if P > 0
                                Sensitivity(i,netNum)= TP / P;
                            end
                            if (P+N) > 0
                                Accuracy(i,netNum)   = (TP+TN) / (P+N);
                            end
                            if (TP+FP) > 0
                                Precision(i,netNum)  = TP / (TP+FP);
                            end
                            if N > 0
                                Specificity(i,netNum)= TN / N;
                            end
                            if (Precision(i,netNum) + Sensitivity(i,netNum)) > 0
                                F1score(i,netNum) = 2*Precision(i,netNum)*Sensitivity(i,netNum) / (Precision(i,netNum) + Sensitivity(i,netNum));
                            end
                            
                            % Jaccart coefficient (IoU) = (a intesect b)/(a union b)
                            % Sorenson-Dice coefficient = 2*(a intesect b)/(|a| + |b|)
                            Mbool = logical(M);
                            MPbool = logical(MP);
                            MPandM = MPbool & Mbool;
                            MPorM = MPbool | Mbool;
                            IoUcoef(i,netNum) = sum(MPandM(:)) / sum(MPorM(:)); 
                            DICEcoef(i,netNum) = 2 * sum(MPandM(:)) / ( sum(MPbool(:)) + sum(Mbool(:)) ); 

                            % If there are no follicles in the image DICE
                            % and IoU are exactly 0 while they should be 1
                            if DICEcoef(i,netNum) == 0 || IoUcoef(i,netNum) == 0
                                DICEcoef(i,netNum) = 1;                            
                                IoUcoef(i,netNum) = 1;
                            end
                            
                            % Save predicted and ground truth eyelid masks as images in dstFolder
                            if netNum == 1
                                imwrite(M, fullfile(dstFolder, [fName '_GroundTruth' fExt]));
                                imwrite(srcImage, fullfile(dstFolder, [fName '_RawImage' fExt]));
                            end
                            imwrite(MP, fullfile(dstFolder, [fName '_Predicted_Net' num2str(netNum) fExt]));
                            disp(['Filename: ' fName ', F1 score:' num2str(F1score(i, netNum)) ', Accuracy:' num2str(Accuracy(i, netNum)) ', Precision:' num2str(Precision(i, netNum)) ', Sensitivity:' num2str(Sensitivity(i, netNum)) ', Specificity:' num2str(Specificity(i, netNum)) ', IoU coef:' num2str(IoUcoef(i, netNum)) ', DICE coef:' num2str(DICEcoef(i, netNum))]);
                            drawnow;
                            
                            if dlg.CancelRequested
                                close(dlg);
                                return % User wants to interrupt computation
                            end
                        end
                        close(dlg);
                    end
            end
            
            % Plot comparison of tested networks
            figure('NumberTitle', 'off', 'ToolBar', 'none', "Name", 'Neural Networks performance', 'Units', 'normalized', 'Position', [0.1 0.1 0.8 0.9]);
            tiledlayout(6,1);
            
            % Network DICE coefficient
            nexttile;
            hold on;
            barh(1:numel(NetNames), mean(DICEcoef,1, 'omitnan'), 'FaceColor', [0.7 0.7 0.7], 'LineWidth', 1.5);
            box off;
            errorbar(mean(DICEcoef,1,'omitnan'), 1:numel(NetNames), std(DICEcoef,1,'omitnan')/sqrt(size(F1score,1)),'.k','horizontal', 'LineWidth', 1.5);
            title('F1/DICE (Sørensen–Dice) coefficient');
            xlim([0 1]);
            ax = gca;
            ax.YTick = 1:numel(NetNames);
            ax.YTickLabel = NetNames;
            
            % Network IoU coefficient
            nexttile;
            hold on;

            barh(1:numel(NetNames), mean(IoUcoef,1, 'omitnan'), 'FaceColor', [0.7 0.7 0.7], 'LineWidth', 1.5);
            box off;
            errorbar(mean(IoUcoef,1,'omitnan'), 1:numel(NetNames), std(IoUcoef,1,'omitnan')/sqrt(size(F1score,1)),'.k','horizontal', 'LineWidth', 1.5);
            title('IoU (Jaccard) coefficient');
            xlim([0 1]);
            ax = gca;
            ax.YTick = 1:numel(NetNames);
            ax.YTickLabel = NetNames;

            % Network Accuracy
            nexttile;
            hold on;
            barh(1:numel(NetNames), mean(Accuracy,1, 'omitnan'), 'FaceColor', [0.7 0.7 0.7], 'LineWidth', 1.5);
            box off;
            errorbar(mean(Accuracy,1,'omitnan'), 1:numel(NetNames), std(Accuracy,1,'omitnan')/sqrt(size(Accuracy,1)),'.k','horizontal', 'LineWidth', 1.5);
            title('Accuracy');
            xlim([0 1]);
            ax = gca;
            ax.YTick = 1:numel(NetNames);
            ax.YTickLabel = NetNames;

            % Network Precision
            nexttile;
            hold on;
            barh(1:numel(NetNames), mean(Precision,1, 'omitnan'), 'FaceColor', [0.7 0.7 0.7], 'LineWidth', 1.5);
            box off;
            errorbar(mean(Precision,1,'omitnan'), 1:numel(NetNames), std(Precision,1,'omitnan')/sqrt(size(Precision,1)),'.k','horizontal', 'LineWidth', 1.5);
            title('Precision (Positive Predicted Values)');
            xlim([0 1]);
            ax = gca;
            ax.YTick = 1:numel(NetNames);
            ax.YTickLabel = NetNames;
            
            % Network Sensitivity
            nexttile;
            hold on;
            barh(1:numel(NetNames), mean(Sensitivity,1, 'omitnan'), 'FaceColor', [0.7 0.7 0.7], 'LineWidth', 1.5);
            box off;
            errorbar(mean(Sensitivity,1,'omitnan'), 1:numel(NetNames), std(Sensitivity,1,'omitnan')/sqrt(size(Sensitivity,1)),'.k','horizontal', 'LineWidth', 1.5);
            title('Sensitivity (Recall, True Positive Rate)');
            xlim([0 1]);
            ax = gca;
            ax.YTick = 1:numel(NetNames);
            ax.YTickLabel = NetNames;

            % Network Specificity
            nexttile;
            hold on;
            barh(1:numel(NetNames), mean(Specificity,1, 'omitnan'), 'FaceColor', [0.7 0.7 0.7], 'LineWidth', 1.5);
            box off;
            errorbar(mean(Specificity,1,'omitnan'), 1:numel(NetNames), std(Specificity,1,'omitnan')/sqrt(size(Specificity,1)),'.k','horizontal', 'LineWidth', 1.5);
            title('Specificity (Selectivity, True Negative Rate)');
            xlim([0 1]);
            ax = gca;
            ax.YTick = 1:numel(NetNames);
            ax.YTickLabel = NetNames;                        
        end

        % Button pushed function: btnResetGPU
        function btnResetGPUButtonPushed(app, event)
            switch uiconfirm(app.UIFigure,'This will reset your GPUs and free up their memory, proceed?','Reset GPUs','Options',{'No','Yes'},'DefaultOption',1,'CancelOption',1)
                case 'Yes'
                    dlg = uiprogressdlg(app.UIFigure,'Title','Resetting all GPU devices...','Indeterminate', 'on');
                    for i = 1:gpuDeviceCount
                        gpuDevice(i);
                        reset(gpuDevice(i));
                        wait(gpuDevice(i));
                    end
                    close(dlg);
            end
        end

        % Button pushed function: btnBatchColoredFollicles
        function btnBatchColoredFolliclesButtonPushed(app, event)
            % Load selected training session
            if isempty(app.RowSelected)
                return
            end
            
            OnlyValid = false;
            switch uiconfirm(app.UIFigure,'Extract only valid follicles?','Settings','Options',{'No','Yes'},'DefaultOption',1,'CancelOption',1)
                case 'Yes'
                    OnlyValid = true;
            end
            % Choose a folder in which to write all binary masks
            dstFolder = uigetdir([],'Select Destination folder');
            app.fixFocus;
            if dstFolder == 0
                return
            elseif ~exist(dstFolder, 'dir')
                mkdir(dstFolder);
            end
                                    
            dlg = uiprogressdlg(app.UIFigure,'Title','Loading follicles...','Value',0, 'Cancelable', 'on');
            UID = app.tblTraining.Data{app.RowSelected(:,1),end}; % Retrieve UID
            Training = loadTraining(UID{:});
            % Extract each eyelid region as a binary image TIF
            for i = 1:numel(Training.Follicles)                
                [~, fName, ~] = fileparts(Training.Follicles(i).ImgFile);
                dlg.Title = ['Processing photo ' num2str(i) '/' num2str(numel(Training.Follicles)) ' : ' fName];
                dlg.Value = i/numel(Training.Follicles);
                
                Ir = zeros(Training.Follicles(i).ImgSize, 'uint8');
                Ig = zeros(Training.Follicles(i).ImgSize, 'uint8');
                Ib = zeros(Training.Follicles(i).ImgSize, 'uint8');
                for f = 1:numel(Training.Follicles(i).Vox)
                    if OnlyValid && Training.Follicles(i).Filter(f)<0
                        continue
                    end
                    
                    % Paint each follicle with a random color
                    Ir(Training.Follicles(i).Vox(f).Ind) = randi([0 255]);
                    Ig(Training.Follicles(i).Vox(f).Ind) = randi([0 255]);
                    Ib(Training.Follicles(i).Vox(f).Ind) = randi([0 255]);
                end
                imwrite(cat(3, Ir, Ig, Ib), fullfile(dstFolder, [fName '.tif']));
                
                if dlg.CancelRequested
                    break % User wants to interrupt computation
                end
            end
            close(dlg);
        end

        % Button pushed function: btnClearFiles
        function btnClearFilesButtonPushed(app, event)
            % Identify image files to score and store path to them in a list
            srcFolder = uigetdir([], 'Source folder');
            files = dir(srcFolder);  % List the content of ImageFolder
            files = files(~[files.isdir]);  % Keep only files (ignore subdirs)
            
            dstFolder = uigetdir([], 'Destination folder');
            if ~exist(dstFolder, 'dir')
                mkdir(dstFolder);
            end
            
            S.CopyHowMany = 0;
            S.Percent = 0;
            S = editStruct(S, 'Action');
            app.fixFocus;
            % If files to copy are in percent, convert to absolute number
            if S.Percent == 1
                S.CopyHowMany = floor(S.CopyHowMany * numel(files) / 100);
            end
            
            % Ensure we are not trying to copy more files than available
            if S.CopyHowMany > numel(files)
                S.CopyHowMany = numel(files);
            end
            
            FilesToCopy = randperm(numel(files), S.CopyHowMany);
            dlg = uiprogressdlg(app.UIFigure,'Title','Copying random files...','Value', 0, 'Cancelable', 'on');
            for i = 1: S.CopyHowMany
                dlg.Title = ['Copying file ' num2str(i) '/' num2str(S.CopyHowMany) ' : ' files(FilesToCopy(i)).name];
                dlg.Value = i/S.CopyHowMany;
                
                if ~copyfile([srcFolder filesep files(FilesToCopy(i)).name], [dstFolder filesep files(FilesToCopy(i)).name])
                    disp(['Error copying file' num2str(i) '/' num2str(S.CopyHowMany) ' : ' files(FilesToCopy(i)).name]);
                end
                
                if dlg.CancelRequested
                    break % User wants to interrupt computation
                end
            end
            close(dlg);
        end

        % Value changed function: cmbNetConfidenceFollicles
        function cmbNetConfidenceFolliclesValueChanged(app, event)
            app.savePreferences;
        end

        % Value changed function: cmbNetConfidenceEyelid
        function cmbNetConfidenceEyelidValueChanged(app, event)
            app.savePreferences;
        end

        % Button pushed function: btnROC
        function btnROCButtonPushed(app, event)
            if isempty(app.NetSelected) || isempty(app.RowSelected) || size(app.NetSelected, 1) ~= 1
                return
            end
            
            S.Min = 0;
            S.Max = 1;
            S.Step = 0.01;
            S = editStruct(S, 'Options');
            
            dlg = uiprogressdlg(app.UIFigure,'Title','Receiver Operating Characteristic','Indeterminate', 'on');
            dlg.Message = 'Loading test data...';
            UID = app.tblTraining.Data{app.RowSelected(:,1),end}; % Retrieve UID
            Training = loadTraining(UID);
            if isempty(Training)
                return
            end

            dlg.Message = 'Loading network...';
            NetName = app.tblNeuralNet.Data{app.NetSelected(1,1),1};
            NetUID = app.tblNeuralNet.Data{app.NetSelected(1,1),end};
            NN  = loadNeuralNet(NetUID);
            try
                inputSize = NN.Layers.Layers(1).InputSize;
            catch
                inputSize = NN.Layers(1).InputSize;
            end
            
            switch NN.Type
                case 'Pixel classifier'
                    Target = NN.Target; % Eyelid / Follicles
                    dlg.Cancelable    = 'on';
                    dlg.Indeterminate = 'off';
                    dlg.Value = 0;
                    
                    % Initialize counter matrices
                    ConfNum     = ceil((S.Max-S.Min)/S.Step)+1;
                    ImagesNum   = numel(Training.(Target));
                    Accuracy    = zeros(ImagesNum, ConfNum);
                    Precision   = zeros(ImagesNum, ConfNum);
                    Sensitivity = zeros(ImagesNum, ConfNum);
                    Specificity = zeros(ImagesNum, ConfNum);
                    F1score     = zeros(ImagesNum, ConfNum);
                    %FileNames   = {Training.(Target).ImgFile}';
                    
                    % Test accuracy of each selected network
                    
                    for i = 1: ImagesNum
                        [~, fName, fExt] = fileparts(Training.(Target)(i).ImgFile);
                        dlg.Value = i/ImagesNum;
                        dlg.Message = {['Photo ' num2str(i) '/' num2str(ImagesNum) ' : ' fName fExt],...
                            'Predicting pixel identity'};
                        
                        % Load Raw Image and create ground truth mask
                        I = app.loadImage(Training.(Target)(i).ImgFile);
                        M = zeros(Training.(Target)(i).ImgSize);
                        switch Target
                            case 'Eyelid'
                                % Create ground truth eyelid mask
                                M(Training.(Target)(i).Vox.Ind) = 255;
                                
                            case 'Follicles'
                                % Create ground truth follicles mask
                                for f = 1:numel(Training.(Target)(i).Vox)
                                    M(Training.(Target)(i).Vox(f).Ind) = 255;
                                end
                        end
                        
                        % Resize Raw Image and Mask to NN size
                        I = imresize(I, inputSize(1:2));
                        M = imresize(M, inputSize(1:2));
                                                
                        % Predict categories and convert into a binary mask
                        [Prediction, ~, AllScores] = semanticseg(I, NN.Net);
                        
                        % Calculate a heatmap of segmentation scores
                        ScoresRF = AllScores(:,:,2);
                        %ScoresRF = AllScores(:,:,2)-AllScores(:,:,1);
                        %ScoresRF(ScoresRF < 0) = 0;

                        c = 0;
                        for conf = S.Max:-S.Step:S.Min
                            dlg.Message = {['Photo ' num2str(i) '/' num2str(ImagesNum) ' : ' fName fExt],...
                                ['Predicting pixel identity at threshold: ' num2str(conf)]};
                            
                            MP = zeros(size(Prediction));
                            MP(ScoresRF >= conf) = 1;                            
                            
                            % Calculate prediction accuracy, precision, sensitivity, specificity, F1 score
                            CP = find(M);  % Condition Positives = Real positive values from ground truth
                            CN = find(~M); % Condition Negatives = Real negative values from ground truth                            
                            PP = find(MP); % Predicted Positive
                            PN = find(~MP); % Predicted Negative
                            
                            P = numel(CP); % Real Positive values
                            N = numel(CN); % Real Negative values
                            TP = numel(intersect(PP,CP)); % True Positive predictions
                            TN = numel(intersect(PN,CN)); % True Negative predictions
                            FP = numel(intersect(PP,CN)); % False Positive predictions
                            FN = numel(intersect(PN,CP)); %#ok False Negative predictions
                            
                            c = c+1;
                            if P > 0
                                Sensitivity(i,c)= TP / P;
                            end
                            if (P+N) > 0
                                Accuracy(i,c)   = (TP+TN) / (P+N);
                            end
                            if (TP+FP) > 0
                                Precision(i,c)  = TP / (TP+FP);
                            end
                            if N > 0
                                Specificity(i,c)= TN / N;
                            end
                            if (Precision(i,c) + Sensitivity(i,c)) > 0
                                F1score(i,c) = 2*Precision(i,c)*Sensitivity(i,c) / (Precision(i,c) + Sensitivity(i,c));
                            end
                            
                        end
                        
                        if dlg.CancelRequested
                            close(dlg);
                            return % User wants to interrupt computation
                        end                        
                    end
                close(dlg);
            end
            
%             % Plot comparison of tested networks
%             assignin('base', 'Accuracy',    Accuracy);
%             assignin('base', 'Precision',   Precision);
%             assignin('base', 'Sensitivity', Sensitivity);
%             assignin('base', 'Specificity', Specificity);
%             assignin('base', 'F1score',     F1score);
%             assignin('base', 'S',           S);
            
            figure('NumberTitle', 'off', 'Name', 'Receiver Operating Characteristic', 'Units', 'normalized', 'Position', [0.1 0.2 0.8 0.6]);
            t = tiledlayout(1,2);
            title(t, NetName);
                        
            % ROC curve
            nexttile;
            hold on;
            TPR = mean(Sensitivity,1);
            TPR_SEM = std(Sensitivity,1)/sqrt(size(Sensitivity,1));
            FPR = mean(1-Specificity,1);
            FPR_SEM = std(1-Specificity,1)/sqrt(size(Specificity,1));
            errorbar(FPR, TPR, TPR_SEM, TPR_SEM, FPR_SEM, FPR_SEM,'-o', 'LineWidth', 1.5, 'MarkerSize', 5);
            plot(S.Min:S.Step:S.Max, S.Min:S.Step:S.Max, '--k');
            title(['Receiver Operating Characteristic (AUC ' num2str(trapz(FPR, TPR),3) ')']);
            xlabel('False Positive Rate (1-Specificity)');
            ylabel('True Positive Rate (Sensitivity)');
            xlim([0 1]);
            ylim([0 1]);
            
            % Performance indeces
            nexttile;
            hold on;
            errorbar(S.Max:-S.Step:S.Min, mean(F1score,1), std(F1score,1)/sqrt(size(F1score,1)),'-v', 'LineWidth', 1.5, 'MarkerSize', 10);
            errorbar(S.Max:-S.Step:S.Min, mean(Accuracy,1), std(Accuracy,1)/sqrt(size(Accuracy,1)),'-o', 'LineWidth', 1.5, 'MarkerSize', 10);
            errorbar(S.Max:-S.Step:S.Min, mean(Precision,1), std(Precision,1)/sqrt(size(Precision,1)),'-s', 'LineWidth', 1.5, 'MarkerSize', 10);
            errorbar(S.Max:-S.Step:S.Min, mean(Sensitivity,1), std(Sensitivity,1)/sqrt(size(Sensitivity,1)),'-s', 'LineWidth', 1.5, 'MarkerSize', 10);
            errorbar(S.Max:-S.Step:S.Min, mean(Specificity,1), std(Specificity,1)/sqrt(size(Specificity,1)),'-s', 'LineWidth', 1.5, 'MarkerSize', 10);
            title('Network Performance');
            xlabel('Network threshold');
            legend({'F1 score', 'Accuracy','Precision (PPV)','Sensitivity (TPR, Recall)','Specificity (TNR)'}, 'location', 'south');
            xlim([S.Min S.Max]);           
        end

        % Button pushed function: btnDiagnoseTI
        function btnDiagnoseTIButtonPushed(app, event)
            if isempty(app.RowSelected)
                return
            end
            
            dlg = uiprogressdlg(app.UIFigure,'Title','Loading data...','Indeterminate', 'on');
            UID = app.tblTraining.Data{app.RowSelected(:,1),end}; % Retrieve UID
            T = loadTraining(UID);
            close(dlg);
            
            dlg = uiprogressdlg(app.UIFigure,'Title','Scoring images...','Value',0, 'Cancelable', 'on');
            Target = 'Eyelid';
            assignin('base','T',T);
            for i = 1:numel(T.(Target))
                
                [~, fName, fExt] = fileparts(T.(Target)(i).ImgFile);
                dlg.Title = ['Processing photo ' num2str(i) '/' num2str(numel(T.(Target))) ' : ' fName fExt];
                dlg.Value = i/numel(T.(Target));

                % Load photo
                try
                    I = app.loadImage(T.(Target)(i).ImgFile);
                catch
                    disp(['Cannot read this image file: ' T.(Target)(i).ImgFile]);
                    continue;
                end

                % Mask eyelid region
                Meyelid = zeros(T.(Target)(i).ImgSize, "uint8");
                Meyelid(T.(Target)(i).Vox.Ind) = 1;

                % Calculate eyelid redness as variation of red channel over average (G+B)
                % zero if R = G = B, one if R=255, G=0 B=0, minus 1 if R=0 G=255 B=255
                Ir = I(:,:,1);
                IeyelidMeanR = mean(Ir(Meyelid == 1)) / 255;
                IeyelidStdR  = std(double(Ir(Meyelid == 1))) / 255;
                %Ig = I(:,:,2);
                %IeyelidMeanG = mean(Ig(Meyelid == 1)) / 255;
                %Ib = I(:,:,3);
                %IeyelidMeanB = mean(Ib(Meyelid == 1)) / 255;
                %Redness = IeyelidMeanR - (IeyelidMeanG + IeyelidMeanB)/2;
                Redness = IeyelidMeanR;
                RednessSD = IeyelidStdR;
                
                disp(['Image: ' fName]);
                disp(['Redness int: ' num2str(Redness)]);
                disp(['Redness std: ' num2str(RednessSD)]);
                disp(' ');
                % Trace contour of blood vessels
                %Igb = uint8(mean(I(:,:,2:3), 3));
                %I = I .* repmat(Meyelid,[1,1,3]);
                
                resp = BloodVesselSegmentation(I.*Meyelid);
                Mvessels = resp.respimage > 75;
                imshow(imoverlay(I.*Meyelid, Mvessels));                
                title(['Redness:' num2str(Redness) ' RedSD:' num2str(RednessSD)]);
                drawnow;
                
                assignin("base",['I' num2str(i)], I);
                assignin("base",['resp' num2str(i)], resp);
                assignin("base",['Meyelid' num2str(i)], Meyelid);
                % imshow(Img); % Show masked image
                % drawnow;
                
                % Evaluate eyelid redness
                %saveTraining(T);
                
                if dlg.CancelRequested
                    break % User wants to interrupt computation
                end
            end
            close(dlg);            
        end

        % Button pushed function: btnBackupAnnotations
        function btnBackupAnnotationsButtonPushed(app, event)
            % Choose a folder in which to write all binary masks
            srcFolder = [userpath filesep 'FollicleFinder' filesep 'Training'];
            dstFolder = uigetdir([],'Select Destination folder');
            app.fixFocus;
            if dstFolder == 0
                return
            elseif ~exist(dstFolder, 'dir')
                mkdir(dstFolder);
            end

            if ~isfolder(srcFolder)                
                return
            end
            
            dlg = uiprogressdlg(app.UIFigure,'Title','Backup','Message','Backing up annotations...', 'Indeterminate', 'on');            
            zip([dstFolder filesep 'Backup_Annotations_' datestr(now, 'yyyy-mm-dd_HH-MM_AM') '.zip'], srcFolder);
            close(dlg);            
        end

        % Button pushed function: btnGradCam
        function btnGradCamButtonPushed(app, event)
            R.GradCAM = 1;
            R.imageLIME = 0;
            R.occlusionSensitivity = 0;
            R = editStruct(R, 'Algorithm');
            if isempty(R)
                return
            end

            srcFolder = uigetdir(pwd,"Select photos folder");
            if srcFolder == 0
                return
            elseif ~isfolder(srcFolder)
                return
            end

            dstFolder = uigetdir(pwd,"Select Grad-Cam results folder");
            app.fixFocus;
            if dstFolder == 0
                return
            elseif ~isfolder(dstFolder)
                return
            end
            
            files = dir(srcFolder);  % List the content of ImageFolder
            files = files(~[files.isdir]);  % Keep only files (ignore subdirs)
            lstImages = {};
            for i = 1:numel(files)
                currFile = [files(i).folder filesep files(i).name];
                if isempty(lstImages)
                    lstImages = {currFile};
                else
                    lstImages{end+1} = currFile; %#ok
                end
            end
            
            % Load selected neural network
            UID = app.tblNeuralNet.Data{app.NetSelected(:,1),end};
            NN = loadNeuralNet(UID);
            try
                inputSize = NN.Layers.Layers(1).InputSize;
            catch
                inputSize = NN.Layers(1).InputSize;
            end

            dlg = uiprogressdlg(app.UIFigure,'Title','Neuralnet attention','Value',0, 'Cancelable', 'on');
            for i = 1:numel(lstImages)                
                [~, fName, fExt] = fileparts(lstImages{i});
                dlg.Message = ['Processing photo ' num2str(i) '/' num2str(numel(lstImages)) ' : ' fName];
                dlg.Value = i/numel(lstImages);                
                
                I = imread(lstImages{i});
                Ismall = imresize(I, inputSize(1:2));
                Label = classify(NN.Net, Ismall);

                disp([char(fName) char(fExt) ' : ' char(Label)]);
                if R.GradCAM
                    scoreMapSmall = gradCAM(NN.Net, Ismall, Label);
                    clf;
                    imshow(I);
                    hold on
                    imagesc(imresize(scoreMapSmall, size(I,1:2)), 'AlphaData', 0.3);
                    colormap jet
                    exportgraphics(gcf,[dstFolder filesep fName '_GradCAM' fExt]);  
                end
                                
                if R.imageLIME
                    scoreMapSmall = imageLIME(NN.Net, Ismall, Label);
                    clf;
                    imshow(I);
                    hold on
                    imagesc(imresize(scoreMapSmall, size(I,1:2)), 'AlphaData', 0.3);
                    colormap jet
                    exportgraphics(gcf,[dstFolder filesep fName '_ImageLIME' fExt]);  
                end

                if R.occlusionSensitivity
                    scoreMapSmall = occlusionSensitivity(NN.Net, Ismall, Label);                    
                    clf;
                    imshow(I)
                    hold on
                    imagesc(imresize(scoreMapSmall, size(I,1:2)), 'AlphaData', 0.3);
                    colormap jet
                    exportgraphics(gcf,[dstFolder filesep fName '_occlusionSensitivity' fExt]);  
                end
                
                if dlg.CancelRequested
                    break % User wants to interrupt computation
                end                
            end
            close(dlg);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Trachoma Finder';
            app.UIFigure.Icon = 'FollicleFinder.png';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.AnnotatePhotosTab = uitab(app.TabGroup);
            app.AnnotatePhotosTab.Title = 'Annotate Photos';
            app.btnChooseUser = uibutton(app.AnnotatePhotosTab, 'push');
            app.btnChooseUser.ButtonPushedFcn = createCallbackFcn(app, @btnChooseUserButtonPushed, true);
            app.btnChooseUser.Icon = '48px-split.png';
            app.btnChooseUser.Tooltip = {'Change current user name'};
            app.btnChooseUser.Text = 'Choose user';
            app.btnPhotosFolder = uibutton(app.AnnotatePhotosTab, 'push');
            app.btnPhotosFolder.ButtonPushedFcn = createCallbackFcn(app, @btnPhotosFolderButtonPushed, true);
            app.btnPhotosFolder.Icon = '48px-Training-set.png';
            app.btnPhotosFolder.Enable = 'off';
            app.btnPhotosFolder.Tooltip = {'Open a folder containing photos to score'};
            app.btnPhotosFolder.Text = 'Photos folder';
            app.btnAnnotateManual = uibutton(app.AnnotatePhotosTab, 'push');
            app.btnAnnotateManual.ButtonPushedFcn = createCallbackFcn(app, @btnAnnotateManualButtonPushed, true);
            app.btnAnnotateManual.Icon = 'Eyelid.png';
            app.btnAnnotateManual.Enable = 'off';
            app.btnAnnotateManual.Tooltip = {'Manually identify follicles'};
            app.btnAnnotateManual.Text = 'Manual';
            app.btnDeleteRow = uibutton(app.AnnotatePhotosTab, 'push');
            app.btnDeleteRow.ButtonPushedFcn = createCallbackFcn(app, @btnDeleteRowButtonPushed, true);
            app.btnDeleteRow.Icon = '48px-Delete.png';
            app.btnDeleteRow.Enable = 'off';
            app.btnDeleteRow.Tooltip = {'Delete selected record'};
            app.btnDeleteRow.Text = 'Delete';
            app.btnExportTraining = uibutton(app.AnnotatePhotosTab, 'push');
            app.btnExportTraining.ButtonPushedFcn = createCallbackFcn(app, @btnExportTrainingButtonPushed, true);
            app.btnExportTraining.Icon = '48px-export.png';
            app.btnExportTraining.Enable = 'off';
            app.btnExportTraining.Tooltip = {'Export selected record to disk'};
            app.btnExportTraining.Text = 'Export';
            app.btnImportTraining = uibutton(app.AnnotatePhotosTab, 'push');
            app.btnImportTraining.ButtonPushedFcn = createCallbackFcn(app, @btnImportTrainingButtonPushed, true);
            app.btnImportTraining.Icon = '48px-import.png';
            app.btnImportTraining.Tooltip = {'Import record from disk'};
            app.btnImportTraining.Text = 'Import';
            app.btnDuplicateTraining = uibutton(app.AnnotatePhotosTab, 'push');
            app.btnDuplicateTraining.ButtonPushedFcn = createCallbackFcn(app, @btnDuplicateTrainingButtonPushed, true);
            app.btnDuplicateTraining.Icon = '48px-copy.png';
            app.btnDuplicateTraining.Enable = 'off';
            app.btnDuplicateTraining.Tooltip = {'Duplicate selected record'};
            app.btnDuplicateTraining.Text = 'Duplicate';
            app.btnAnnotateAuto = uibutton(app.AnnotatePhotosTab, 'push');
            app.btnAnnotateAuto.ButtonPushedFcn = createCallbackFcn(app, @btnAnnotateAutoButtonPushed, true);
            app.btnAnnotateAuto.Icon = '48px-AutoEyelid.png';
            app.btnAnnotateAuto.Enable = 'off';
            app.btnAnnotateAuto.Tooltip = {'Automatically identify eyelid region using selected pixel classifier NeuralNet'};
            app.btnAnnotateAuto.Text = 'Automatic';
            app.btnDiagnoseTF = uibutton(app.AnnotatePhotosTab, 'push');
            app.btnDiagnoseTF.ButtonPushedFcn = createCallbackFcn(app, @btnDiagnoseTFButtonPushed, true);
            app.btnDiagnoseTF.Icon = '48px-ValidateFollicles.png';
            app.btnDiagnoseTF.Enable = 'off';
            app.btnDiagnoseTF.Tooltip = {'Validate follicle candidates using selected object classifier NeuralNet'};
            app.btnDiagnoseTF.Text = 'Diagnose TF';
            app.btnDiagnoseTI = uibutton(app.AnnotatePhotosTab, 'push');
            app.btnDiagnoseTI.ButtonPushedFcn = createCallbackFcn(app, @btnDiagnoseTIButtonPushed, true);
            app.btnDiagnoseTI.Icon = '48px-DiagnoseTI.png';
            app.btnDiagnoseTI.Enable = 'off';
            app.btnDiagnoseTI.Tooltip = {'Validate follicle candidates using selected object classifier NeuralNet'};
            app.btnDiagnoseTI.Text = 'Diagnose TI';
            app.NeuralNetworkTab = uitab(app.TabGroup);
            app.NeuralNetworkTab.Title = 'Neural Network';
            app.btnNetGTruth = uibutton(app.NeuralNetworkTab, 'push');
            app.btnNetGTruth.ButtonPushedFcn = createCallbackFcn(app, @btnNetGTruthButtonPushed, true);
            app.btnNetGTruth.Icon = '48px-Training-set.png';
            app.btnNetGTruth.Enable = 'off';
            app.btnNetGTruth.Tooltip = {'Generate a ground truth dataset from selected grading sessions'};
            app.btnNetGTruth.Text = {'Generate'; 'Training Set'};
            app.btnNetTrain = uibutton(app.NeuralNetworkTab, 'push');
            app.btnNetTrain.ButtonPushedFcn = createCallbackFcn(app, @btnNetTrainButtonPushed, true);
            app.btnNetTrain.Icon = '48px-robot.png';
            app.btnNetTrain.Enable = 'off';
            app.btnNetTrain.Tooltip = {'Train the selected NeuralNet'};
            app.btnNetTrain.Text = {'Train'; 'Network'};
            app.btnNetTest = uibutton(app.NeuralNetworkTab, 'push');
            app.btnNetTest.ButtonPushedFcn = createCallbackFcn(app, @btnNetTestButtonPushed, true);
            app.btnNetTest.Icon = '48px-unblock-granted.png';
            app.btnNetTest.Enable = 'off';
            app.btnNetTest.Tooltip = {'Test the selected trained NeuralNet'};
            app.btnNetTest.Text = {'Test'; 'Network'};
            app.NetTypeDropDownLabel = uilabel(app.NeuralNetworkTab);
            app.NetTypeDropDownLabel.Text = 'Net Type:';
            app.cmbNetType = uidropdown(app.NeuralNetworkTab);
            app.cmbNetType.Items = {'Pixel classifier', 'Object classifier', 'Object detector', 'Image classifier'};
            app.cmbNetType.ValueChangedFcn = createCallbackFcn(app, @cmbNetTypeValueChanged, true);
            app.cmbNetType.Value = 'Pixel classifier';
            app.NetModelDropDownLabel = uilabel(app.NeuralNetworkTab);
            app.NetModelDropDownLabel.Text = 'Net Model:';
            app.cmbNetModel = uidropdown(app.NeuralNetworkTab);
            app.cmbNetModel.Items = {};
            app.cmbNetModel.Value = {};
            app.btnNetCreate = uibutton(app.NeuralNetworkTab, 'push');
            app.btnNetCreate.ButtonPushedFcn = createCallbackFcn(app, @btnNetCreateButtonPushed, true);
            app.btnNetCreate.Icon = '48px-new.png';
            app.btnNetCreate.Tooltip = {'Create an ew NeuralNet of the specified model'};
            app.btnNetCreate.Text = {'Create'; 'Network'};
            app.btnNetDelete = uibutton(app.NeuralNetworkTab, 'push');
            app.btnNetDelete.ButtonPushedFcn = createCallbackFcn(app, @btnNetDeleteButtonPushed, true);
            app.btnNetDelete.Icon = '48px-Delete.png';
            app.btnNetDelete.Enable = 'off';
            app.btnNetDelete.Tooltip = {'Delete selected NeuralNet'};
            app.btnNetDelete.Text = '';
            app.btnNetClone = uibutton(app.NeuralNetworkTab, 'push');
            app.btnNetClone.ButtonPushedFcn = createCallbackFcn(app, @btnNetCloneButtonPushed, true);
            app.btnNetClone.Icon = '48px-copy.png';
            app.btnNetClone.Enable = 'off';
            app.btnNetClone.Tooltip = {'Duplicate selected NeuralNet'};
            app.btnNetClone.Text = '';
            app.btnNetRename = uibutton(app.NeuralNetworkTab, 'push');
            app.btnNetRename.ButtonPushedFcn = createCallbackFcn(app, @btnNetRenameButtonPushed, true);
            app.btnNetRename.Icon = '48px-Edit.png';
            app.btnNetRename.Enable = 'off';
            app.btnNetRename.Tooltip = {'Rename selected NeuralNet'};
            app.btnNetRename.Text = '';
            app.btnNetInfo = uibutton(app.NeuralNetworkTab, 'push');
            app.btnNetInfo.ButtonPushedFcn = createCallbackFcn(app, @btnNetInfoButtonPushed, true);
            app.btnNetInfo.Icon = '48px-info.png';
            app.btnNetInfo.Enable = 'off';
            app.btnNetInfo.Tooltip = {'Visualize NeuralNet architecture'};
            app.btnNetInfo.Text = '';
            app.btnNetImport = uibutton(app.NeuralNetworkTab, 'push');
            app.btnNetImport.ButtonPushedFcn = createCallbackFcn(app, @btnNetImportButtonPushed, true);
            app.btnNetImport.Icon = '48px-import.png';
            app.btnNetImport.Tooltip = {'Import NeuralNet from file'};
            app.btnNetImport.Text = '';
            app.btnNetExport = uibutton(app.NeuralNetworkTab, 'push');
            app.btnNetExport.ButtonPushedFcn = createCallbackFcn(app, @btnNetExportButtonPushed, true);
            app.btnNetExport.Icon = '48px-export.png';
            app.btnNetExport.Enable = 'off';
            app.btnNetExport.Tooltip = {'Save a copy of NeuralNet to custom folder'};
            app.btnNetExport.Text = '';
            app.TargetLabel = uilabel(app.NeuralNetworkTab);
            app.TargetLabel.Text = 'Target:';
            app.cmbNetTarget = uidropdown(app.NeuralNetworkTab);
            app.cmbNetTarget.Items = {'Eyelid', 'Follicles', 'TF'};
            app.cmbNetTarget.Value = 'Eyelid';
            app.CompareTab = uitab(app.TabGroup);
            app.CompareTab.Title = 'Compare';
            app.btnCompareGraders = uibutton(app.CompareTab, 'push');
            app.btnCompareGraders.ButtonPushedFcn = createCallbackFcn(app, @btnCompareGradersButtonPushed, true);
            app.btnCompareGraders.Icon = '48px-compare-humans.png';
            app.btnCompareGraders.Tooltip = {'Compares agreement level (ICC) between selected graders'};
            app.btnCompareGraders.Text = {'Graders '; 'Agreement'};
            app.btnCompareNetworks = uibutton(app.CompareTab, 'push');
            app.btnCompareNetworks.ButtonPushedFcn = createCallbackFcn(app, @btnCompareNetworksButtonPushed, true);
            app.btnCompareNetworks.Icon = '48px-Monkeys.png';
            app.btnCompareNetworks.Tooltip = {'Compare recall and precision of selected neural nets over selected photos'};
            app.btnCompareNetworks.Text = {'Networks '; 'Performance'};
            app.btnROC = uibutton(app.CompareTab, 'push');
            app.btnROC.ButtonPushedFcn = createCallbackFcn(app, @btnROCButtonPushed, true);
            app.btnROC.Icon = '48px-Monkeys.png';
            app.btnROC.Tooltip = {'Calculate Receiver Operating Characteristic curve'};
            app.btnROC.Text = {'ROC'; 'Curve'};
            app.btnGradCam = uibutton(app.CompareTab, 'push');
            app.btnGradCam.ButtonPushedFcn = createCallbackFcn(app, @btnGradCamButtonPushed, true);
            app.btnGradCam.Icon = '48px-GradCamera.png';
            app.btnGradCam.Tooltip = {'Visualize neural net attention as heatmap'};
            app.btnGradCam.Text = 'Grad-CAM';
            app.ToolsTab = uitab(app.TabGroup);
            app.ToolsTab.Title = 'Tools';
            app.btnBatchResize = uibutton(app.ToolsTab, 'push');
            app.btnBatchResize.ButtonPushedFcn = createCallbackFcn(app, @btnBatchResizeButtonPushed, true);
            app.btnBatchResize.Icon = '48px-resize.png';
            app.btnBatchResize.Tooltip = {'Resize all photos in selected folder'};
            app.btnBatchResize.Text = {'Batch'; 'Resize'};
            app.btnBatchMaskEyelid = uibutton(app.ToolsTab, 'push');
            app.btnBatchMaskEyelid.ButtonPushedFcn = createCallbackFcn(app, @btnBatchMaskEyelidButtonPushed, true);
            app.btnBatchMaskEyelid.Icon = '48px-Masks.png';
            app.btnBatchMaskEyelid.Enable = 'off';
            app.btnBatchMaskEyelid.Tooltip = {'Create binary mask image files of eyelids'};
            app.btnBatchMaskEyelid.Text = {'Export'; 'Masks'};
            app.btnBatchSaveEachFollicle = uibutton(app.ToolsTab, 'push');
            app.btnBatchSaveEachFollicle.ButtonPushedFcn = createCallbackFcn(app, @btnBatchSaveEachFollicleButtonPushed, true);
            app.btnBatchSaveEachFollicle.Icon = '48px-Mushroom-cutout.png';
            app.btnBatchSaveEachFollicle.Enable = 'off';
            app.btnBatchSaveEachFollicle.Tooltip = {'Create image cutouts of each follicle'};
            app.btnBatchSaveEachFollicle.Text = {'Individual '; 'Follicle'};
            app.btnBatchMaskFollicles = uibutton(app.ToolsTab, 'push');
            app.btnBatchMaskFollicles.ButtonPushedFcn = createCallbackFcn(app, @btnBatchMaskFolliclesButtonPushed, true);
            app.btnBatchMaskFollicles.Icon = '48px-Mushroom.png';
            app.btnBatchMaskFollicles.Enable = 'off';
            app.btnBatchMaskFollicles.Tooltip = {'Export masked photos of eyelid/follicles, with optional blurring'};
            app.btnBatchMaskFollicles.Text = {'Export '; 'Masked'};
            app.btnRelinkImages = uibutton(app.ToolsTab, 'push');
            app.btnRelinkImages.ButtonPushedFcn = createCallbackFcn(app, @btnRelinkImagesButtonPushed, true);
            app.btnRelinkImages.Icon = '48px-relink-images.png';
            app.btnRelinkImages.Enable = 'off';
            app.btnRelinkImages.Tooltip = {'Relink photos folder to a new location'};
            app.btnRelinkImages.Text = {'Relink'; 'Photos'};
            app.btnBatchSaveBboxes = uibutton(app.ToolsTab, 'push');
            app.btnBatchSaveBboxes.ButtonPushedFcn = createCallbackFcn(app, @btnBatchSaveBboxesButtonPushed, true);
            app.btnBatchSaveBboxes.Icon = '48px-Mushroom-bboxes.png';
            app.btnBatchSaveBboxes.Enable = 'off';
            app.btnBatchSaveBboxes.Tooltip = {'Save bounding boxes coordinates into excel tables'};
            app.btnBatchSaveBboxes.Text = {'Follicles '; 'BBoxes'};
            app.btnMergeRecords = uibutton(app.ToolsTab, 'push');
            app.btnMergeRecords.ButtonPushedFcn = createCallbackFcn(app, @btnMergeRecordsButtonPushed, true);
            app.btnMergeRecords.Icon = '48px-Merge.png';
            app.btnMergeRecords.Enable = 'off';
            app.btnMergeRecords.Tooltip = {'Merge selected records into a single one'};
            app.btnMergeRecords.Text = {'Merge'; 'Records'};
            app.btnClearEyelid = uibutton(app.ToolsTab, 'push');
            app.btnClearEyelid.ButtonPushedFcn = createCallbackFcn(app, @btnClearEyelidButtonPushed, true);
            app.btnClearEyelid.Icon = '48px-clear.png';
            app.btnClearEyelid.Enable = 'off';
            app.btnClearEyelid.Tooltip = {'Clear eyelid segmentation data from selected records'};
            app.btnClearEyelid.Text = 'Eyelids';
            app.btnClearFollicles = uibutton(app.ToolsTab, 'push');
            app.btnClearFollicles.ButtonPushedFcn = createCallbackFcn(app, @btnClearFolliclesButtonPushed, true);
            app.btnClearFollicles.Icon = '48px-clear.png';
            app.btnClearFollicles.Enable = 'off';
            app.btnClearFollicles.Tooltip = {'Clear follicles segmentation data from selected record'};
            app.btnClearFollicles.Text = 'Follicles';
            app.btnChangeTrainingType = uibutton(app.ToolsTab, 'push');
            app.btnChangeTrainingType.ButtonPushedFcn = createCallbackFcn(app, @btnChangeTrainingTypeButtonPushed, true);
            app.btnChangeTrainingType.Icon = '48px-Swap-auto-manual.png';
            app.btnChangeTrainingType.Enable = 'off';
            app.btnChangeTrainingType.Tooltip = {'Convert training type from Manual to Automatic'};
            app.btnChangeTrainingType.Text = {'Type of'; 'Training'};
            app.btnResetGPU = uibutton(app.ToolsTab, 'push');
            app.btnResetGPU.ButtonPushedFcn = createCallbackFcn(app, @btnResetGPUButtonPushed, true);
            app.btnResetGPU.Icon = '48px-GPU.png';
            app.btnResetGPU.Tooltip = {'Convert training type from Manual to Automatic'};
            app.btnResetGPU.Text = {'Reset'; 'GPUs'};
            app.btnBatchColoredFollicles = uibutton(app.ToolsTab, 'push');
            app.btnBatchColoredFollicles.ButtonPushedFcn = createCallbackFcn(app, @btnBatchColoredFolliclesButtonPushed, true);
            app.btnBatchColoredFollicles.Icon = '48px-Mushroom-colored.png';
            app.btnBatchColoredFollicles.Enable = 'off';
            app.btnBatchColoredFollicles.Tooltip = {'Create color mask image files of follicles using random colors'};
            app.btnBatchColoredFollicles.Text = {'Colored'; 'Follicles'};
            app.btnClearFiles = uibutton(app.ToolsTab, 'push');
            app.btnClearFiles.ButtonPushedFcn = createCallbackFcn(app, @btnClearFilesButtonPushed, true);
            app.btnClearFiles.Icon = '48px-copy.png';
            app.btnClearFiles.Tooltip = {'Randomly copy selected amount of files between folders'};
            app.btnClearFiles.Text = 'Copy Files';
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.ZoomlevelPanel = uipanel(app.SettingsTab);
            app.ZoomlevelPanel.Title = 'Zoom level';
            app.FolliclesLabel = uilabel(app.ZoomlevelPanel);
            app.FolliclesLabel.Text = 'Follicles';
            app.cmbZoomFollicles = uispinner(app.ZoomlevelPanel);
            app.cmbZoomFollicles.Limits = [1 32];
            app.cmbZoomFollicles.ValueChangedFcn = createCallbackFcn(app, @cmbZoomFolliclesValueChanged, true);
            app.cmbZoomFollicles.Value = 2;
            app.EyelidLabel = uilabel(app.ZoomlevelPanel);
            app.EyelidLabel.Text = 'Eyelid';
            app.cmbZoomEyelid = uispinner(app.ZoomlevelPanel);
            app.cmbZoomEyelid.Limits = [1 32];
            app.cmbZoomEyelid.ValueChangedFcn = createCallbackFcn(app, @cmbZoomEyelidValueChanged, true);
            app.cmbZoomEyelid.Value = 1;
            app.ObjectsColorPanel = uipanel(app.SettingsTab);
            app.ObjectsColorPanel.Title = 'Objects Color';
            app.btnColorSelected = uibutton(app.ObjectsColorPanel, 'push');
            app.btnColorSelected.ButtonPushedFcn = createCallbackFcn(app, @btnColorSelectedButtonPushed, true);
            app.btnColorSelected.Text = 'Selected';
            app.btnColorFalse = uibutton(app.ObjectsColorPanel, 'push');
            app.btnColorFalse.ButtonPushedFcn = createCallbackFcn(app, @btnColorFalseButtonPushed, true);
            app.btnColorFalse.Text = 'False';
            app.btnColorTrue = uibutton(app.ObjectsColorPanel, 'push');
            app.btnColorTrue.ButtonPushedFcn = createCallbackFcn(app, @btnColorTrueButtonPushed, true);
            app.btnColorTrue.Text = 'True';
            app.btnColorMaybe = uibutton(app.ObjectsColorPanel, 'push');
            app.btnColorMaybe.ButtonPushedFcn = createCallbackFcn(app, @btnColorMaybeButtonPushed, true);
            app.btnColorMaybe.Text = 'Maybe';
            app.ColorFalse = uilamp(app.ObjectsColorPanel);
            app.ColorSelected = uilamp(app.ObjectsColorPanel);
            app.ColorTrue = uilamp(app.ObjectsColorPanel);
            app.ColorMaybe = uilamp(app.ObjectsColorPanel);
            app.btnResetDefaults = uibutton(app.SettingsTab, 'push');
            app.btnResetDefaults.ButtonPushedFcn = createCallbackFcn(app, @btnResetDefaultsButtonPushed, true);
            app.btnResetDefaults.Icon = '48px-Refresh.png';
            app.btnResetDefaults.Tooltip = {'Refresh table of analyzed data'};
            app.btnResetDefaults.Text = {'Reset '; 'Settings'};
            app.NeuralNetConfidencePanel = uipanel(app.SettingsTab);
            app.NeuralNetConfidencePanel.Title = 'Neural Net Confidence';
            app.FolliclesLabel_2 = uilabel(app.NeuralNetConfidencePanel);
            app.FolliclesLabel_2.Text = 'Follicles';
            app.cmbNetConfidenceFollicles = uispinner(app.NeuralNetConfidencePanel);
            app.cmbNetConfidenceFollicles.Step = 0.05;
            app.cmbNetConfidenceFollicles.Limits = [0 1];
            app.cmbNetConfidenceFollicles.ValueChangedFcn = createCallbackFcn(app, @cmbNetConfidenceFolliclesValueChanged, true);
            app.cmbNetConfidenceFollicles.Value = 0.9;
            app.EyelidLabel_2 = uilabel(app.NeuralNetConfidencePanel);
            app.EyelidLabel_2.Text = 'Eyelid';
            app.cmbNetConfidenceEyelid = uispinner(app.NeuralNetConfidencePanel);
            app.cmbNetConfidenceEyelid.Step = 0.05;
            app.cmbNetConfidenceEyelid.Limits = [0 1];
            app.cmbNetConfidenceEyelid.ValueChangedFcn = createCallbackFcn(app, @cmbNetConfidenceEyelidValueChanged, true);
            app.cmbNetConfidenceEyelid.Value = 0.9;
            app.btnRefreshTable = uibutton(app.SettingsTab, 'push');
            app.btnRefreshTable.ButtonPushedFcn = createCallbackFcn(app, @btnRefreshTableButtonPushed, true);
            app.btnRefreshTable.Icon = '48px-Refresh.png';
            app.btnRefreshTable.Tooltip = {'Refresh tables and clear selections'};
            app.btnRefreshTable.Text = {'Refresh'; 'Tables'};
            app.btnBackupAnnotations = uibutton(app.SettingsTab, 'push');
            app.btnBackupAnnotations.ButtonPushedFcn = createCallbackFcn(app, @btnBackupAnnotationsButtonPushed, true);
            app.btnBackupAnnotations.Icon = 'Backup.png';
            app.btnBackupAnnotations.Tooltip = {'Refresh tables and clear selections'};
            app.btnBackupAnnotations.Text = {'Backup'; 'Annotations'};
            app.AboutTab = uitab(app.TabGroup);
            app.AboutTab.Title = 'About';
            app.Image = uiimage(app.AboutTab);
            app.Image.ImageSource = 'FollicleFinder.png';
            app.lblAppName = uilabel(app.AboutTab);
            app.lblAppName.Text = 'Trachoma Finder';
            app.lblVersion = uilabel(app.AboutTab);
            app.lblVersion.Text = 'Version xx.yy.zz';
            app.lblCopyright = uilabel(app.AboutTab);
            app.lblCopyright.Text = 'Copyright 2019-2021 Luca Della Santina';
            app.TextArea = uitextarea(app.AboutTab);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'GNU GENERAL PUBLIC LICENSE'; 'Version 3'; ' 29 June 2007'; 'Copyright © 2007 Free Software Foundation'; ' Inc. <https://fsf.org/>'; 'Everyone is permitted to copy and distribute verbatim copies of this license document but changing it is not allowed.'; ''; 'Preamble'; 'The GNU General Public License is a free copyleft license for software and other kinds of works.'; 'The licenses for most software and other practical works are designed to take away your freedom to share and change the works. By contrast the GNU General Public License is intended to guarantee your freedom to share and change all versions of a program--to make sure it remains free software for all its users. We the Free Software Foundation use the GNU General Public License for most of our software it applies also to any other work released this way by its authors. You can apply it to your programs too.'; 'When we speak of free software we are referring to freedom not price. Our General Public Licenses are designed to make sure that you have the freedom to distribute copies of free software (and charge for them if you wish) that you receive source code or can get it if you want it that you can change the software or use pieces of it in new free programs and that you know you can do these things.'; 'To protect your rights we need to prevent others from denying you these rights or asking you to surrender the rights. Therefore'; ' you have certain responsibilities if you distribute copies of the software or if you modify it: responsibilities to respect the freedom of others.'; 'For example if you distribute copies of such a program'; ' whether gratis or for a fee you must pass on to the recipients the same freedoms that you received. You must make sure that they'; ' too receive or can get the source code. And you must show them these terms so they know their rights.'; 'Developers that use the GNU GPL protect your rights with two steps: (1) assert copyright on the software and (2) offer you this License giving you legal permission to copy distribute and/or modify it.'; 'For the developers'' and authors'' protection the GPL clearly explains that there is no warranty for this free software. For both users'' and authors'' sake'; ' the GPL requires that modified versions be marked as changed so that their problems will not be attributed erroneously to authors of previous versions.'; 'Some devices are designed to deny users access to install or run modified versions of the software inside them although the manufacturer can do so. This is fundamentally incompatible with the aim of protecting users'' freedom to change the software. The systematic pattern of such abuse occurs in the area of products for individuals to use'; ' which is precisely where it is most unacceptable. Therefore we have designed this version of the GPL to prohibit the practice for those products. If such problems arise substantially in other domains'; ' we stand ready to extend this provision to those domains in future versions of the GPL as needed to protect the freedom of users.'; 'Finally every program is threatened constantly by software patents. States should not allow patents to restrict development and use of software on general-purpose computers but in those that do we wish to avoid the special danger that patents applied to a free program could make it effectively proprietary. To prevent this the GPL assures that patents cannot be used to render the program non-free.'; 'The precise terms and conditions for copying distribution and modification follow.'; ''; 'TERMS AND CONDITIONS'; '0. Definitions.'; '“This License” refers to version 3 of the GNU General Public License.'; '“Copyright” also means copyright-like laws that apply to other kinds of works'; ' such as semiconductor masks.'; '“The Program” refers to any copyrightable work licensed under this License. Each licensee is addressed as “you”. “Licensees” and “recipients” may be individuals or organizations.'; 'To “modify” a work means to copy from or adapt all or part of the work in a fashion requiring copyright permission'; ' other than the making of an exact copy. The resulting work is called a “modified version” of the earlier work or a work “based on” the earlier work.'; 'A “covered work” means either the unmodified Program or a work based on the Program.'; 'To “propagate” a work means to do anything with it that'; ' without permission'; ' would make you directly or secondarily liable for infringement under applicable copyright law'; ' except executing it on a computer or modifying a private copy. Propagation includes copying'; ' distribution (with or without modification)'; ' making available to the public'; ' and in some countries other activities as well.'; 'To “convey” a work means any kind of propagation that enables other parties to make or receive copies. Mere interaction with a user through a computer network'; ' with no transfer of a copy'; ' is not conveying.'; 'An interactive user interface displays “Appropriate Legal Notices” to the extent that it includes a convenient and prominently visible feature that (1) displays an appropriate copyright notice'; ' and (2) tells the user that there is no warranty for the work (except to the extent that warranties are provided)'; ' that licensees may convey the work under this License'; ' and how to view a copy of this License. If the interface presents a list of user commands or options'; ' such as a menu'; ' a prominent item in the list meets this criterion.'; '1. Source Code.'; 'The “source code” for a work means the preferred form of the work for making modifications to it. “Object code” means any non-source form of a work.'; 'A “Standard Interface” means an interface that either is an official standard defined by a recognized standards body'; ' or'; ' in the case of interfaces specified for a particular programming language'; ' one that is widely used among developers working in that language.'; 'The “System Libraries” of an executable work include anything'; ' other than the work as a whole'; ' that (a) is included in the normal form of packaging a Major Component'; ' but which is not part of that Major Component'; ' and (b) serves only to enable use of the work with that Major Component'; ' or to implement a Standard Interface for which an implementation is available to the public in source code form. A “Major Component”'; ' in this context'; ' means a major essential component (kernel'; ' window system'; ' and so on) of the specific operating system (if any) on which the executable work runs'; ' or a compiler used to produce the work'; ' or an object code interpreter used to run it.'; 'The “Corresponding Source” for a work in object code form means all the source code needed to generate'; ' install'; ' and (for an executable work) run the object code and to modify the work'; ' including scripts to control those activities. However'; ' it does not include the work''s System Libraries'; ' or general-purpose tools or generally available free programs which are used unmodified in performing those activities but which are not part of the work. For example'; ' Corresponding Source includes interface definition files associated with source files for the work'; ' and the source code for shared libraries and dynamically linked subprograms that the work is specifically designed to require'; ' such as by intimate data communication or control flow between those subprograms and other parts of the work.'; 'The Corresponding Source need not include anything that users can regenerate automatically from other parts of the Corresponding Source.'; 'The Corresponding Source for a work in source code form is that same work.'; '2. Basic Permissions.'; 'All rights granted under this License are granted for the term of copyright on the Program'; ' and are irrevocable provided the stated conditions are met. This License explicitly affirms your unlimited permission to run the unmodified Program. The output from running a covered work is covered by this License only if the output'; ' given its content'; ' constitutes a covered work. This License acknowledges your rights of fair use or other equivalent'; ' as provided by copyright law.'; 'You may make'; ' run and propagate covered works that you do not convey'; ' without conditions so long as your license otherwise remains in force. You may convey covered works to others for the sole purpose of having them make modifications exclusively for you'; ' or provide you with facilities for running those works'; ' provided that you comply with the terms of this License in conveying all material for which you do not control copyright. Those thus making or running the covered works for you must do so exclusively on your behalf'; ' under your direction and control'; ' on terms that prohibit them from making any copies of your copyrighted material outside their relationship with you.'; 'Conveying under any other circumstances is permitted solely under the conditions stated below. Sublicensing is not allowed'; ' section 10 makes it unnecessary.'; '3. Protecting Users'' Legal Rights From Anti-Circumvention Law.'; 'No covered work shall be deemed part of an effective technological measure under any applicable law fulfilling obligations under article 11 of the WIPO copyright treaty adopted on 20 December 1996'; ' or similar laws prohibiting or restricting circumvention of such measures.'; 'When you convey a covered work'; ' you waive any legal power to forbid circumvention of technological measures to the extent such circumvention is effected by exercising rights under this License with respect to the covered work'; ' and you disclaim any intention to limit operation or modification of the work as a means of enforcing'; ' against the work''s users'; ' your or third parties'' legal rights to forbid circumvention of technological measures.'; '4. Conveying Verbatim Copies.'; 'You may convey verbatim copies of the Program''s source code as you receive it'; ' in any medium'; ' provided that you conspicuously and appropriately publish on each copy an appropriate copyright notice'; ' keep intact all notices stating that this License and any non-permissive terms added in accord with section 7 apply to the code'; ' keep intact all notices of the absence of any warranty'; ' and give all recipients a copy of this License along with the Program.'; 'You may charge any price or no price for each copy that you convey'; ' and you may offer support or warranty protection for a fee.'; '5. Conveying Modified Source Versions.'; 'You may convey a work based on the Program'; ' or the modifications to produce it from the Program'; ' in the form of source code under the terms of section 4'; ' provided that you also meet all of these conditions:'; '•	a) The work must carry prominent notices stating that you modified it'; ' and giving a relevant date.'; '•	b) The work must carry prominent notices stating that it is released under this License and any conditions added under section 7. This requirement modifies the requirement in section 4 to “keep intact all notices”.'; '•	c) You must license the entire work'; ' as a whole'; ' under this License to anyone who comes into possession of a copy. This License will therefore apply'; ' along with any applicable section 7 additional terms'; ' to the whole of the work'; ' and all its parts'; ' regardless of how they are packaged. This License gives no permission to license the work in any other way'; ' but it does not invalidate such permission if you have separately received it.'; '•	d) If the work has interactive user interfaces'; ' each must display Appropriate Legal Notices'; ' however'; ' if the Program has interactive interfaces that do not display Appropriate Legal Notices'; ' your work need not make them do so.'; 'A compilation of a covered work with other separate and independent works'; ' which are not by their nature extensions of the covered work'; ' and which are not combined with it such as to form a larger program'; ' in or on a volume of a storage or distribution medium'; ' is called an “aggregate” if the compilation and its resulting copyright are not used to limit the access or legal rights of the compilation''s users beyond what the individual works permit. Inclusion of a covered work in an aggregate does not cause this License to apply to the other parts of the aggregate.'; '6. Conveying Non-Source Forms.'; 'You may convey a covered work in object code form under the terms of sections 4 and 5'; ' provided that you also convey the machine-readable Corresponding Source under the terms of this License'; ' in one of these ways:'; '•	a) Convey the object code in'; ' or embodied in'; ' a physical product (including a physical distribution medium)'; ' accompanied by the Corresponding Source fixed on a durable physical medium customarily used for software interchange.'; '•	b) Convey the object code in'; ' or embodied in'; ' a physical product (including a physical distribution medium)'; ' accompanied by a written offer'; ' valid for at least three years and valid for as long as you offer spare parts or customer support for that product model'; ' to give anyone who possesses the object code either (1) a copy of the Corresponding Source for all the software in the product that is covered by this License'; ' on a durable physical medium customarily used for software interchange'; ' for a price no more than your reasonable cost of physically performing this conveying of source'; ' or (2) access to copy the Corresponding Source from a network server at no charge.'; '•	c) Convey individual copies of the object code with a copy of the written offer to provide the Corresponding Source. This alternative is allowed only occasionally and noncommercially'; ' and only if you received the object code with such an offer'; ' in accord with subsection 6b.'; '•	d) Convey the object code by offering access from a designated place (gratis or for a charge)'; ' and offer equivalent access to the Corresponding Source in the same way through the same place at no further charge. You need not require recipients to copy the Corresponding Source along with the object code. If the place to copy the object code is a network server'; ' the Corresponding Source may be on a different server (operated by you or a third party) that supports equivalent copying facilities'; ' provided you maintain clear directions next to the object code saying where to find the Corresponding Source. Regardless of what server hosts the Corresponding Source'; ' you remain obligated to ensure that it is available for as long as needed to satisfy these requirements.'; '•	e) Convey the object code using peer-to-peer transmission'; ' provided you inform other peers where the object code and Corresponding Source of the work are being offered to the general public at no charge under subsection 6d.'; 'A separable portion of the object code'; ' whose source code is excluded from the Corresponding Source as a System Library'; ' need not be included in conveying the object code work.'; 'A “User Product” is either (1) a “consumer product”'; ' which means any tangible personal property which is normally used for personal'; ' family'; ' or household purposes'; ' or (2) anything designed or sold for incorporation into a dwelling. In determining whether a product is a consumer product'; ' doubtful cases shall be resolved in favor of coverage. For a particular product received by a particular user'; ' “normally used” refers to a typical or common use of that class of product'; ' regardless of the status of the particular user or of the way in which the particular user actually uses'; ' or expects or is expected to use'; ' the product. A product is a consumer product regardless of whether the product has substantial commercial'; ' industrial or non-consumer uses'; ' unless such uses represent the only significant mode of use of the product.'; '“Installation Information” for a User Product means any methods'; ' procedures'; ' authorization keys'; ' or other information required to install and execute modified versions of a covered work in that User Product from a modified version of its Corresponding Source. The information must suffice to ensure that the continued functioning of the modified object code is in no case prevented or interfered with solely because modification has been made.'; 'If you convey an object code work under this section in'; ' or with'; ' or specifically for use in'; ' a User Product'; ' and the conveying occurs as part of a transaction in which the right of possession and use of the User Product is transferred to the recipient in perpetuity or for a fixed term (regardless of how the transaction is characterized)'; ' the Corresponding Source conveyed under this section must be accompanied by the Installation Information. But this requirement does not apply if neither you nor any third party retains the ability to install modified object code on the User Product (for example'; ' the work has been installed in ROM).'; 'The requirement to provide Installation Information does not include a requirement to continue to provide support service'; ' warranty'; ' or updates for a work that has been modified or installed by the recipient'; ' or for the User Product in which it has been modified or installed. Access to a network may be denied when the modification itself materially and adversely affects the operation of the network or violates the rules and protocols for communication across the network.'; 'Corresponding Source conveyed'; ' and Installation Information provided'; ' in accord with this section must be in a format that is publicly documented (and with an implementation available to the public in source code form)'; ' and must require no special password or key for unpacking'; ' reading or copying.'; '7. Additional Terms.'; '“Additional permissions” are terms that supplement the terms of this License by making exceptions from one or more of its conditions. Additional permissions that are applicable to the entire Program shall be treated as though they were included in this License'; ' to the extent that they are valid under applicable law. If additional permissions apply only to part of the Program'; ' that part may be used separately under those permissions'; ' but the entire Program remains governed by this License without regard to the additional permissions.'; 'When you convey a copy of a covered work'; ' you may at your option remove any additional permissions from that copy'; ' or from any part of it. (Additional permissions may be written to require their own removal in certain cases when you modify the work.) You may place additional permissions on material'; ' added by you to a covered work'; ' for which you have or can give appropriate copyright permission.'; 'Notwithstanding any other provision of this License'; ' for material you add to a covered work'; ' you may (if authorized by the copyright holders of that material) supplement the terms of this License with terms:'; '•	a) Disclaiming warranty or limiting liability differently from the terms of sections 15 and 16 of this License'; ' or'; '•	b) Requiring preservation of specified reasonable legal notices or author attributions in that material or in the Appropriate Legal Notices displayed by works containing it'; ' or'; '•	c) Prohibiting misrepresentation of the origin of that material'; ' or requiring that modified versions of such material be marked in reasonable ways as different from the original version'; ' or'; '•	d) Limiting the use for publicity purposes of names of licensors or authors of the material'; ' or'; '•	e) Declining to grant rights under trademark law for use of some trade names'; ' trademarks'; ' or service marks'; ' or'; '•	f) Requiring indemnification of licensors and authors of that material by anyone who conveys the material (or modified versions of it) with contractual assumptions of liability to the recipient'; ' for any liability that these contractual assumptions directly impose on those licensors and authors.'; 'All other non-permissive additional terms are considered “further restrictions” within the meaning of section 10. If the Program as you received it'; ' or any part of it'; ' contains a notice stating that it is governed by this License along with a term that is a further restriction'; ' you may remove that term. If a license document contains a further restriction but permits relicensing or conveying under this License'; ' you may add to a covered work material governed by the terms of that license document'; ' provided that the further restriction does not survive such relicensing or conveying.'; 'If you add terms to a covered work in accord with this section'; ' you must place'; ' in the relevant source files'; ' a statement of the additional terms that apply to those files'; ' or a notice indicating where to find the applicable terms.'; 'Additional terms'; ' permissive or non-permissive'; ' may be stated in the form of a separately written license'; ' or stated as exceptions'; ' the above requirements apply either way.'; '8. Termination.'; 'You may not propagate or modify a covered work except as expressly provided under this License. Any attempt otherwise to propagate or modify it is void'; ' and will automatically terminate your rights under this License (including any patent licenses granted under the third paragraph of section 11).'; 'However'; ' if you cease all violation of this License'; ' then your license from a particular copyright holder is reinstated (a) provisionally'; ' unless and until the copyright holder explicitly and finally terminates your license'; ' and (b) permanently'; ' if the copyright holder fails to notify you of the violation by some reasonable means prior to 60 days after the cessation.'; 'Moreover'; ' your license from a particular copyright holder is reinstated permanently if the copyright holder notifies you of the violation by some reasonable means'; ' this is the first time you have received notice of violation of this License (for any work) from that copyright holder'; ' and you cure the violation prior to 30 days after your receipt of the notice.'; 'Termination of your rights under this section does not terminate the licenses of parties who have received copies or rights from you under this License. If your rights have been terminated and not permanently reinstated'; ' you do not qualify to receive new licenses for the same material under section 10.'; '9. Acceptance Not Required for Having Copies.'; 'You are not required to accept this License in order to receive or run a copy of the Program. Ancillary propagation of a covered work occurring solely as a consequence of using peer-to-peer transmission to receive a copy likewise does not require acceptance. However'; ' nothing other than this License grants you permission to propagate or modify any covered work. These actions infringe copyright if you do not accept this License. Therefore'; ' by modifying or propagating a covered work'; ' you indicate your acceptance of this License to do so.'; '10. Automatic Licensing of Downstream Recipients.'; 'Each time you convey a covered work'; ' the recipient automatically receives a license from the original licensors'; ' to run'; ' modify and propagate that work'; ' subject to this License. You are not responsible for enforcing compliance by third parties with this License.'; 'An “entity transaction” is a transaction transferring control of an organization'; ' or substantially all assets of one'; ' or subdividing an organization'; ' or merging organizations. If propagation of a covered work results from an entity transaction'; ' each party to that transaction who receives a copy of the work also receives whatever licenses to the work the party''s predecessor in interest had or could give under the previous paragraph'; ' plus a right to possession of the Corresponding Source of the work from the predecessor in interest'; ' if the predecessor has it or can get it with reasonable efforts.'; 'You may not impose any further restrictions on the exercise of the rights granted or affirmed under this License. For example'; ' you may not impose a license fee'; ' royalty'; ' or other charge for exercise of rights granted under this License'; ' and you may not initiate litigation (including a cross-claim or counterclaim in a lawsuit) alleging that any patent claim is infringed by making'; ' using'; ' selling'; ' offering for sale'; ' or importing the Program or any portion of it.'; '11. Patents.'; 'A “contributor” is a copyright holder who authorizes use under this License of the Program or a work on which the Program is based. The work thus licensed is called the contributor''s “contributor version”.'; 'A contributor''s “essential patent claims” are all patent claims owned or controlled by the contributor'; ' whether already acquired or hereafter acquired'; ' that would be infringed by some manner'; ' permitted by this License'; ' of making'; ' using'; ' or selling its contributor version'; ' but do not include claims that would be infringed only as a consequence of further modification of the contributor version. For purposes of this definition'; ' “control” includes the right to grant patent sublicenses in a manner consistent with the requirements of this License.'; 'Each contributor grants you a non-exclusive'; ' worldwide'; ' royalty-free patent license under the contributor''s essential patent claims'; ' to make'; ' use'; ' sell'; ' offer for sale'; ' import and otherwise run'; ' modify and propagate the contents of its contributor version.'; 'In the following three paragraphs'; ' a “patent license” is any express agreement or commitment'; ' however denominated'; ' not to enforce a patent (such as an express permission to practice a patent or covenant not to sue for patent infringement). To “grant” such a patent license to a party means to make such an agreement or commitment not to enforce a patent against the party.'; 'If you convey a covered work'; ' knowingly relying on a patent license'; ' and the Corresponding Source of the work is not available for anyone to copy'; ' free of charge and under the terms of this License'; ' through a publicly available network server or other readily accessible means'; ' then you must either (1) cause the Corresponding Source to be so available'; ' or (2) arrange to deprive yourself of the benefit of the patent license for this particular work'; ' or (3) arrange'; ' in a manner consistent with the requirements of this License'; ' to extend the patent license to downstream recipients. “Knowingly relying” means you have actual knowledge that'; ' but for the patent license'; ' your conveying the covered work in a country'; ' or your recipient''s use of the covered work in a country'; ' would infringe one or more identifiable patents in that country that you have reason to believe are valid.'; 'If'; ' pursuant to or in connection with a single transaction or arrangement'; ' you convey'; ' or propagate by procuring conveyance of'; ' a covered work'; ' and grant a patent license to some of the parties receiving the covered work authorizing them to use'; ' propagate'; ' modify or convey a specific copy of the covered work'; ' then the patent license you grant is automatically extended to all recipients of the covered work and works based on it.'; 'A patent license is “discriminatory” if it does not include within the scope of its coverage'; ' prohibits the exercise of'; ' or is conditioned on the non-exercise of one or more of the rights that are specifically granted under this License. You may not convey a covered work if you are a party to an arrangement with a third party that is in the business of distributing software'; ' under which you make payment to the third party based on the extent of your activity of conveying the work'; ' and under which the third party grants'; ' to any of the parties who would receive the covered work from you'; ' a discriminatory patent license (a) in connection with copies of the covered work conveyed by you (or copies made from those copies)'; ' or (b) primarily for and in connection with specific products or compilations that contain the covered work'; ' unless you entered into that arrangement'; ' or that patent license was granted'; ' prior to 28 March 2007.'; 'Nothing in this License shall be construed as excluding or limiting any implied license or other defenses to infringement that may otherwise be available to you under applicable patent law.'; '12. No Surrender of Others'' Freedom.'; 'If conditions are imposed on you (whether by court order'; ' agreement or otherwise) that contradict the conditions of this License'; ' they do not excuse you from the conditions of this License. If you cannot convey a covered work so as to satisfy simultaneously your obligations under this License and any other pertinent obligations'; ' then as a consequence you may not convey it at all. For example'; ' if you agree to terms that obligate you to collect a royalty for further conveying from those to whom you convey the Program'; ' the only way you could satisfy both those terms and this License would be to refrain entirely from conveying the Program.'; '13. Use with the GNU Affero General Public License.'; 'Notwithstanding any other provision of this License'; ' you have permission to link or combine any covered work with a work licensed under version 3 of the GNU Affero General Public License into a single combined work'; ' and to convey the resulting work. The terms of this License will continue to apply to the part which is the covered work'; ' but the special requirements of the GNU Affero General Public License'; ' section 13'; ' concerning interaction through a network will apply to the combination as such.'; '14. Revised Versions of this License.'; 'The Free Software Foundation may publish revised and/or new versions of the GNU General Public License from time to time. Such new versions will be similar in spirit to the present version'; ' but may differ in detail to address new problems or concerns.'; 'Each version is given a distinguishing version number. If the Program specifies that a certain numbered version of the GNU General Public License “or any later version” applies to it'; ' you have the option of following the terms and conditions either of that numbered version or of any later version published by the Free Software Foundation. If the Program does not specify a version number of the GNU General Public License'; ' you may choose any version ever published by the Free Software Foundation.'; 'If the Program specifies that a proxy can decide which future versions of the GNU General Public License can be used'; ' that proxy''s public statement of acceptance of a version permanently authorizes you to choose that version for the Program.'; 'Later license versions may give you additional or different permissions. However'; ' no additional obligations are imposed on any author or copyright holder as a result of your choosing to follow a later version.'; '15. Disclaimer of Warranty.'; 'THERE IS NO WARRANTY FOR THE PROGRAM'; ' TO THE EXTENT PERMITTED BY APPLICABLE LAW. EXCEPT WHEN OTHERWISE STATED IN WRITING THE COPYRIGHT HOLDERS AND/OR OTHER PARTIES PROVIDE THE PROGRAM “AS IS” WITHOUT WARRANTY OF ANY KIND'; ' EITHER EXPRESSED OR IMPLIED'; ' INCLUDING'; ' BUT NOT LIMITED TO'; ' THE IMPLIED WARRANTIES OF MERCHANTABILITY AND FITNESS FOR A PARTICULAR PURPOSE. THE ENTIRE RISK AS TO THE QUALITY AND PERFORMANCE OF THE PROGRAM IS WITH YOU. SHOULD THE PROGRAM PROVE DEFECTIVE'; ' YOU ASSUME THE COST OF ALL NECESSARY SERVICING'; ' REPAIR OR CORRECTION.'; '16. Limitation of Liability.'; 'IN NO EVENT UNLESS REQUIRED BY APPLICABLE LAW OR AGREED TO IN WRITING WILL ANY COPYRIGHT HOLDER'; ' OR ANY OTHER PARTY WHO MODIFIES AND/OR CONVEYS THE PROGRAM AS PERMITTED ABOVE'; ' BE LIABLE TO YOU FOR DAMAGES'; ' INCLUDING ANY GENERAL'; ' SPECIAL'; ' INCIDENTAL OR CONSEQUENTIAL DAMAGES ARISING OUT OF THE USE OR INABILITY TO USE THE PROGRAM (INCLUDING BUT NOT LIMITED TO LOSS OF DATA OR DATA BEING RENDERED INACCURATE OR LOSSES SUSTAINED BY YOU OR THIRD PARTIES OR A FAILURE OF THE PROGRAM TO OPERATE WITH ANY OTHER PROGRAMS)'; ' EVEN IF SUCH HOLDER OR OTHER PARTY HAS BEEN ADVISED OF THE POSSIBILITY OF SUCH DAMAGES.'; '17. Interpretation of Sections 15 and 16.'; 'If the disclaimer of warranty and limitation of liability provided above cannot be given local legal effect according to their terms'; ' reviewing courts shall apply local law that most closely approximates an absolute waiver of all civil liability in connection with the Program'; ' unless a warranty or assumption of liability accompanies a copy of the Program in return for a fee.'; 'END OF TERMS AND CONDITIONS'; 'How to Apply These Terms to Your New Programs'; 'If you develop a new program'; ' and you want it to be of the greatest possible use to the public'; ' the best way to achieve this is to make it free software which everyone can redistribute and change under these terms.'; 'To do so'; ' attach the following notices to the program. It is safest to attach them to the start of each source file to most effectively state the exclusion of warranty'; ' and each file should have at least the “copyright” line and a pointer to where the full notice is found.'; '    <one line to give the program''s name and a brief idea of what it does.>'; '    Copyright (C) <year>  <name of author>'; ''; '    This program is free software: you can redistribute it and/or modify'; '    it under the terms of the GNU General Public License as published by'; '    the Free Software Foundation'; ' either version 3 of the License'; ' or'; '    (at your option) any later version.'; ''; '    This program is distributed in the hope that it will be useful'; ''; '    but WITHOUT ANY WARRANTY'; ' without even the implied warranty of'; '    MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE.  See the'; '    GNU General Public License for more details.'; ''; '    You should have received a copy of the GNU General Public License'; '    along with this program.  If not'; ' see <https://www.gnu.org/licenses/>.'; 'Also add information on how to contact you by electronic and paper mail.'; 'If the program does terminal interaction'; ' make it output a short notice like this when it starts in an interactive mode:'; '    <program>  Copyright (C) <year>  <name of author>'; '    This program comes with ABSOLUTELY NO WARRANTY'; ' for details type `show w''.'; '    This is free software'; ' and you are welcome to redistribute it'; '    under certain conditions'; ' type `show c'' for details.'; 'The hypothetical commands `show w'' and `show c'' should show the appropriate parts of the General Public License. Of course'; ' your program''s commands might be different'; ' for a GUI interface'; ' you would use an “about box”.'; 'You should also get your employer (if you work as a programmer) or school'; ' if any'; ' to sign a “copyright disclaimer” for the program'; ' if necessary. For more information on this'; ' and how to apply and follow the GNU GPL'; ' see <https://www.gnu.org/licenses/>.'; 'The GNU General Public License does not permit incorporating your program into proprietary programs. If your program is a subroutine library'; ' you may consider it more useful to permit linking proprietary applications with the library. If this is what you want to do'; ' use the GNU Lesser General Public License instead of this License. But first'; ' please read <https://www.gnu.org/licenses/why-not-lgpl.html>.'};
            app.tblTraining = uitable(app.GridLayout);
            app.tblTraining.ColumnName = {'User'; 'Photos Folder'; 'Type'; 'Last edit'; 'Progress'; 'Unique ID'};
            app.tblTraining.RowName = {};
            app.tblTraining.CellSelectionCallback = createCallbackFcn(app, @tblTrainingCellSelection, true);
            app.tblNeuralNet = uitable(app.GridLayout);
            app.tblNeuralNet.ColumnName = {'Neural Net Name'; 'Target'; 'Type'; 'Model'; 'Last change'; 'Trained'; 'Unique ID'};
            app.tblNeuralNet.RowName = {};
            app.tblNeuralNet.CellSelectionCallback = createCallbackFcn(app, @tblNeuralNetCellSelection, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TrachomaFinder

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end